classdef interface_characterization_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SaveTab                         matlab.ui.container.Tab
        DetectedFEsLabel                matlab.ui.control.Label
        AnalogIOLamp                    matlab.ui.control.Lamp
        AnalogIOLampLabel               matlab.ui.control.Label
        EMGLamp                         matlab.ui.control.Lamp
        EMGLampLabel                    matlab.ui.control.Label
        StimLamp                        matlab.ui.control.Lamp
        StimLampLabel                   matlab.ui.control.Label
        RecordingLabel                  matlab.ui.control.Label
        RecordingstatusPanel            matlab.ui.container.Panel
        FilesaveMsg                     matlab.ui.control.TextArea
        StopRecordingButton             matlab.ui.control.Button
        PauseRecordingButton            matlab.ui.control.Button
        StartRecordingButton            matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        SelectfolderButton              matlab.ui.control.Button
        FileEditField                   matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        StimulationTab                  matlab.ui.container.Tab
        StimLabel                       matlab.ui.control.Label
        StopStimButton                  matlab.ui.control.Button
        StartStimButton                 matlab.ui.control.Button
        stimSetup                       matlab.ui.container.TabGroup
        SinglePulseTab                  matlab.ui.container.Tab
        SinglePulse_icon                matlab.ui.control.Image
        SinglePulse_descr               matlab.ui.control.Label
        SinglePulse_addStimChPanel      matlab.ui.container.Panel
        SinglePulse_ch                  matlab.ui.control.DropDown
        SinglePulse_cathodic            matlab.ui.control.CheckBox
        SinglePulse_amp                 matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel        matlab.ui.control.Label
        StimChannelDropDownLabel        matlab.ui.control.Label
        SinglePulse_PW                  matlab.ui.control.NumericEditField
        DurationsLabel                  matlab.ui.control.Label
        SinglePulse_addStimButton       matlab.ui.control.Button
        SinglePulse_activeStimChsPanel  matlab.ui.container.Panel
        SinglePulse_activeStimTable     matlab.ui.control.Table
        SinglePulse_DeleteSelectedRowButton  matlab.ui.control.Button
        SinglePulse_LoadProtocolButton  matlab.ui.control.Button
        SinglePulse_SaveProtocolButton  matlab.ui.control.Button
        SingleBurstTab                  matlab.ui.container.Tab
        SingleBurst_icon                matlab.ui.control.Image
        SingleBurst_descr               matlab.ui.control.Label
        SingleBurst_addStimChPanel      matlab.ui.container.Panel
        DurationmsLabel_2               matlab.ui.control.Label
        SingleBurst_ch                  matlab.ui.control.DropDown
        SingleBurst_cathodic            matlab.ui.control.CheckBox
        SingleBurst_amp                 matlab.ui.control.NumericEditField
        SingleBurst_PW                  matlab.ui.control.NumericEditField
        SingleBurst_freq                matlab.ui.control.NumericEditField
        SingleBurst_dur                 matlab.ui.control.NumericEditField
        DurationmsLabel                 matlab.ui.control.Label
        FrequencyHzEditFieldLabel_3     matlab.ui.control.Label
        DurationsLabel_2                matlab.ui.control.Label
        AmplitudeAEditField_2Label      matlab.ui.control.Label
        StimChannelDropDownLabel_2      matlab.ui.control.Label
        SingleBurst_delay               matlab.ui.control.NumericEditField
        SingleBurst_addStimButton       matlab.ui.control.Button
        SingleBurst_activeStimChsPanel  matlab.ui.container.Panel
        SingleBurst_activeStimTable     matlab.ui.control.Table
        SingleBurst_DeleteSelectedRowButton  matlab.ui.control.Button
        SingleBurst_LoadProtocolButton  matlab.ui.control.Button
        SingleBurst_SaveProtocolButton  matlab.ui.control.Button
        RCTab                           matlab.ui.container.Tab
        icon_RC                         matlab.ui.control.Image
        RC_activeStimChsPanel           matlab.ui.container.Panel
        RC_activeStimTable              matlab.ui.control.Table
        RC_DeleteSelectedRowButton      matlab.ui.control.Button
        RC_LoadProtocolButton           matlab.ui.control.Button
        RC_SaveProtocolButton           matlab.ui.control.Button
        RC_addStimChPanel               matlab.ui.container.Panel
        stepEditFieldLabel              matlab.ui.control.Label
        RC_ch                           matlab.ui.control.DropDown
        RC_cathodic                     matlab.ui.control.CheckBox
        RC_amp_label                    matlab.ui.control.Label
        RC_min_amp                      matlab.ui.control.NumericEditField
        RC_max_amp                      matlab.ui.control.NumericEditField
        RC_amp_step                     matlab.ui.control.NumericEditField
        RC_reps                         matlab.ui.control.NumericEditField
        RepetitionsperamplitudeEditFieldLabel  matlab.ui.control.Label
        RC_PW                           matlab.ui.control.NumericEditField
        PulseWidthsEditFieldLabel       matlab.ui.control.Label
        RC_freq                         matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel         matlab.ui.control.Label
        maxEditFieldLabel               matlab.ui.control.Label
        minEditFieldLabel               matlab.ui.control.Label
        StimChannelDropDownLabel_3      matlab.ui.control.Label
        RC_addStimButton                matlab.ui.control.Button
        RC_descr                        matlab.ui.control.Label
        AMBurstsTab                     matlab.ui.container.Tab
        AMBursts_icon                   matlab.ui.control.Image
        AMBursts_descr                  matlab.ui.control.Label
        AMBursts_addStimChPanel         matlab.ui.container.Panel
        AMBursts_ch                     matlab.ui.control.DropDown
        StimChannelDropDownLabel_4      matlab.ui.control.Label
        AMBursts_cathodic               matlab.ui.control.CheckBox
        AMBursts_amp_label              matlab.ui.control.Label
        AMBursts_min_amp                matlab.ui.control.NumericEditField
        AMBursts_max_amp                matlab.ui.control.NumericEditField
        AMBursts_amp_step               matlab.ui.control.Spinner
        AMBursts_reps                   matlab.ui.control.NumericEditField
        AMBursts_PW                     matlab.ui.control.NumericEditField
        AMBursts_freq                   matlab.ui.control.NumericEditField
        AMBursts_dur                    matlab.ui.control.NumericEditField
        AMBursts_IBI                    matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_7      matlab.ui.control.Label
        BurstdurationmsLabel_3          matlab.ui.control.Label
        IntraBurstFrequencyLabel_8      matlab.ui.control.Label
        PulseWidthsEditField_2Label     matlab.ui.control.Label
        RepetitionsperamplitudeEditField_2Label  matlab.ui.control.Label
        OffsetLabel_10                  matlab.ui.control.Label
        MaxEditFieldLabel               matlab.ui.control.Label
        MinEditFieldLabel               matlab.ui.control.Label
        AMBursts_addStimButton          matlab.ui.control.Button
        AMBursts_activeStimChsPanel     matlab.ui.container.Panel
        AMBursts_activeStimTable        matlab.ui.control.Table
        AMBursts_DeleteSelectedRowButton  matlab.ui.control.Button
        AMBursts_LoadProtocolButton     matlab.ui.control.Button
        AMBursts_SaveProtocolButton     matlab.ui.control.Button
        FMBurstsTab                     matlab.ui.container.Tab
        FMBursts_icon                   matlab.ui.control.Image
        FMBursts_descr                  matlab.ui.control.Label
        FMBursts_addStimChPanel         matlab.ui.container.Panel
        FMBursts_ch                     matlab.ui.control.DropDown
        FMBursts_cathodic               matlab.ui.control.CheckBox
        FMBursts_freq_label             matlab.ui.control.Label
        FMBursts_amp                    matlab.ui.control.NumericEditField
        FMBursts_PW                     matlab.ui.control.NumericEditField
        FMBursts_min_freq               matlab.ui.control.NumericEditField
        FMBursts_max_freq               matlab.ui.control.NumericEditField
        FMBursts_freq_step              matlab.ui.control.Spinner
        OffsetLabel_11                  matlab.ui.control.Label
        MaxEditField_3Label             matlab.ui.control.Label
        MinEditField_3Label             matlab.ui.control.Label
        FMBursts_reps                   matlab.ui.control.NumericEditField
        RepetitionsperfrequencyEditFieldLabel  matlab.ui.control.Label
        FMBursts_dur                    matlab.ui.control.NumericEditField
        FMBursts_IBI                    matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_10     matlab.ui.control.Label
        BurstdurationmsLabel_4          matlab.ui.control.Label
        PulseWidthsEditField_4Label     matlab.ui.control.Label
        IntraBurstFrequencyLabel_9      matlab.ui.control.Label
        StimChannelDropDownLabel_5      matlab.ui.control.Label
        FMBursts_addStimButton          matlab.ui.control.Button
        FMBursts_activeStimChsPanel     matlab.ui.container.Panel
        FMBursts_activeStimTable        matlab.ui.control.Table
        FMBursts_DeleteSelectedRowButton  matlab.ui.control.Button
        FMBursts_LoadProtocolButton     matlab.ui.control.Button
        FMBursts_SaveProtocolButton     matlab.ui.control.Button
        TonicTab                        matlab.ui.container.Tab
        Tonic_descr                     matlab.ui.control.Label
        Tonic_icon                      matlab.ui.control.Image
        Tonic_addStimChPanel            matlab.ui.container.Panel
        Tonic_ch                        matlab.ui.control.DropDown
        Tonic_cathodic                  matlab.ui.control.CheckBox
        Tonic_amp                       matlab.ui.control.NumericEditField
        Tonic_PW                        matlab.ui.control.NumericEditField
        Tonic_freq                      matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_2     matlab.ui.control.Label
        DurationsLabel_3                matlab.ui.control.Label
        AmplitudeALabel                 matlab.ui.control.Label
        StimChannelDropDownLabel_6      matlab.ui.control.Label
        Tonic_TC                        matlab.ui.container.ButtonGroup
        Tonic_train_dur                 matlab.ui.control.NumericEditField
        Tonic_stop_button               matlab.ui.control.RadioButton
        Tonic_train_dur_button          matlab.ui.control.RadioButton
        Tonic_addStimButton             matlab.ui.control.Button
        Tonic_activeStimChsPanel        matlab.ui.container.Panel
        Tonic_activeStimTable           matlab.ui.control.Table
        Tonic_DeleteSelectedRowButton   matlab.ui.control.Button
        Tonic_LoadProtocolButton        matlab.ui.control.Button
        Tonic_SaveProtocolButton        matlab.ui.control.Button
        AMSineTab                       matlab.ui.container.Tab
        AMSine_addStimChPanel           matlab.ui.container.Panel
        AMSine_ch                       matlab.ui.control.DropDown
        AMSine_cathodic                 matlab.ui.control.CheckBox
        SineperiodsEditFieldLabel       matlab.ui.control.Label
        StimChannelDropDownLabel_7      matlab.ui.control.Label
        BurstdurationmsLabel_5          matlab.ui.control.Label
        AMSine_min_amp                  matlab.ui.control.NumericEditField
        AMSine_max_amp                  matlab.ui.control.NumericEditField
        AMSine_PW                       matlab.ui.control.NumericEditField
        AMSine_freq                     matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_11     matlab.ui.control.Label
        PulseWidthsEditField_2Label_2   matlab.ui.control.Label
        MaxampALabel                    matlab.ui.control.Label
        MinampLabel                     matlab.ui.control.Label
        AMSine_period                   matlab.ui.control.NumericEditField
        AMSine_dur                      matlab.ui.control.NumericEditField
        AMSine_addStimButton            matlab.ui.control.Button
        AMSine_activeStimChsPanel       matlab.ui.container.Panel
        AMSine_SaveProtocolButton       matlab.ui.control.Button
        AMSine_activeStimTable          matlab.ui.control.Table
        AMSine_DeleteSelectedRowButton  matlab.ui.control.Button
        AMSine_LoadProtocolButton       matlab.ui.control.Button
        FMSineTab                       matlab.ui.container.Tab
        FMSine_addStimChPanel           matlab.ui.container.Panel
        FMSine_cathodic                 matlab.ui.control.CheckBox
        FMSine_ch                       matlab.ui.control.DropDown
        StimChannelDropDownLabel_8      matlab.ui.control.Label
        FMSine_amp                      matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_12     matlab.ui.control.Label
        FMSine_PW                       matlab.ui.control.NumericEditField
        PulseWidthsEditField_2Label_3   matlab.ui.control.Label
        FMSine_min_freq                 matlab.ui.control.NumericEditField
        MinampLabel_2                   matlab.ui.control.Label
        FMSine_max_freq                 matlab.ui.control.NumericEditField
        MaxampALabel_2                  matlab.ui.control.Label
        SineperiodsEditFieldLabel_2     matlab.ui.control.Label
        FMSine_period                   matlab.ui.control.NumericEditField
        BurstdurationmsLabel_6          matlab.ui.control.Label
        FMSine_dur                      matlab.ui.control.NumericEditField
        FMSine_addStimButton            matlab.ui.control.Button
        FMSine_activeStimChsPanel       matlab.ui.container.Panel
        FMSine_activeStimTable          matlab.ui.control.Table
        FMSine_DeleteSelectedRowButton  matlab.ui.control.Button
        FMSine_LoadProtocolButton       matlab.ui.control.Button
        FMSine_SaveProtocolButton       matlab.ui.control.Button
        StreamingTab                    matlab.ui.container.Tab
        StreamButton                    matlab.ui.control.Button
        GainDropDown                    matlab.ui.control.DropDown
        GainDropDownLabel               matlab.ui.control.Label
        ObjectListBox                   matlab.ui.control.ListBox
        ChannelsPanel                   matlab.ui.container.Panel
        allChs                          matlab.ui.control.CheckBox
        Ch12CheckBox                    matlab.ui.control.CheckBox
        Ch11CheckBox                    matlab.ui.control.CheckBox
        Ch10CheckBox                    matlab.ui.control.CheckBox
        Ch9CheckBox                     matlab.ui.control.CheckBox
        Ch8CheckBox                     matlab.ui.control.CheckBox
        Ch7CheckBox                     matlab.ui.control.CheckBox
        Ch6CheckBox                     matlab.ui.control.CheckBox
        Ch5CheckBox                     matlab.ui.control.CheckBox
        Ch4CheckBox                     matlab.ui.control.CheckBox
        Ch3CheckBox                     matlab.ui.control.CheckBox
        Ch2CheckBox                     matlab.ui.control.CheckBox
        Ch1CheckBox                     matlab.ui.control.CheckBox
        ObjectshapeLabel                matlab.ui.control.Label
        stimAxes                        matlab.ui.control.UIAxes
        EMGaxes                         matlab.ui.control.UIAxes
        pressureAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)

        % Recording variables
        savePath = "C:\Users\m.ceradini\Desktop\Elena\Code Regrap\Rewire_Project\Data";    % folder where to save recordings. By defaut it is current directory.
        fileNameBase = [];      % parameter for xippmex('trial') function: filename
        autoStopTime = 0;       % parameter for xippmex('trial') function: if set, recording automatically stops after auto_stop_time seconds.
        autoIncr = 0;           % parameter for xippmex('trial') function: boolean. If set to 1, it outputs filename
        recON = 0;              % flag for recording ON
        ccRec = 0;              % counter to save stim parameters

        % Sampling rates
        fsEMG = 2000;           % EMG sampling rate [Hz]
        clockSummit = 3e4;      % clock of Summit processor [Hz]

        % Stim variables
        safeLimit = 120*1e3;    % charge safety limit [pC]
        ampLimit = 1000;        % amplitude limit in the GUI [uA]
        pwLimit = 100;          % pulse-width limit in the GUI [us]
        newRipple = 1;          % flag for New Ripple Summit
        stimRes = 4;            % stim resolution (1 = 1 uA/step, ......, 4 = 10 uA/step)
        ampStepSize = 10;       % amplitude step size [uA/step]
        maxFreq = 1000;         % maximum allowed frequency [Hz]
        stimChsID               % IDs of micro+stim FE available channels
        activeStimCh            % ID of channel selected for stimulation
        stimEN = 0              % boolean for stim enabled
        selectedRow_activeStimTable = [];

        % Variables for recorded signals (EMG and pressure)
        EMGchs = zeros(1,16)    % IDs of EMG channels in EMG front-end
        analogChs               % IDs of analog channels in I/O front-end

        % Streaming variables
        appTimer                % timer object used for streaming
        streaming = 0           % boolean for streaming status
        firstRun = 1            % boolean for I streaming run (used to clean the axes)
        plotUpdateRate = 4      % plot update rate [Hz] -> [10,15] Hz maximum

        anLpressure             % animated line for pressure stream
        anLEMG                  % animated line for EMG
        anLstimulation          % animated line for stim data

        t0                      % first timepoint of Summit at 30kHz clock
        prevTimestamp           % previous timestamp (used to append data streams) at 30kHz clock

        plotTimeSpan = 5        % time span for the plot [s]

        EMGchsChecks = zeros(1,12)   % booleans indicating if EMG chs are to be streamed (based on check box)
        EMGchsToStream          % EMG channels to stream (Summit IDs)
        EMGreadWind             % length of requested EMG data to Summit [s]
        EMGyTicks               % array of indices for yTicks of EMG streams
        computeEMGscale = 0     % boolean for the computation of EMG scaling factor (it is done only at I stream run)
        EMGscale                % scaling factor for EMG channels

        streamStim = 1          % boolean to stream stim (if stim data are not received for period = plotTimeSpan, stim is not streamed anymore)
        nChecksStimData = 0     % number of additional checks for Stim data stream:
        % this is because, timer checks if stim
        % buffer contains anything and plots it, only when
        % the flag 'active_stim_ch' is not 0. it is
        % set when a stimulation command is
        % sent, and unset when the stim buffer is
        % empty for 3 consecutive runs. it might
        % happen that buffer is empty at i-th check,
        % but still there's data at the subsequent
        % (i+1)-th check, that's the reason why this
        % variable exists
    end


    methods (Access = private)

        function stopTmr(app,src,~)
            % delete(src)
        end

        function stopStimTimer(app)
            app.StimLabel.Text = 'Stim: OFF';
            app.StimLabel.FontColor = 'k';
        end

        function confirmClose(app,~,event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                xippmex('stim','enable',0);
                xippmex('close');
                pause(0.5);
                stop(app.appTimer);
                delete(timerfind);
                delete(app);
            end
        end

        % update file name
        function updateFileName(app)
            dateStr = string(datetime('now','Format','yyyy-MM-dd'));
            timeStr = string(datetime('now','Format','HH-mm-ss'));
            % compute recording number incrementally for today
            recNb = computeRecNb(app,dateStr);
            % create file name
            fileName = [dateStr '-' timeStr '-rec' num2str(recNb)];
            % update field with path + user-written text
            app.FileEditField.Value = strjoin([app.savePath,filesep,fileName],'');
        end

        % update title for stim axis
        function updateStimAxisTitle(app)
            % create the string and update the axis title
            selectedTab = strrep(app.stimSetup.SelectedTab.Title,' ','');
            try
                eval(['ch = app.', selectedTab, '_activeStimTable.Data(:,1);']);
                chString = ['Ch ' strjoin(compose('%d',ch),',');];
            catch
                chString = 'No chs selected';
            end
            txt = [selectedTab ' - ' chString];
            title(app.stimAxes,txt);
        end

        function updatePressurePlot(app,h)
            % get digital events
            [~, timestamps, events] = xippmex('digin'); % timestamps based on 30 kHz clock ticks
            if ~isempty(events)
                s = double(extractfield(events,'parallel'));
                x = (timestamps-app.t0)/app.clockSummit;    % (s)
                if x(end)<=app.plotTimeSpan
                    xlim(app.pressureAxes,[0 app.plotTimeSpan]);
                    addpoints(h,x,s);
                else
                    if x(1)<app.plotTimeSpan
                        idx = find(x==app.plotTimeSpan);
                        addpoints(h,x(1:idx),s(1:idx));
                        addpoints(h,x(idx+1:end),s(idx+1:end));
                    else
                        x_range(2) = x(end);
                        x_range(1) = x_range(2)-app.plotTimeSpan;
                        xlim(app.pressureAxes,x_range)
                        addpoints(h,x,s);
                    end
                end
            end
        end

        function updateEMGplot(app,aL)

            % request EMG data to the Summit
            [EMG, timestamp] = xippmex('cont',app.EMGchsToStream,app.EMGreadWind*1e3,'hi-res');
            timestamp = double(timestamp);
            % compute EMG scale if it is first run
            if app.computeEMGscale
                app.EMGscale = max(EMG,[],2) * 10; % max EMG in the I read window multiplied per 10
                app.computeEMGscale = 0;
            end
            % scale EMG for display purpose
            EMG = EMG./app.EMGscale;
            % save timepoint of last read sample (to start from that next cycle)
            app.prevTimestamp = timestamp + app.EMGreadWind*app.clockSummit; % timestamp is sampled at a freq = summit clock
            % da risolvere il fatto che possano sovrapporsi dei samples..
            % plot data batch
            xStep = app.clockSummit/app.fsEMG;
            x = timestamp:xStep:(timestamp+app.EMGreadWind*app.clockSummit-1);
            xShifted  = (x-app.t0)/app.clockSummit;
            if xShifted(end)<=app.plotTimeSpan
                xlim(app.EMGaxes,[0 app.plotTimeSpan]);
                for iCh = 1:size(EMG,1)
                    addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                end
            else
                if xShifted(1)<app.plotTimeSpan
                    idx = find(xShifted==app.plotTimeSpan);
                    for iCh = 1:size(EMG,1)
                        addpoints(aL{iCh},xShifted(1:idx),EMG(iCh,1:idx)+app.EMGyTicks(iCh));
                    end
                    for iCh = 1:size(EMG,1)
                        addpoints(aL{iCh},xShifted(idx+1:end),EMG(iCh,idx+1:end)+app.EMGyTicks(iCh));
                    end
                else
                    xRange(2) = xShifted(end);
                    xRange(1) = xRange(2)-app.plotTimeSpan;
                    xlim(app.EMGaxes,xRange)
                    try
                        for iCh = 1:size(EMG,1)
                            addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                        end
                    catch
                        for iCh = 1:size(EMG,1)-1
                            addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                        end
                        disp(['Index exceeds the number of array elements.\n' ...
                            'This may happen when updating which EMG channel to ' ...
                            'stream, since checkbox may be ticked while timer is' ...
                            ' already updating EMG lines with previous set of channels!'])
                    end
                end
            end
        end

        function initializeFileEditField(app)
            app.FileEditField.Value = app.savePath;
        end

        % Compute recording number for filename
        function recNb = computeRecNb(app,dateStr)

            % Find all the files of today
            fileNameList = extractfield(dir(app.savePath),'name');
            filesToday = fileNameList(find(contains(fileNameList,dateStr)));

            % Find the recording numbers of today
            recsToday = [];
            for iT = 1:length(filesToday)
                recIdx = strfind(filesToday{iT},'rec');
                recsToday(iT) = str2double(regexp(filesToday{iT}(recIdx+3:recIdx+5),'\d*','Match'));
            end
            maxRecNb = max(recsToday);
            if isempty(maxRecNb)
                maxRecNb = 0;
            end

            % Compute recording number incrementally
            recNb = maxRecNb + 1;
        end

        % ....
        function updateEMGchsChecks(app)
            app.EMGchsChecks =  [app.Ch1CheckBox.Value app.Ch2CheckBox.Value ...
                app.Ch3CheckBox.Value app.Ch4CheckBox.Value ...
                app.Ch5CheckBox.Value app.Ch6CheckBox.Value ...
                app.Ch7CheckBox.Value app.Ch8CheckBox.Value ...
                app.Ch9CheckBox.Value app.Ch10CheckBox.Value ...
                app.Ch11CheckBox.Value app.Ch12CheckBox.Value];
        end

        % .....
        function add_remove_EMG_plot(app)
            % update list of selected electrode IDs
            app.EMGchsToStream = app.EMGchs(logical(app.EMGchsChecks));
            for ch = 1:length(app.anLEMG)
                clearpoints(app.anLEMG{ch})
            end
            % EMG
            for ch = 1:length(app.EMGchsToStream)
                app.anLEMG{ch} = animatedline(app.EMGaxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',app.fsEMG*(app.plotTimeSpan+1));
            end
            app.EMGaxes.XLim = [0 app.plotTimeSpan];
            app.EMGaxes.YLim = [-1 length(app.EMGchsToStream)+1];
            app.EMGyTicks = 1:length(app.EMGchsToStream);
            app.EMGaxes.YTick = app.EMGyTicks;
            app.EMGaxes.YTickLabel = num2cell(app.EMGchsToStream-(app.EMGchs(1)-1));    % levo la quantità elecs(1)-1 per far sì che si legga sempre
            % l'ID dell'elettrodo a partire da 1 (e non da 129 nel caso il
            % FE EMG sia connesso alla porta B)
        end

        % ......
        function updateStimPlot(app)

            % request stim data to the Summit
            [stimCount, stimTimestamps, ~] = xippmex('spike', app.activeStimCh, 1);
            % if stim data are received, plot them
            if stimCount
                app.nChecksStimData = 0;
                x = (stimTimestamps{1}-app.t0)/app.clockSummit; % timestamp is sampled at a freq = summit clock
                if x(end)<=app.plotTimeSpan
                    stem_h{1,1} = stem(app.stimAxes,repelem(x,2), repmat([-10 10],1,stimCount), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                else
                    if x(1)<app.plotTimeSpan   % questo caso si può forse evitare
                        idx = find(x==app.plotTimeSpan);
                        stem_h{2,1} = stem(app.stimAxes,repelem(x(1:idx),2), repmat([-10 10],1,idx), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                        stem_h{3,1} = stem(app.stimAxes,repelem(x(idx+1:end),2), repmat([-10 10],1,stimCount-idx), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                    else
                        stem_h{4,1} = stem(app.stimAxes,repelem(x,2), repmat([-10 10],1,stimCount), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                    end
                end
                % if data are not received for some time (for example when
                % stim train is over), stim streaming is stopped to slim
                % the processing cost
            else
                app.nChecksStimData = app.nChecksStimData + 1;
                if app.nChecksStimData > ceil(app.plotTimeSpan*app.plotUpdateRate)
                    % put down streamStim flag
                    app.streamStim = 0;
                    % clear plot to save memory
                    cla(app.stimAxes)
                end
            end
        end
    end

    methods (Access = public)

        function timerCallback(app,~,~)
            if app.streaming
                % ------------- Pressure ----------------
                if ~isempty(app.analogChs)
                    try
                        updatePressurePlot(app,app.anLpressure);
                    catch
                        disp('Issue in streaming pressure data')
                    end
                end
                % ------------- EMG ---------------------
                if ~isempty(app.EMGchsToStream)
                    try
                        updateEMGplot(app,app.anLEMG);
                    catch
                        disp('Issue in streaming EMG data')
                    end
                end
                % ------------- Stimulation -------------
                if app.stimEN && app.streamStim
                    try
                        updateStimPlot(app);
                    catch
                        disp('Issue in streaming Stim data')
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path for auxiliary functions
            addpath(genpath(fileparts(mfilename('fullpath'))));
            % connect with Summit
            try
                if app.newRipple
                    xippmex('tcp'); % TCP communication
                    xippmex('addoper', 129); % set operator (for recording)
                else
                    xippmex; % UDP communication
                end
                pause(1);
            catch
                waitfor(msgbox('Could not find Summit. Fix the problem and restart the app.'));
                delete(app);
                return
            end
            % find stim channels
            app.stimChsID = xippmex('elec','stim');
            if isempty(app.stimChsID)
                app.StartStimButton.Enable = 'off';
                app.StopStimButton.Enable = 'off';
            else
                app.StimLamp.Color = 'g';
            end
            % find EMG channels and apply hi-res filters
            app.EMGchs = xippmex('elec','EMG');
            if isempty(app.EMGchs)
                app.ChannelsPanel.Enable = 'off';
            else
                app.EMGLamp.Color = 'g';
                [~,hires_filters] = xippmex('filter','list',1,'hires');
                [~,hires_notch_filters] = xippmex('filter','list',1,'hires notch');
                for ich = 1:length(app.EMGchs)
                    xippmex('filter','set',app.EMGchs(ich),'hires notch',1); % notch at 50/100/150 Hz
                    xippmex('filter','set',app.EMGchs(ich),'hires',5); % CHECK THISSSS (band-pass 15-375 Hz)
                end
            end
            % find analog channels
            % app.analogChs = xippmex('elec','analog');
            % if isempty(app.analogChs)
            %     app.ObjectListBox.Enable = 'off';
            %     app.GainDropDown.Enable = 'off';
            % else
            %     app.AnalogIOLamp.Color = 'g';
            % end
            try
                xippmex('digin');
                app.AnalogIOLamp.Color = 'g';
                app.analogChs = 1;
            catch
                app.ObjectListBox.Enable = 'off';
                app.GainDropDown.Enable = 'off';
            end
            % flush stim buffer by calling spike cmd
            xippmex('spike',app.stimChsID,1);
            % update file name with pre-set settings from drop down
            updateFileName(app);
            % check if there are other timers and delete them
            timers = timerfind;
            if ~isempty(timers)
                stop(timers);
                % delete(timers);     % l'ho scommentata il 12 aprile 2023:
                % prima di allora funzionava, quindi se hai problemi sui
                % timer, ricommentala
            end
            % define timererrordlg
            app.appTimer = timer;
            app.appTimer.Period = 1/app.plotUpdateRate;
            app.appTimer.ExecutionMode = 'fixedRate';
            % define timer callback functions
            app.appTimer.StartFcn = @(~,~)tic;
            app.appTimer.TimerFcn = @app.timerCallback;
            app.appTimer.StopFcn = @app.stopTmr;
            % start timer
            start(app.appTimer);
            %             dbstop if caught error
            % fill StimChannelDropDown with available stim channels in all the stim tabs
            app.SinglePulse_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.SingleBurst_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.RC_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.AMBursts_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.FMBursts_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.Tonic_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.AMSine_ch.Items = string(num2cell(1:length(app.stimChsID)));
            app.FMSine_ch.Items = string(num2cell(1:length(app.stimChsID)));
            % Set stimulation step size for the available chs
            if app.newRipple
                try
                    xippmex('stim','res',app.stimChsID,app.stimRes);
                catch e
                    waitfor(msgbox([e.message ' Disable the stim using Trellis and restart the app.']));
                    delete(app);
                end
            else
                app.ampStepSize = 7.5; % [uA/step]
            end
            % get selected electrode ID
            app.activeStimCh = app.stimChsID(str2double(app.SinglePulse_ch.Value));
            % activate stim data stream
            xippmex('signal',app.stimChsID,'stim',ones(1,length(app.stimChsID)));
            % set stim axis title
            title(app.stimAxes, 'No stim channels available');
            % constrain xlim for Streaming Tab axes and link axes
            app.pressureAxes.XLim = [0 app.plotTimeSpan];
            app.EMGaxes.XLim = [0 app.plotTimeSpan];
            app.stimAxes.XLim = [0 app.plotTimeSpan];
            linkaxes([app.pressureAxes, app.EMGaxes, app.stimAxes],'x');
            % set length of EMG data batch to ask Summit based on plotUpdateRate
            app.EMGreadWind = 1/app.plotUpdateRate;
            % set ylim for EMG axes (will have to update them runtime)
            app.EMGaxes.YLim = [0 1];
            % set ylim for Stim_Axes
            app.stimAxes.YLim = [-30,30];
            hold(app.stimAxes,'on');
        end

        % Close request function: InterfaceUIFigure
        function InterfaceUIFigureCloseRequest(app, event)
            % Display confirmation dialog box
            msg = 'Are you sure you want to close?';
            uiconfirm(app.InterfaceUIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            newPath = uigetdir();
            if newPath
                app.savePath = newPath;
                updateFileName(app);
            end
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            txt1 = regexp(app.FileEditField.Value,filesep,'split');
            txt1 = txt1{end};
            computeRecNb(app,app.savePath,txt1);
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Current status: RECORDING
            if strcmp(trial_descriptor.status,'recording')

                msgbox('Already recording. Please stop the current recording and start a new one.')
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Already in RECORDING status';

                % Current status: STOPPED
            elseif strcmp(trial_descriptor.status,'stopped')

                % Update filename to prevent file replacement
                updateFileName(app);
                app.fileNameBase = app.FileEditField.Value;
                % Save universal time of start recording
                timeNow = datestr(now,'HH:MM:SS.FFF');
                writematrix(timeNow,[app.fileNameBase '-startTime.txt'],'WriteMode','append');
                % Start recording
                xippmex('trial','recording',app.fileNameBase,app.autoStopTime,app.autoIncr);
                % Update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Recording...';

                % Current status: PAUSED -> Resume recording
            elseif strcmp(trial_descriptor.status,'paused')

                % Save universal time
                timeNow = datestr(now,'HH:MM:SS.FFF');
                writematrix(timeNow,[app.fileNameBase '-startTime.txt'],'WriteMode','append');
                % Resume recording
                xippmex('trial','recording',app.fileNameBase,app.autoStopTime,app.autoIncr);
                % Update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = sprintf('Recording correctly resumed after pause...');
            end

            % Set flag for recording ON and clear recording counter
            app.recON = 1;
            app.ccRec = 0;
        end

        % Button pushed function: PauseRecordingButton
        function PauseRecordingButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Current status: RECORDING
            if  strcmp(trial_descriptor.status,'recording')

                % pause recording
                xippmex('trial','paused');
                % update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = sprintf(['Recording paused successfully.\n' ...
                    'Resume whenever want']);

                % Current status: already PAUSED
            elseif strcmp(trial_descriptor.status,'paused')

                msgbox('Recording was already paused.')
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Recording was already paused.';

                % Current status: STOPPED
            elseif strcmp(trial_descriptor.status,'stopped')

                msgbox('Recording is off.')
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'off';
                app.FilesaveMsg.Value = 'Recording is off.';

            end

            % Clear flag for recording ON
            app.recON = 0;
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Stop recording if xippmex was actually recording
            if  strcmp(trial_descriptor.status,'recording') || strcmp(trial_descriptor.status,'paused')

                % stop recording
                xippmex('trial','stopped');
                % update filename to prevent file replacement
                updateFileName(app);
                % update message on the GUI
                app.FilesaveMsg.Value = 'Recording stopped successfully.';
                % reset saving settings
                app.fileNameBase = [];
                app.autoStopTime = 0;

                % Otherwise just send a message
            elseif strcmp(trial_descriptor.status,'stopped')

                msgbox('Xippmex was not recording.')
                app.FilesaveMsg.Value = 'Xippmex was not recording.';

            end

            % In any case update buttons on the GUI
            app.StartRecordingButton.Enable = 'on';
            app.PauseRecordingButton.Enable = 'off';
            app.StopRecordingButton.Enable = 'off';

            % Clear flag for recording ON
            app.recON = 0;
        end

        % Selection change function: stimSetup
        function stimSetupSelectionChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % clear selected row to delete
            app.selectedRow_activeStimTable = [];
            % enable/disable the stim button depending if the table in the new tab is empty or not
            selectedTab = strrep(app.stimSetup.SelectedTab.Title,' ','');
            eval(['tableEmpty = isempty([app.', selectedTab, '_activeStimTable.Data', ']);']);
            if tableEmpty
                app.StartStimButton.Enable = 'off';
            else
                app.StartStimButton.Enable = 'on';
            end
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
            if app.Ch1CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(1)      % previously 0
                    app.EMGchsChecks(1) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(1)      % previously 1
                    app.EMGchsChecks(1) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            if app.Ch2CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(2)      % previously 0
                    app.EMGchsChecks(2) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(2)      % previously 1
                    app.EMGchsChecks(2) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            if app.Ch3CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(3)      % previously 0
                    app.EMGchsChecks(3) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(3)      % previously 1
                    app.EMGchsChecks(3) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            if app.Ch4CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(4)      % previously 0
                    app.EMGchsChecks(4) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(4)      % previously 1
                    app.EMGchsChecks(4) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch5CheckBox
        function Ch5CheckBoxValueChanged(app, event)
            if app.Ch5CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(5)      % previously 0
                    app.EMGchsChecks(5) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(5)      % previously 1
                    app.EMGchsChecks(5) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch6CheckBox
        function Ch6CheckBoxValueChanged(app, event)
            if app.Ch6CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(6)      % previously 0
                    app.EMGchsChecks(6) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(6)      % previously 1
                    app.EMGchsChecks(6) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch7CheckBox
        function Ch7CheckBoxValueChanged(app, event)
            if app.Ch7CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(7)      % previously 0
                    app.EMGchsChecks(7) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(7)      % previously 1
                    app.EMGchsChecks(7) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch8CheckBox
        function Ch8CheckBoxValueChanged(app, event)
            if app.Ch8CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(8)      % previously 0
                    app.EMGchsChecks(8) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(8)      % previously 1
                    app.EMGchsChecks(8) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch9CheckBox
        function Ch9CheckBoxValueChanged(app, event)
            if app.Ch9CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(9)      % previously 0
                    app.EMGchsChecks(9) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(9)      % previously 1
                    app.EMGchsChecks(9) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch10CheckBox
        function Ch10CheckBoxValueChanged(app, event)
            if app.Ch10CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(10)      % previously 0
                    app.EMGchsChecks(10) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(10)      % previously 1
                    app.EMGchsChecks(10) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch11CheckBox
        function Ch11CheckBoxValueChanged(app, event)
            if app.Ch11CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(11)      % previously 0
                    app.EMGchsChecks(11) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(11)      % previously 1
                    app.EMGchsChecks(11) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch12CheckBox
        function Ch12CheckBoxValueChanged(app, event)
            if app.Ch12CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(12)      % previously 0
                    app.EMGchsChecks(12) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(12)      % previously 1
                    app.EMGchsChecks(12) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: allChs
        function allChsValueChanged(app, event)
            if app.allChs.Value
                app.EMGchsChecks(:) = 1;
                app.Ch1CheckBox.Value = 1;
                app.Ch2CheckBox.Value = 1;
                app.Ch3CheckBox.Value = 1;
                app.Ch4CheckBox.Value = 1;
                app.Ch5CheckBox.Value = 1;
                app.Ch6CheckBox.Value = 1;
                app.Ch7CheckBox.Value = 1;
                app.Ch8CheckBox.Value = 1;
                app.Ch9CheckBox.Value = 1;
                app.Ch10CheckBox.Value = 1;
                app.Ch11CheckBox.Value = 1;
                app.Ch12CheckBox.Value = 1;
            else
                app.EMGchsChecks(:) = 0;
                app.Ch1CheckBox.Value = 0;
                app.Ch2CheckBox.Value = 0;
                app.Ch3CheckBox.Value = 0;
                app.Ch4CheckBox.Value = 0;
                app.Ch5CheckBox.Value = 0;
                app.Ch6CheckBox.Value = 0;
                app.Ch7CheckBox.Value = 0;
                app.Ch8CheckBox.Value = 0;
                app.Ch9CheckBox.Value = 0;
                app.Ch10CheckBox.Value = 0;
                app.Ch11CheckBox.Value = 0;
                app.Ch12CheckBox.Value = 0;
            end
            add_remove_EMG_plot(app);
        end

        % Value changed function: SinglePulse_amp
        function SinglePulse_ampValueChanged(app, event)
            % check for safety limits
            if app.SinglePulse_amp.Value*app.SinglePulse_PW.Value > app.safeLimit
                app.SinglePulse_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: SinglePulse_PW
        function SinglePulse_PWValueChanged(app, event)
            % check for satey limits
            if app.SinglePulse_amp.Value*app.SinglePulse_PW.Value > app.safeLimit
                app.SinglePulse_amp.PW = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: SingleBurst_amp
        function SingleBurst_ampValueChanged(app, event)
            % check safety limits
            if app.SingleBurst_amp.Value*app.SingleBurst_PW.Value > app.safeLimit
                app.SingleBurst_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: SingleBurst_freq
        function SingleBurst_freqValueChanged(app, event)
            % Frequency exceeds limit
            if app.SingleBurst_freq.Value > app.maxFreq
                app.SingleBurst_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
        end

        % Value changed function: SingleBurst_PW
        function SingleBurst_PWValueChanged(app, event)
            % check safety limits
            if app.SingleBurst_amp.Value*app.SingleBurst_PW.Value > app.safeLimit
                app.SingleBurst_amp.PW = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: RC_min_amp
        function RC_min_ampValueChanged(app, event)
            % check safety limits
            if app.RC_min_amp.Value*app.RC_PW.Value > app.safeLimit
                app.RC_min_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.RC_min_amp.Value > app.RC_max_amp.Value
                app.RC_min_amp.Value = app.RC_max_amp.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
        end

        % Value changed function: RC_max_amp
        function RC_max_ampValueChanged(app, event)
            % check safety limits
            if app.RC_max_amp.Value*app.RC_PW.Value > app.safeLimit
                app.RC_max_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.RC_max_amp.Value < app.RC_min_amp.Value
                app.RC_max_amp.Value = app.RC_min_amp.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
        end

        % Value changed function: RC_PW
        function RC_PWValueChanged(app, event)
            % check safety limits
            if app.RC_min_amp.Value*app.RC_PW.Value > app.safeLimit | app.RC_max_amp.Value*app.RC_PW.Value > app.safeLimit
                app.RC_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: AMBursts_freq
        function AMBursts_freqValueChanged(app, event)
            % Frequency exceeds limit
            if app.AMBursts_freq.Value > app.maxFreq
                app.AMBursts_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
        end

        % Value changed function: AMBursts_min_amp
        function AMBursts_min_ampValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.AMBursts_min_amp.Value*app.AMBursts_PW.Value > app.safeLimit
                app.AMBursts_min_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.AMBursts_min_amp.Value > app.AMBursts_max_amp.Value
                app.AMBursts_min_amp.Value = app.AMBursts_max_amp.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
        end

        % Value changed function: AMBursts_max_amp
        function AMBursts_max_ampValueChanged(app, event)
            % check safety limits
            if app.AMBursts_max_amp.Value*app.AMBursts_PW.Value > app.safeLimit
                app.AMBursts_max_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.AMBursts_max_amp.Value < app.AMBursts_min_amp.Value
                app.AMBursts_max_amp.Value = app.AMBursts_min_amp.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
        end

        % Value changed function: AMBursts_PW
        function AMBursts_PWValueChanged(app, event)
            % check safety limits
            if app.AMBursts_min_amp.Value*app.AMBursts_PW.Value > app.safeLimit | app.AMBursts_max_amp.Value*app.AMBursts_PW.Value > app.safeLimit
                app.AMBursts_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: FMBursts_amp
        function FMBursts_ampValueChanged(app, event)
            % check safety limits
            if app.FMBursts_amp.Value*app.FMBursts_PW.Value > app.safeLimit
                app.FMBursts_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: FMBursts_min_freq
        function FMBursts_min_freqValueChanged(app, event)
            % Min freq > max freq
            if app.FMBursts_min_freq.Value > app.FMBursts_max_freq.Value
                app.FMBursts_min_freq.Value = app.FMBursts_max_freq.Value;
                warndlg('Min freq is higher than max freq!','Parameter Warning')
            end
            % Frequency exceeds limit
            if app.FMBursts_min_freq.Value > app.maxFreq
                app.FMBursts_min_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
        end

        % Value changed function: FMBursts_max_freq
        function FMBursts_max_freqValueChanged(app, event)
            % Max freq < min freq
            if app.FMBursts_max_freq.Value < app.FMBursts_min_freq.Value
                app.FMBursts_max_freq.Value = app.FMBursts_min_freq.Value;
                warndlg('Max freq is lower than min freq!','Parameter Warning')
            end
            % Frequency exceeds limit
            if app.FMBursts_max_freq.Value > app.maxFreq
                app.FMBursts_max_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
        end

        % Value changed function: FMBursts_PW
        function FMBursts_PWValueChanged(app, event)
            % check safety limits
            if app.FMBursts_amp.Value*app.FMBursts_PW.Value > app.safeLimit
                app.FMBursts_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: Tonic_amp
        function Tonic_ampValueChanged(app, event)
            % check safety limits
            if app.Tonic_amp.Value*app.Tonic_PW.Value > app.safeLimit
                app.Tonic_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: Tonic_freq
        function Tonic_freqValueChanged(app, event)
            % Frequency exceeds limit
            if app.Tonic_freq.Value > app.maxFreq
                app.Tonic_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
        end

        % Value changed function: Tonic_PW
        function Tonic_PWValueChanged(app, event)
            % check safety limits
            if app.Tonic_amp.Value*app.Tonic_PW.Value > app.safeLimit
                app.Tonic_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Button pushed function: StreamButton
        function Stream(app, event)

            app.streaming = ~app.streaming;

            % Streaming = 1
            if app.streaming
                % set firstRun to 0 and check if it changes for further runs
                if app.firstRun
                    app.computeEMGscale = 1;
                    app.firstRun = ~app.firstRun;
                else
                    % clear points in streaming axes
                    clearpoints(app.anLpressure);
                    for ch = 1:length(app.anLEMG)
                        clearpoints(app.anLEMG{ch});
                    end
                    try
                        clearpoints(app.anLstimulation); % sometimes it deletes the stim animated line and I don't know why
                    catch
                    end
                end
                % check if there are other timers and delete them
                timers = timerfind;
                if ~isempty(timers)
                    stop(timers);
                    delete(timers);
                end
                % define timer
                app.appTimer = timer;
                app.appTimer.Period = 1/app.plotUpdateRate;
                app.appTimer.ExecutionMode = 'fixedRate';
                app.appTimer.BusyMode = 'drop';
                % take selected EMG channels
                updateEMGchsChecks(app);
                app.EMGchsToStream = app.EMGchs(logical(app.EMGchsChecks));
                % initialize plots
                % pressure
                app.anLpressure = animatedline(app.pressureAxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',100*app.plotTimeSpan);
                app.pressureAxes.XLim = [0 app.plotTimeSpan];
                % EMG
                for ch = 1:length(app.EMGchsToStream)
                    app.anLEMG{ch} = animatedline(app.EMGaxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',app.fsEMG*(app.plotTimeSpan+1)); % EL: why +1?
                end
                app.EMGaxes.XLim = [0 app.plotTimeSpan];
                app.EMGaxes.YLim = [-1 length(app.EMGchsToStream)+1];
                % stimulation
                app.anLstimulation = animatedline(app.stimAxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',100*app.plotTimeSpan);
                app.stimAxes.XLim = [0 app.plotTimeSpan];
                xlabel(app.stimAxes, ['time [' num2str(app.plotTimeSpan) ' s]']);
                % define timer callback functions
                app.appTimer.StartFcn = @(~,~)tic;
                app.appTimer.TimerFcn = @app.timerCallback;
                app.appTimer.StopFcn = @app.stopTmr;
                % change button appearance
                app.StreamButton.Text = 'Stop';
                app.StreamButton.BackgroundColor = [1 0 0];
                % start timer t and take NIP clock time
                app.t0 = xippmex('time');
                app.prevTimestamp = app.t0;
                start(app.appTimer);

                % ......
            elseif ~app.streaming && strcmp(app.appTimer.Running,'on')   % button pressed while executing ('stop')
                %                 stop(app.t);    % ASSICURATI che chiamando questa funzione
                % vada nella callback stopFcn, salvi (chiami xippmex) e si elimini.
                % altrimenti, mi conviene racchiudere salvataggio ed
                % eliminazione del timer in una funzione che chiamo quando
                % occorre. infatti, se non do TasksToExecute ma premo il
                % bottone, devo assicurarmi che timer vada nella callback
                % di terminazione.

                % delete(app.t);
                % disable button to prevent pressing it while deleting
                % timer
                app.StreamButton.Enable = 0;
                app.StreamButton.BackgroundColor = [0.96 0.96 0.96];
                pause(1)
                % restore start button
                app.StreamButton.BackgroundColor = [0 1 0];
                app.StreamButton.Text = 'Stream';
                app.StreamButton.Enable = 1;
            end
        end

        % Button pushed function: StartStimButton
        function StartStimButtonPushed(app, event)
            % Enable stimulation
            xippmex('stim','enable',1);
            pause(0.5);
            app.stimEN = 1;
            % set flag for stim streaming to 1
            app.streamStim = 1;
            % flush stim buffer by calling spike cmd
            xippmex('spike',app.stimChsID,1);
            % get active stimulation tab and current parameters
            mode = strrep(app.stimSetup.SelectedTab.Title,' ','');
            eval(['stim_params = [app.', mode, '_activeStimTable.Data','];']);
            eval(['stim_params_name = [app.', mode, '_activeStimTable.ColumnName','];']);
            % save stim parameters if recording is on
            app.ccRec = app.ccRec + 1;
            if app.recON
                timeNow = datestr(now,'HH:MM:SS.FFF');
                %save([app.fileNameBase '_stimParams_' num2str(app.ccRec) '.mat'],'stim_params','stim_params_name','timeNow');
                writematrix(timeNow,[app.fileNameBase '_stimParams.txt'],'WriteMode','append');
                writecell(stim_params_name',[app.fileNameBase '_stimParams.txt'],'WriteMode','append');
                writematrix(stim_params,[app.fileNameBase '_stimParams.txt'],'WriteMode','append');
            end
            % make stimulation string
            [stimString,safe,cmdDelay,cmdTotDuration] = make_stim_string_vMultiChs(mode,stim_params,stim_params_name,app.safeLimit,app.stimChsID,app.ampStepSize);
            % create timer to change GUI stim label
            timerStim = timer;
            timerStim.StartDelay = cmdTotDuration;
            timerStim.TimerFcn = @(~,~)stopStimTimer(app);
            % Stimulate if below safety limits!
            if safe
                app.StimLabel.Text = 'Stim: ON';
                app.StimLabel.FontColor = 'r';
                beep;
                start(timerStim);
                for icmd = 1:length(stimString)
                    pause(cmdDelay(icmd));
                    if app.stimEN % otherwise it continues sending the stim commands even after pressing the stop stim button
                        disp(stimString{icmd});
                        xippmex('stim',stimString{icmd});
                    end
                end
            else
                StopStimButtonPushed(app,event);
                warndlg('STIM EXCEEDS SAFETY LIMITS! Stim has been disabled.','Stim Warning')
            end
        end

        % Button pushed function: StopStimButton
        function StopStimButtonPushed(app, event)
            % Disable stimulation
            app.stimEN = 0;
            xippmex('stim', 'enable', 0);
            pause(0.5);
            % Write text on the GUI
            app.StimLabel.Text = 'Stim: OFF';
            app.StimLabel.FontColor = 'k';
        end

        % Button pushed function: SinglePulse_addStimButton
        function SinglePulse_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.SinglePulse_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.SinglePulse_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.SinglePulse_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.SinglePulse_ch.Value));
            if ~isempty(idxRow)
                app.SinglePulse_activeStimTable.Data(idxRow,:) = [str2double(app.SinglePulse_ch.Value), ...
                    app.SinglePulse_cathodic.Value, app.SinglePulse_amp.Value, app.SinglePulse_PW.Value];
                % otherwise add the row
            else
                app.SinglePulse_activeStimTable.Data(end+1,:) = [str2double(app.SinglePulse_ch.Value), ...
                    app.SinglePulse_cathodic.Value, app.SinglePulse_amp.Value, app.SinglePulse_PW.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.SinglePulse_cathodic.Value = 1;
        end

        % Cell selection callback: SinglePulse_activeStimTable
        function SinglePulse_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: SinglePulse_DeleteSelectedRowButton
        function SinglePulse_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.SinglePulse_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.SinglePulse_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: SinglePulse_LoadProtocolButton
        function SinglePulse_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.SinglePulse_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.SinglePulse_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.SinglePulse_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: SinglePulse_SaveProtocolButton
        function SinglePulse_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.SinglePulse_activeStimTable.Data;
            stim_params_name = app.SinglePulse_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: SinglePulse_activeStimTable
        function SinglePulse_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.SinglePulse_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.SinglePulse_activeStimTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.SinglePulse_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==amp_idx
                if value>app.ampLimit
                    app.SinglePulse_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.SinglePulse_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: SingleBurst_addStimButton
        function SingleBurst_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.SingleBurst_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.SingleBurst_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.SingleBurst_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.SingleBurst_ch.Value));
            if ~isempty(idxRow)
                app.SingleBurst_activeStimTable.Data(idxRow,:) = [str2double(app.SingleBurst_ch.Value), ...
                    app.SingleBurst_cathodic.Value, app.SingleBurst_amp.Value, app.SingleBurst_PW.Value, ...
                    app.SingleBurst_freq.Value, app.SingleBurst_dur.Value, app.SingleBurst_delay.Value];
                % otherwise add the row
            else
                app.SingleBurst_activeStimTable.Data(end+1,:) = [str2double(app.SingleBurst_ch.Value), ...
                    app.SingleBurst_cathodic.Value, app.SingleBurst_amp.Value, app.SingleBurst_PW.Value, ...
                    app.SingleBurst_freq.Value, app.SingleBurst_dur.Value, app.SingleBurst_delay.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.SingleBurst_cathodic.Value = 1;
        end

        % Cell selection callback: SingleBurst_activeStimTable
        function SingleBurst_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Cell edit callback: SingleBurst_activeStimTable
        function SingleBurst_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.SingleBurst_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.SingleBurst_activeStimTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.SingleBurst_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==amp_idx
                if value>app.ampLimit
                    app.SingleBurst_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.SingleBurst_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: SingleBurst_DeleteSelectedRowButton
        function SingleBurst_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.SingleBurst_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.SingleBurst_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: SingleBurst_LoadProtocolButton
        function SingleBurst_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.SingleBurst_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.SingleBurst_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.SingleBurst_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: SingleBurst_SaveProtocolButton
        function SingleBurst_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.SingleBurst_activeStimTable.Data;
            stim_params_name = app.SingleBurst_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Button pushed function: RC_addStimButton
        function RC_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.RC_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.RC_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.RC_activeStimTable.Data(:,idxCol)];
            end
            % if there are no active chs, just add the first one
            if isempty(activeChs)
                app.RC_activeStimTable.Data(end+1,:) = [str2double(app.RC_ch.Value), ...
                    app.RC_cathodic.Value, app.RC_min_amp.Value, app.RC_max_amp.Value, ...
                    app.RC_amp_step.Value, app.RC_reps.Value app.RC_PW.Value, app.RC_freq.Value];
                % otherwise check if the ch is already present in the list
            else
                idxRow = find(activeChs==str2double(app.RC_ch.Value));
                % if not present, compute the amp step for the new ch so that it is consistent
                if isempty(idxRow)
                    min_amp_old = app.RC_activeStimTable.Data(find(contains(app.RC_activeStimTable.ColumnName,'min amp')));
                    max_amp_old = app.RC_activeStimTable.Data(find(contains(app.RC_activeStimTable.ColumnName,'max amp')));
                    step_old = app.RC_activeStimTable.Data(find(contains(app.RC_activeStimTable.ColumnName,'step')));
                    n_steps = floor((max_amp_old - min_amp_old ) / step_old);
                    step_new = floor((app.RC_max_amp.Value - app.RC_min_amp.Value) / n_steps);
                    app.RC_activeStimTable.Data(end+1,:) = [str2double(app.RC_ch.Value), ...
                        app.RC_cathodic.Value, app.RC_min_amp.Value, app.RC_max_amp.Value, ...
                        step_new, app.RC_reps.Value app.RC_PW.Value, app.RC_freq.Value];
                    % if the ch is already present in the list of active protocols, substitute the row
                else
                    app.RC_activeStimTable.Data(idxRow,:) = [str2double(app.RC_ch.Value), ...
                        app.RC_cathodic.Value, app.RC_min_amp.Value, app.RC_max_amp.Value, ...
                        app.RC_amp_step.Value, app.RC_reps.Value app.RC_PW.Value, app.RC_freq.Value];
                end
            end
            % block the parameters that must be equal for all the chs
            app.RC_reps.Enable = 'off';
            app.RC_freq.Enable = 'off';
            app.RC_amp_step.Enable = 'off';
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.RC_cathodic.Value = 1;
        end

        % Cell selection callback: RC_activeStimTable
        function RC_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: RC_DeleteSelectedRowButton
        function RC_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.RC_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button and unblock
            % the parameters that mist be equal for all the channels
            if isempty(app.RC_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
                app.RC_reps.Enable = 'on';
                app.RC_freq.Enable = 'on';
                app.RC_amp_step.Enable = 'on';
            end
        end

        % Button pushed function: RC_LoadProtocolButton
        function RC_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.RC_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.RC_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.RC_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: RC_SaveProtocolButton
        function RC_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.RC_activeStimTable.Data;
            stim_params_name = app.RC_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: RC_activeStimTable
        function RC_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.RC_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            min_amp_idx = find(strcmp(app.RC_activeStimTable.ColumnName,'min amp [µA]'));
            max_amp_idx = find(strcmp(app.RC_activeStimTable.ColumnName,'max amp [µA]'));
            pw_idx = find(strcmp(app.RC_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed and min amp <= max amp
            if idx(2)==min_amp_idx
                if value>app.RC_activeStimTable.Data(idx(1),max_amp_idx)
                    app.RC_activeStimTable.Data(idx(1),idx(2)) = app.RC_activeStimTable.Data(idx(1),max_amp_idx);
                end
                if value>app.ampLimit
                    app.RC_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==max_amp_idx
                if value<app.RC_activeStimTable.Data(idx(1),min_amp_idx)
                    app.RC_activeStimTable.Data(idx(1),idx(2)) = app.RC_activeStimTable.Data(idx(1),min_amp_idx);
                end
                if value>app.ampLimit
                    app.RC_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.RC_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: Tonic_addStimButton
        function Tonic_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.Tonic_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.Tonic_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.Tonic_activeStimTable.Data(:,idxCol)];
            end
            % get the termination condition
            if app.Tonic_train_dur_button.Value
                dur = app.Tonic_train_dur.Value;
            else
                dur = 3.6*1e3; % 3600 s = 1 h
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.Tonic_ch.Value));
            if ~isempty(idxRow)
                app.Tonic_activeStimTable.Data(idxRow,:) = [str2double(app.Tonic_ch.Value), ...
                    app.Tonic_cathodic.Value, app.Tonic_amp.Value, app.Tonic_PW.Value, app.Tonic_freq.Value, dur];
                % otherwise add the row
            else
                app.Tonic_activeStimTable.Data(end+1,:) = [str2double(app.Tonic_ch.Value), ...
                    app.Tonic_cathodic.Value, app.Tonic_amp.Value, app.Tonic_PW.Value, app.Tonic_freq.Value, dur];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.Tonic_cathodic.Value = 1;
        end

        % Cell selection callback: Tonic_activeStimTable
        function Tonic_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: Tonic_DeleteSelectedRowButton
        function Tonic_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.Tonic_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.Tonic_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: Tonic_LoadProtocolButton
        function Tonic_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.Tonic_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.Tonic_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.Tonic_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: Tonic_SaveProtocolButton
        function Tonic_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.Tonic_activeStimTable.Data;
            stim_params_name = app.Tonic_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: Tonic_activeStimTable
        function Tonic_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.Tonic_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.Tonic_activeStimTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.Tonic_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==amp_idx
                if value>app.ampLimit
                    app.Tonic_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.Tonic_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: AMBursts_addStimButton
        function AMBursts_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.AMBursts_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.AMBursts_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.AMBursts_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.AMBursts_ch.Value));
            if ~isempty(idxRow)
                app.AMBursts_activeStimTable.Data(idxRow,:) = [str2double(app.AMBursts_ch.Value), ...
                    app.AMBursts_cathodic.Value, app.AMBursts_min_amp.Value, app.AMBursts_max_amp.Value, ...
                    app.AMBursts_amp_step.Value, app.AMBursts_reps.Value app.AMBursts_PW.Value, ...
                    app.AMBursts_freq.Value, app.AMBursts_dur.Value app.AMBursts_IBI.Value];
                % otherwise add the row
            else
                app.AMBursts_activeStimTable.Data(end+1,:) = [str2double(app.AMBursts_ch.Value), ...
                    app.AMBursts_cathodic.Value, app.AMBursts_min_amp.Value, app.AMBursts_max_amp.Value, ...
                    app.AMBursts_amp_step.Value, app.AMBursts_reps.Value app.AMBursts_PW.Value, ...
                    app.AMBursts_freq.Value, app.AMBursts_dur.Value app.AMBursts_IBI.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.AMBursts_cathodic.Value = 1;
        end

        % Cell selection callback: AMBursts_activeStimTable
        function AMBursts_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: AMBursts_DeleteSelectedRowButton
        function AMBursts_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.AMBursts_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.AMBursts_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: AMBursts_LoadProtocolButton
        function AMBursts_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.AMBursts_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.AMBursts_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.AMBursts_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: AMBursts_SaveProtocolButton
        function AMBursts_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.AMBursts_activeStimTable.Data;
            stim_params_name = app.AMBursts_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: AMBursts_activeStimTable
        function AMBursts_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.AMBursts_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            min_amp_idx = find(strcmp(app.AMBursts_activeStimTable.ColumnName,'min amp [µA]'));
            max_amp_idx = find(strcmp(app.AMBursts_activeStimTable.ColumnName,'max amp [µA]'));
            pw_idx = find(strcmp(app.AMBursts_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed and min amp <= max amp
            if idx(2)==min_amp_idx
                if value>app.AMBursts_activeStimTable.Data(idx(1),max_amp_idx)
                    app.AMBursts_activeStimTable.Data(idx(1),idx(2)) = app.AMBursts_activeStimTable.Data(idx(1),max_amp_idx);
                end
                if value>app.ampLimit
                    app.AMBursts_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==max_amp_idx
                if value<app.AMBursts_activeStimTable.Data(idx(1),min_amp_idx)
                    app.AMBursts_activeStimTable.Data(idx(1),idx(2)) = app.AMBursts_activeStimTable.Data(idx(1),min_amp_idx);
                end
                if value>app.ampLimit
                    app.AMBursts_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.AMBursts_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: FMBursts_addStimButton
        function FMBursts_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.FMBursts_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.FMBursts_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.FMBursts_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.FMBursts_ch.Value));
            if ~isempty(idxRow)
                app.FMBursts_activeStimTable.Data(idxRow,:) = [str2double(app.FMBursts_ch.Value), ...
                    app.FMBursts_cathodic.Value, app.FMBursts_amp.Value, app.FMBursts_PW.Value, ...
                    app.FMBursts_min_freq.Value, app.FMBursts_max_freq.Value, app.FMBursts_freq_step.Value, ...
                    app.FMBursts_reps.Value, app.FMBursts_dur.Value, app.FMBursts_IBI.Value];
                % otherwise add the row
            else
                app.FMBursts_activeStimTable.Data(end+1,:) = [str2double(app.FMBursts_ch.Value), ...
                    app.FMBursts_cathodic.Value, app.FMBursts_amp.Value, app.FMBursts_PW.Value, ...
                    app.FMBursts_min_freq.Value, app.FMBursts_max_freq.Value, app.FMBursts_freq_step.Value, ...
                    app.FMBursts_reps.Value, app.FMBursts_dur.Value app.FMBursts_IBI.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.FMBursts_cathodic.Value = 1;
        end

        % Cell selection callback: FMBursts_activeStimTable
        function FMBursts_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: FMBursts_DeleteSelectedRowButton
        function FMBursts_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.FMBursts_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.FMBursts_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: FMBursts_LoadProtocolButton
        function FMBursts_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.FMBursts_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.FMBursts_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.FMBursts_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: FMBursts_SaveProtocolButton
        function FMBursts_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.FMBursts_activeStimTable.Data;
            stim_params_name = app.FMBursts_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: FMBursts_activeStimTable
        function FMBursts_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.FMBursts_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.FMBursts_activeStimTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.FMBursts_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==amp_idx
                if value>app.ampLimit
                    app.FMBursts_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.FMBursts_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Button pushed function: AMSine_addStimButton
        function AMSine_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.AMSine_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.AMSine_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.AMSine_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.AMSine_ch.Value));
            if ~isempty(idxRow)
                app.AMSine_activeStimTable.Data(idxRow,:) = [str2double(app.AMSine_ch.Value), ...
                    app.AMSine_cathodic.Value, app.AMSine_min_amp.Value, app.AMSine_max_amp.Value, ...
                    app.AMSine_PW.Value, app.AMSine_freq.Value, ...
                    app.AMSine_period.Value app.AMSine_dur.Value];
                % otherwise add the row
            else
                app.AMSine_activeStimTable.Data(end+1,:) = [str2double(app.AMSine_ch.Value), ...
                    app.AMSine_cathodic.Value, app.AMSine_min_amp.Value, app.AMSine_max_amp.Value, ...
                    app.AMSine_PW.Value, app.AMSine_freq.Value, ...
                    app.AMSine_period.Value app.AMSine_dur.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.AMSine_cathodic.Value = 1;
        end

        % Button pushed function: AMSine_DeleteSelectedRowButton
        function AMSine_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.AMSine_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.AMSine_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Value changed function: AMSine_min_amp
        function AMSine_min_ampValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.AMSine_min_amp.Value*app.AMSine_PW.Value > app.safeLimit
                app.AMSine_min_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.AMSine_min_amp.Value > app.AMSine_max_amp.Value
                app.AMSine_min_amp.Value = app.AMSine_max_amp.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
        end

        % Value changed function: AMSine_max_amp
        function AMSine_max_ampValueChanged(app, event)
            % check safety limits
            if app.AMSine_max_amp.Value*app.AMSine_PW.Value > app.safeLimit
                app.AMSine_max_amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.AMSine_max_amp.Value < app.AMSine_min_amp.Value
                app.AMSine_max_amp.Value = app.AMSine_min_amp.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
        end

        % Value changed function: AMSine_PW
        function AMSine_PWValueChanged(app, event)
            % check safety limits
            if app.AMSine_min_amp.Value*app.AMSine_PW.Value > app.safeLimit | app.AMSine_max_amp.Value*app.AMSine_PW.Value > app.safeLimit
                app.AMSine_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Cell edit callback: AMSine_activeStimTable
        function AMSine_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.AMSine_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            min_amp_idx = find(strcmp(app.AMSine_activeStimTable.ColumnName,'min amp [µA]'));
            max_amp_idx = find(strcmp(app.AMSine_activeStimTable.ColumnName,'max amp [µA]'));
            pw_idx = find(strcmp(app.AMSine_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed and min amp <= max amp
            if idx(2)==min_amp_idx
                if value>app.AMSine_activeStimTable.Data(idx(1),max_amp_idx)
                    app.AMSine_activeStimTable.Data(idx(1),idx(2)) = app.AMSine_activeStimTable.Data(idx(1),max_amp_idx);
                end
                if value>app.ampLimit
                    app.AMSine_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==max_amp_idx
                if value<app.AMSine_activeStimTable.Data(idx(1),min_amp_idx)
                    app.AMSine_activeStimTable.Data(idx(1),idx(2)) = app.AMSine_activeStimTable.Data(idx(1),min_amp_idx);
                end
                if value>app.ampLimit
                    app.AMSine_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.AMSine_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Cell selection callback: AMSine_activeStimTable
        function AMSine_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end

        % Button pushed function: AMSine_LoadProtocolButton
        function AMSine_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.AMSine_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.AMSine_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.AMSine_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: AMSine_SaveProtocolButton
        function AMSine_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.AMSine_activeStimTable.Data;
            stim_params_name = app.AMSine_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Button pushed function: FMSine_addStimButton
        function FMSine_addStimButtonPushed(app, event)
            % get the chs already in the list of active protocols
            if isempty(app.FMSine_activeStimTable.Data)
                activeChs = [];
            else
                idxCol = find(strcmp(app.FMSine_activeStimTable.ColumnName,'Ch'));
                activeChs = [app.FMSine_activeStimTable.Data(:,idxCol)];
            end
            % if the ch is already present in the list of active protocols, substitute the row
            idxRow = find(activeChs==str2double(app.FMSine_ch.Value));
            if ~isempty(idxRow)
                app.FMSine_activeStimTable.Data(idxRow,:) = [str2double(app.FMSine_ch.Value), ...
                    app.FMSine_cathodic.Value, app.FMSine_amp.Value, app.FMSine_PW.Value, ...
                    app.FMSine_min_freq.Value, app.FMSine_max_freq.Value, ...
                    app.FMSine_period.Value app.FMSine_dur.Value];
                % otherwise add the row
            else
                app.FMSine_activeStimTable.Data(end+1,:) = [str2double(app.FMSine_ch.Value), ...
                    app.FMSine_cathodic.Value, app.FMSine_amp.Value, app.FMSine_PW.Value, ...
                    app.FMSine_min_freq.Value, app.FMSine_max_freq.Value, ...
                    app.FMSine_period.Value app.FMSine_dur.Value];
            end
            % enable the stim button
            app.StartStimButton.Enable = 'on';
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            % reset cathodic-first checkbox to 1
            app.FMSine_cathodic.Value = 1;
        end

        % Button pushed function: FMSine_DeleteSelectedRowButton
        function FMSine_DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRow_activeStimTable;
            if ~isempty(row)
                app.FMSine_activeStimTable.Data(row,:) = [];
                app.selectedRow_activeStimTable = [];
            end
            % if the table is empty, disable the stim button
            if isempty(app.FMSine_activeStimTable.Data)
                app.StartStimButton.Enable = 'off';
            end
        end

        % Button pushed function: FMSine_LoadProtocolButton
        function FMSine_LoadProtocolButtonPushed(app, event)
            % browse file
            [file,path] = uigetfile('*.mat');
            % load file
            if file
                load(strcat(path,file));
                % clear the stim table
                app.FMSine_activeStimTable.Data = [];
                % set the protocol in the stim table
                for iP = 1:length(stim_params_name)
                    idxCol = find(contains(app.FMSine_activeStimTable.ColumnName,stim_params_name{iP}));
                    app.FMSine_activeStimTable.Data(:,idxCol) = stim_params(:,iP);
                end
            end
        end

        % Button pushed function: FMSine_SaveProtocolButton
        function FMSine_SaveProtocolButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.FMSine_activeStimTable.Data;
            stim_params_name = app.FMSine_activeStimTable.ColumnName;
            % select folder and set file name
            [file,path] = uiputfile('*.mat');
            % save protocol
            save(strcat(path,file),'stim_params','stim_params_name');
        end

        % Cell edit callback: FMSine_activeStimTable
        function FMSine_activeStimTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.FMSine_activeStimTable.Data(idx(1),idx(2)); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.FMSine_activeStimTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.FMSine_activeStimTable.ColumnName,'PW [µs]'));
            % check for limit exceed and min amp <= max amp
            if idx(2)==amp_idx
                if value>app.ampLimit
                    app.FMSine_activeStimTable.Data(idx(1),idx(2)) = app.ampLimit;
                end
            elseif idx(2)==pw_idx
                if value>app.pwLimit
                    app.FMSine_activeStimTable.Data(idx(1),idx(2)) = app.pwLimit;
                end
            end
        end

        % Cell selection callback: FMSine_activeStimTable
        function FMSine_activeStimTableCellSelection(app, event)
            % get selected row
            if ~isempty(event.Indices)
                app.selectedRow_activeStimTable = event.Indices(1,1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.InterfaceUIFigure = uifigure('Visible', 'off');
            app.InterfaceUIFigure.Name = 'Interface';
            app.InterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @InterfaceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.InterfaceUIFigure);
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.Panel = uipanel(app.SaveTab);
            app.FileEditFieldLabel = uilabel(app.Panel);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.Panel, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Editable = 'off';
            app.SelectfolderButton = uibutton(app.Panel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.StartRecordingButton = uibutton(app.SaveTab, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonButtonPushed, true);
            app.StartRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'start.jpeg');
            app.StartRecordingButton.Text = '';
            app.PauseRecordingButton = uibutton(app.SaveTab, 'push');
            app.PauseRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseRecordingButtonPushed, true);
            app.PauseRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'pause.jpeg');
            app.PauseRecordingButton.Enable = 'off';
            app.PauseRecordingButton.Text = '';
            app.StopRecordingButton = uibutton(app.SaveTab, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonButtonPushed, true);
            app.StopRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'stop.jpeg');
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Text = '';
            app.RecordingstatusPanel = uipanel(app.SaveTab);
            app.RecordingstatusPanel.Title = 'Recording status';
            app.FilesaveMsg = uitextarea(app.RecordingstatusPanel);
            app.FilesaveMsg.Editable = 'off';
            app.FilesaveMsg.Value = {'Recording is OFF.'};
            app.RecordingLabel = uilabel(app.SaveTab);
            app.RecordingLabel.Text = 'Recording';
            app.StimLampLabel = uilabel(app.SaveTab);
            app.StimLampLabel.Text = 'Stim';
            app.StimLamp = uilamp(app.SaveTab);
            app.EMGLampLabel = uilabel(app.SaveTab);
            app.EMGLampLabel.Text = 'EMG';
            app.EMGLamp = uilamp(app.SaveTab);
            app.AnalogIOLampLabel = uilabel(app.SaveTab);
            app.AnalogIOLampLabel.Text = 'Analog I/O';
            app.AnalogIOLamp = uilamp(app.SaveTab);
            app.DetectedFEsLabel = uilabel(app.SaveTab);
            app.DetectedFEsLabel.Text = 'Detected FEs';
            app.StimulationTab = uitab(app.TabGroup);
            app.StimulationTab.Title = 'Stimulation';
            app.stimSetup = uitabgroup(app.StimulationTab);
            app.stimSetup.SelectionChangedFcn = createCallbackFcn(app, @stimSetupSelectionChanged, true);
            app.SinglePulseTab = uitab(app.stimSetup);
            app.SinglePulseTab.Title = 'Single Pulse';
            app.SinglePulse_activeStimChsPanel = uipanel(app.SinglePulseTab);
            app.SinglePulse_activeStimChsPanel.Title = 'Active stimulation channels';
            app.SinglePulse_SaveProtocolButton = uibutton(app.SinglePulse_activeStimChsPanel, 'push');
            app.SinglePulse_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePulse_SaveProtocolButtonPushed, true);
            app.SinglePulse_SaveProtocolButton.Text = 'Save protocol';
            app.SinglePulse_LoadProtocolButton = uibutton(app.SinglePulse_activeStimChsPanel, 'push');
            app.SinglePulse_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePulse_LoadProtocolButtonPushed, true);
            app.SinglePulse_LoadProtocolButton.Text = 'Load protocol';
            app.SinglePulse_DeleteSelectedRowButton = uibutton(app.SinglePulse_activeStimChsPanel, 'push');
            app.SinglePulse_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePulse_DeleteSelectedRowButtonPushed, true);
            app.SinglePulse_DeleteSelectedRowButton.Text = 'Delete selected row';
            app.SinglePulse_activeStimTable = uitable(app.SinglePulse_activeStimChsPanel);
            app.SinglePulse_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'Amp [µA]'; 'PW [µs]'};
            app.SinglePulse_activeStimTable.RowName = {};
            app.SinglePulse_activeStimTable.SelectionType = 'row';
            app.SinglePulse_activeStimTable.ColumnEditable = true;
            app.SinglePulse_activeStimTable.CellEditCallback = createCallbackFcn(app, @SinglePulse_activeStimTableCellEdit, true);
            app.SinglePulse_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @SinglePulse_activeStimTableCellSelection, true);
            app.SinglePulse_activeStimTable.Multiselect = 'off';
            app.SinglePulse_addStimChPanel = uipanel(app.SinglePulseTab);
            app.SinglePulse_addStimChPanel.Title = 'Add a stimulation channel';
            app.SinglePulse_addStimButton = uibutton(app.SinglePulse_addStimChPanel, 'push');
            app.SinglePulse_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePulse_addStimButtonPushed, true);
            app.SinglePulse_addStimButton.Text = '+';
            app.DurationsLabel = uilabel(app.SinglePulse_addStimChPanel);
            app.DurationsLabel.Text = 'PulseWidth [µs]';
            app.SinglePulse_PW = uieditfield(app.SinglePulse_addStimChPanel, 'numeric');
            app.SinglePulse_PW.Limits = [0 100];
            app.SinglePulse_PW.ValueChangedFcn = createCallbackFcn(app, @SinglePulse_PWValueChanged, true);
            app.SinglePulse_PW.Value = 50;
            app.StimChannelDropDownLabel = uilabel(app.SinglePulse_addStimChPanel);
            app.StimChannelDropDownLabel.Text = 'Stim Channel';
            app.AmplitudeAEditFieldLabel = uilabel(app.SinglePulse_addStimChPanel);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude [µA]';
            app.SinglePulse_amp = uieditfield(app.SinglePulse_addStimChPanel, 'numeric');
            app.SinglePulse_amp.Limits = [0 1000];
            app.SinglePulse_amp.ValueChangedFcn = createCallbackFcn(app, @SinglePulse_ampValueChanged, true);
            app.SinglePulse_amp.Value = 100;
            app.SinglePulse_cathodic = uicheckbox(app.SinglePulse_addStimChPanel);
            app.SinglePulse_cathodic.Text = 'cathodic-first';
            app.SinglePulse_cathodic.Value = true;
            app.SinglePulse_ch = uidropdown(app.SinglePulse_addStimChPanel);
            app.SinglePulse_ch.Items = {'1', '2,', '16'};
            app.SinglePulse_ch.Value = '16';
            app.SinglePulse_descr = uilabel(app.SinglePulseTab);
            app.SinglePulse_descr.Text = 'Deliver a single stimulation pulse per channel.';
            app.SinglePulse_icon = uiimage(app.SinglePulseTab);
            app.SinglePulse_icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'pulse.png');
            app.SingleBurstTab = uitab(app.stimSetup);
            app.SingleBurstTab.Title = 'Single Burst';
            app.SingleBurst_activeStimChsPanel = uipanel(app.SingleBurstTab);
            app.SingleBurst_activeStimChsPanel.Title = 'Active stimulation channels';
            app.SingleBurst_SaveProtocolButton = uibutton(app.SingleBurst_activeStimChsPanel, 'push');
            app.SingleBurst_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBurst_SaveProtocolButtonPushed, true);
            app.SingleBurst_SaveProtocolButton.Text = 'Save protocol';
            app.SingleBurst_LoadProtocolButton = uibutton(app.SingleBurst_activeStimChsPanel, 'push');
            app.SingleBurst_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBurst_LoadProtocolButtonPushed, true);
            app.SingleBurst_LoadProtocolButton.Text = 'Load protocol';
            app.SingleBurst_DeleteSelectedRowButton = uibutton(app.SingleBurst_activeStimChsPanel, 'push');
            app.SingleBurst_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBurst_DeleteSelectedRowButtonPushed, true);
            app.SingleBurst_DeleteSelectedRowButton.Text = 'Delete selected row';
            app.SingleBurst_activeStimTable = uitable(app.SingleBurst_activeStimChsPanel);
            app.SingleBurst_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'Amp [µA]'; 'PW [µs]'; 'Freq [Hz]'; 'Dur [ms]'; 'Delay [ms]'};
            app.SingleBurst_activeStimTable.RowName = {};
            app.SingleBurst_activeStimTable.SelectionType = 'row';
            app.SingleBurst_activeStimTable.ColumnEditable = true;
            app.SingleBurst_activeStimTable.CellEditCallback = createCallbackFcn(app, @SingleBurst_activeStimTableCellEdit, true);
            app.SingleBurst_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @SingleBurst_activeStimTableCellSelection, true);
            app.SingleBurst_activeStimTable.Multiselect = 'off';
            app.SingleBurst_addStimChPanel = uipanel(app.SingleBurstTab);
            app.SingleBurst_addStimChPanel.Title = 'Add a stimulation channel';
            app.SingleBurst_addStimButton = uibutton(app.SingleBurst_addStimChPanel, 'push');
            app.SingleBurst_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @SingleBurst_addStimButtonPushed, true);
            app.SingleBurst_addStimButton.Text = '+';
            app.SingleBurst_delay = uieditfield(app.SingleBurst_addStimChPanel, 'numeric');
            app.SingleBurst_delay.Limits = [0 Inf];
            app.StimChannelDropDownLabel_2 = uilabel(app.SingleBurst_addStimChPanel);
            app.StimChannelDropDownLabel_2.Text = 'Stim Channel';
            app.AmplitudeAEditField_2Label = uilabel(app.SingleBurst_addStimChPanel);
            app.AmplitudeAEditField_2Label.Text = 'Amplitude [µA]';
            app.DurationsLabel_2 = uilabel(app.SingleBurst_addStimChPanel);
            app.DurationsLabel_2.Text = 'PulseWidth [µs]';
            app.FrequencyHzEditFieldLabel_3 = uilabel(app.SingleBurst_addStimChPanel);
            app.FrequencyHzEditFieldLabel_3.Text = 'Frequency [Hz]';
            app.DurationmsLabel = uilabel(app.SingleBurst_addStimChPanel);
            app.DurationmsLabel.Text = 'Duration [ms]';
            app.SingleBurst_dur = uieditfield(app.SingleBurst_addStimChPanel, 'numeric');
            app.SingleBurst_dur.Limits = [0 Inf];
            app.SingleBurst_dur.Value = 1000;
            app.SingleBurst_freq = uieditfield(app.SingleBurst_addStimChPanel, 'numeric');
            app.SingleBurst_freq.Limits = [0 1000];
            app.SingleBurst_freq.ValueChangedFcn = createCallbackFcn(app, @SingleBurst_freqValueChanged, true);
            app.SingleBurst_freq.Value = 30;
            app.SingleBurst_PW = uieditfield(app.SingleBurst_addStimChPanel, 'numeric');
            app.SingleBurst_PW.Limits = [0 100];
            app.SingleBurst_PW.ValueChangedFcn = createCallbackFcn(app, @SingleBurst_PWValueChanged, true);
            app.SingleBurst_PW.Value = 50;
            app.SingleBurst_amp = uieditfield(app.SingleBurst_addStimChPanel, 'numeric');
            app.SingleBurst_amp.Limits = [0 1000];
            app.SingleBurst_amp.ValueChangedFcn = createCallbackFcn(app, @SingleBurst_ampValueChanged, true);
            app.SingleBurst_amp.Value = 100;
            app.SingleBurst_cathodic = uicheckbox(app.SingleBurst_addStimChPanel);
            app.SingleBurst_cathodic.Text = 'cathodic-first';
            app.SingleBurst_cathodic.Value = true;
            app.SingleBurst_ch = uidropdown(app.SingleBurst_addStimChPanel);
            app.SingleBurst_ch.Items = {'1', '2,', '16'};
            app.SingleBurst_ch.Value = '16';
            app.DurationmsLabel_2 = uilabel(app.SingleBurst_addStimChPanel);
            app.DurationmsLabel_2.Text = 'Delay [ms]';
            app.SingleBurst_descr = uilabel(app.SingleBurstTab);
            app.SingleBurst_descr.Text = 'Deliver a single stimulation burst per channel.';
            app.SingleBurst_icon = uiimage(app.SingleBurstTab);
            app.SingleBurst_icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'burst.png');
            app.RCTab = uitab(app.stimSetup);
            app.RCTab.Title = 'RC';
            app.RC_descr = uilabel(app.RCTab);
            app.RC_descr.Text = 'Perform recruitment curves with selected channels.';
            app.RC_addStimChPanel = uipanel(app.RCTab);
            app.RC_addStimChPanel.Title = 'Add a stimulation channel';
            app.RC_addStimButton = uibutton(app.RC_addStimChPanel, 'push');
            app.RC_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @RC_addStimButtonPushed, true);
            app.RC_addStimButton.Text = '+';
            app.StimChannelDropDownLabel_3 = uilabel(app.RC_addStimChPanel);
            app.StimChannelDropDownLabel_3.Text = 'Stim Channel';
            app.minEditFieldLabel = uilabel(app.RC_addStimChPanel);
            app.minEditFieldLabel.Text = 'min';
            app.maxEditFieldLabel = uilabel(app.RC_addStimChPanel);
            app.maxEditFieldLabel.Text = 'max';
            app.FrequencyHzSpinnerLabel = uilabel(app.RC_addStimChPanel);
            app.FrequencyHzSpinnerLabel.Text = 'Frequency [Hz]';
            app.RC_freq = uispinner(app.RC_addStimChPanel);
            app.RC_freq.Limits = [1 2];
            app.RC_freq.Value = 1;
            app.PulseWidthsEditFieldLabel = uilabel(app.RC_addStimChPanel);
            app.PulseWidthsEditFieldLabel.Text = 'PulseWidth [µs]';
            app.RC_PW = uieditfield(app.RC_addStimChPanel, 'numeric');
            app.RC_PW.Limits = [0 100];
            app.RC_PW.ValueChangedFcn = createCallbackFcn(app, @RC_PWValueChanged, true);
            app.RC_PW.Value = 50;
            app.RepetitionsperamplitudeEditFieldLabel = uilabel(app.RC_addStimChPanel);
            app.RepetitionsperamplitudeEditFieldLabel.Text = {'Repetitions'; 'per amplitude'};
            app.RC_reps = uieditfield(app.RC_addStimChPanel, 'numeric');
            app.RC_reps.Limits = [1 100];
            app.RC_reps.Value = 3;
            app.RC_amp_step = uieditfield(app.RC_addStimChPanel, 'numeric');
            app.RC_amp_step.Limits = [1 100];
            app.RC_amp_step.Value = 10;
            app.RC_max_amp = uieditfield(app.RC_addStimChPanel, 'numeric');
            app.RC_max_amp.Limits = [0 Inf];
            app.RC_max_amp.ValueChangedFcn = createCallbackFcn(app, @RC_max_ampValueChanged, true);
            app.RC_max_amp.Value = 200;
            app.RC_min_amp = uieditfield(app.RC_addStimChPanel, 'numeric');
            app.RC_min_amp.Limits = [0 1000];
            app.RC_min_amp.ValueChangedFcn = createCallbackFcn(app, @RC_min_ampValueChanged, true);
            app.RC_min_amp.Value = 50;
            app.RC_amp_label = uilabel(app.RC_addStimChPanel);
            app.RC_amp_label.Text = 'Amplitude [µA]';
            app.RC_cathodic = uicheckbox(app.RC_addStimChPanel);
            app.RC_cathodic.Text = 'cathodic-first';
            app.RC_cathodic.Value = true;
            app.RC_ch = uidropdown(app.RC_addStimChPanel);
            app.RC_ch.Items = {'1', '2,', '16'};
            app.RC_ch.Value = '16';
            app.stepEditFieldLabel = uilabel(app.RC_addStimChPanel);
            app.stepEditFieldLabel.Text = 'step';
            app.RC_activeStimChsPanel = uipanel(app.RCTab);
            app.RC_activeStimChsPanel.Title = 'Active stimulation channels';
            app.RC_SaveProtocolButton = uibutton(app.RC_activeStimChsPanel, 'push');
            app.RC_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @RC_SaveProtocolButtonPushed, true);
            app.RC_SaveProtocolButton.Text = 'Save protocol';
            app.RC_LoadProtocolButton = uibutton(app.RC_activeStimChsPanel, 'push');
            app.RC_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @RC_LoadProtocolButtonPushed, true);
            app.RC_LoadProtocolButton.Text = 'Load protocol';
            app.RC_DeleteSelectedRowButton = uibutton(app.RC_activeStimChsPanel, 'push');
            app.RC_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @RC_DeleteSelectedRowButtonPushed, true);
            app.RC_DeleteSelectedRowButton.Text = 'Delete selected row';
            app.RC_activeStimTable = uitable(app.RC_activeStimChsPanel);
            app.RC_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'min amp [µA]'; 'max amp [µA]'; 'step [µA]'; 'reps'; 'PW [µs]'; 'Freq [Hz]'};
            app.RC_activeStimTable.RowName = {};
            app.RC_activeStimTable.SelectionType = 'row';
            app.RC_activeStimTable.ColumnEditable = true;
            app.RC_activeStimTable.CellEditCallback = createCallbackFcn(app, @RC_activeStimTableCellEdit, true);
            app.RC_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @RC_activeStimTableCellSelection, true);
            app.RC_activeStimTable.Multiselect = 'off';
            app.icon_RC = uiimage(app.RCTab);
            app.icon_RC.ImageSource = fullfile(pathToMLAPP, 'icons', 'RC.png');
            app.AMBurstsTab = uitab(app.stimSetup);
            app.AMBurstsTab.Title = 'AM Bursts';
            app.AMBursts_activeStimChsPanel = uipanel(app.AMBurstsTab);
            app.AMBursts_activeStimChsPanel.Title = 'Active stimulation channels';
            app.AMBursts_SaveProtocolButton = uibutton(app.AMBursts_activeStimChsPanel, 'push');
            app.AMBursts_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @AMBursts_SaveProtocolButtonPushed, true);
            app.AMBursts_SaveProtocolButton.Text = 'Save protocol';
            app.AMBursts_LoadProtocolButton = uibutton(app.AMBursts_activeStimChsPanel, 'push');
            app.AMBursts_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @AMBursts_LoadProtocolButtonPushed, true);
            app.AMBursts_LoadProtocolButton.Text = 'Load protocol';
            app.AMBursts_DeleteSelectedRowButton = uibutton(app.AMBursts_activeStimChsPanel, 'push');
            app.AMBursts_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @AMBursts_DeleteSelectedRowButtonPushed, true);
            app.AMBursts_DeleteSelectedRowButton.Text = {'Delete'; 'selected row'};
            app.AMBursts_activeStimTable = uitable(app.AMBursts_activeStimChsPanel);
            app.AMBursts_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'min amp [µA]'; 'max amp [µA]'; 'step [µA]'; 'reps'; 'PW [µs]'; 'Freq [Hz]'; 'Dur [ms]'; 'IBI [ms]'};
            app.AMBursts_activeStimTable.RowName = {};
            app.AMBursts_activeStimTable.SelectionType = 'row';
            app.AMBursts_activeStimTable.ColumnEditable = true;
            app.AMBursts_activeStimTable.CellEditCallback = createCallbackFcn(app, @AMBursts_activeStimTableCellEdit, true);
            app.AMBursts_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @AMBursts_activeStimTableCellSelection, true);
            app.AMBursts_activeStimTable.Multiselect = 'off';
            app.AMBursts_addStimChPanel = uipanel(app.AMBurstsTab);
            app.AMBursts_addStimChPanel.Title = 'Add a stimulation channel';
            app.AMBursts_addStimButton = uibutton(app.AMBursts_addStimChPanel, 'push');
            app.AMBursts_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @AMBursts_addStimButtonPushed, true);
            app.AMBursts_addStimButton.Text = '+';
            app.MinEditFieldLabel = uilabel(app.AMBursts_addStimChPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MaxEditFieldLabel = uilabel(app.AMBursts_addStimChPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.OffsetLabel_10 = uilabel(app.AMBursts_addStimChPanel);
            app.OffsetLabel_10.Text = 'step [µA]';
            app.RepetitionsperamplitudeEditField_2Label = uilabel(app.AMBursts_addStimChPanel);
            app.RepetitionsperamplitudeEditField_2Label.Text = {'Repetitions'; 'per amplitude'};
            app.PulseWidthsEditField_2Label = uilabel(app.AMBursts_addStimChPanel);
            app.PulseWidthsEditField_2Label.Text = 'PulseWidth [µs]';
            app.IntraBurstFrequencyLabel_8 = uilabel(app.AMBursts_addStimChPanel);
            app.IntraBurstFrequencyLabel_8.Text = 'Intra-Burst frequency [Hz]';
            app.BurstdurationmsLabel_3 = uilabel(app.AMBursts_addStimChPanel);
            app.BurstdurationmsLabel_3.Text = 'Burst duration [ms]';
            app.IntraBurstFrequencyLabel_7 = uilabel(app.AMBursts_addStimChPanel);
            app.IntraBurstFrequencyLabel_7.Text = 'IBI [ms]';
            app.AMBursts_IBI = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_IBI.Limits = [0 Inf];
            app.AMBursts_IBI.Value = 2000;
            app.AMBursts_dur = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_dur.Limits = [0 Inf];
            app.AMBursts_dur.Value = 1000;
            app.AMBursts_freq = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_freq.Limits = [0 1000];
            app.AMBursts_freq.ValueChangedFcn = createCallbackFcn(app, @AMBursts_freqValueChanged, true);
            app.AMBursts_freq.Value = 30;
            app.AMBursts_PW = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_PW.Limits = [0 100];
            app.AMBursts_PW.ValueChangedFcn = createCallbackFcn(app, @AMBursts_PWValueChanged, true);
            app.AMBursts_PW.Value = 50;
            app.AMBursts_reps = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_reps.Limits = [1 20];
            app.AMBursts_reps.Value = 3;
            app.AMBursts_amp_step = uispinner(app.AMBursts_addStimChPanel);
            app.AMBursts_amp_step.Limits = [0 100];
            app.AMBursts_amp_step.Value = 20;
            app.AMBursts_max_amp = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_max_amp.Limits = [0 1000];
            app.AMBursts_max_amp.ValueChangedFcn = createCallbackFcn(app, @AMBursts_max_ampValueChanged, true);
            app.AMBursts_max_amp.Value = 500;
            app.AMBursts_min_amp = uieditfield(app.AMBursts_addStimChPanel, 'numeric');
            app.AMBursts_min_amp.Limits = [0 1000];
            app.AMBursts_min_amp.ValueChangedFcn = createCallbackFcn(app, @AMBursts_min_ampValueChanged, true);
            app.AMBursts_min_amp.Value = 50;
            app.AMBursts_amp_label = uilabel(app.AMBursts_addStimChPanel);
            app.AMBursts_amp_label.Text = 'Amplitude [µA]';
            app.AMBursts_cathodic = uicheckbox(app.AMBursts_addStimChPanel);
            app.AMBursts_cathodic.Text = 'cathodic-first';
            app.AMBursts_cathodic.Value = true;
            app.StimChannelDropDownLabel_4 = uilabel(app.AMBursts_addStimChPanel);
            app.StimChannelDropDownLabel_4.Text = 'Stim Channel';
            app.AMBursts_ch = uidropdown(app.AMBursts_addStimChPanel);
            app.AMBursts_ch.Items = {'1', '2,', '16'};
            app.AMBursts_ch.Value = '16';
            app.AMBursts_descr = uilabel(app.AMBurstsTab);
            app.AMBursts_descr.Text = 'Deliver amplitude-modulated bursts.';
            app.AMBursts_icon = uiimage(app.AMBurstsTab);
            app.AMBursts_icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'AM.png');
            app.FMBurstsTab = uitab(app.stimSetup);
            app.FMBurstsTab.Title = 'FM Bursts';
            app.FMBursts_activeStimChsPanel = uipanel(app.FMBurstsTab);
            app.FMBursts_activeStimChsPanel.Title = 'Active stimulation channels';
            app.FMBursts_SaveProtocolButton = uibutton(app.FMBursts_activeStimChsPanel, 'push');
            app.FMBursts_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @FMBursts_SaveProtocolButtonPushed, true);
            app.FMBursts_SaveProtocolButton.Text = 'Save protocol';
            app.FMBursts_LoadProtocolButton = uibutton(app.FMBursts_activeStimChsPanel, 'push');
            app.FMBursts_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @FMBursts_LoadProtocolButtonPushed, true);
            app.FMBursts_LoadProtocolButton.Text = 'Load protocol';
            app.FMBursts_DeleteSelectedRowButton = uibutton(app.FMBursts_activeStimChsPanel, 'push');
            app.FMBursts_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @FMBursts_DeleteSelectedRowButtonPushed, true);
            app.FMBursts_DeleteSelectedRowButton.Text = {'Delete'; 'selected row'};
            app.FMBursts_activeStimTable = uitable(app.FMBursts_activeStimChsPanel);
            app.FMBursts_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'Amp [µA]'; 'PW [µs]'; 'min freq [Hz]'; 'max freq [Hz]'; 'step [Hz]'; 'reps'; 'Dur [ms]'; 'IBI [ms]'};
            app.FMBursts_activeStimTable.RowName = {};
            app.FMBursts_activeStimTable.SelectionType = 'row';
            app.FMBursts_activeStimTable.ColumnEditable = true;
            app.FMBursts_activeStimTable.CellEditCallback = createCallbackFcn(app, @FMBursts_activeStimTableCellEdit, true);
            app.FMBursts_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @FMBursts_activeStimTableCellSelection, true);
            app.FMBursts_activeStimTable.Multiselect = 'off';
            app.FMBursts_addStimChPanel = uipanel(app.FMBurstsTab);
            app.FMBursts_addStimChPanel.Title = 'Add a stimulation channel';
            app.FMBursts_addStimButton = uibutton(app.FMBursts_addStimChPanel, 'push');
            app.FMBursts_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @FMBursts_addStimButtonPushed, true);
            app.FMBursts_addStimButton.Text = '+';
            app.StimChannelDropDownLabel_5 = uilabel(app.FMBursts_addStimChPanel);
            app.StimChannelDropDownLabel_5.Text = 'Stim Channel';
            app.IntraBurstFrequencyLabel_9 = uilabel(app.FMBursts_addStimChPanel);
            app.IntraBurstFrequencyLabel_9.Text = 'Amplitude [µA]';
            app.PulseWidthsEditField_4Label = uilabel(app.FMBursts_addStimChPanel);
            app.PulseWidthsEditField_4Label.Text = 'PulseWidth [µs]';
            app.BurstdurationmsLabel_4 = uilabel(app.FMBursts_addStimChPanel);
            app.BurstdurationmsLabel_4.Text = 'Burst duration [ms]';
            app.IntraBurstFrequencyLabel_10 = uilabel(app.FMBursts_addStimChPanel);
            app.IntraBurstFrequencyLabel_10.Text = 'IBI [ms]';
            app.FMBursts_IBI = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_IBI.Limits = [0 Inf];
            app.FMBursts_IBI.Value = 2000;
            app.FMBursts_dur = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_dur.Limits = [0 Inf];
            app.FMBursts_dur.Value = 1000;
            app.RepetitionsperfrequencyEditFieldLabel = uilabel(app.FMBursts_addStimChPanel);
            app.RepetitionsperfrequencyEditFieldLabel.Text = {'Repetitions'; 'per frequency'};
            app.FMBursts_reps = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_reps.Limits = [1 20];
            app.FMBursts_reps.Value = 2;
            app.MinEditField_3Label = uilabel(app.FMBursts_addStimChPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.MaxEditField_3Label = uilabel(app.FMBursts_addStimChPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.OffsetLabel_11 = uilabel(app.FMBursts_addStimChPanel);
            app.OffsetLabel_11.Text = 'step [Hz]';
            app.FMBursts_freq_step = uispinner(app.FMBursts_addStimChPanel);
            app.FMBursts_freq_step.Limits = [0 100];
            app.FMBursts_freq_step.Value = 10;
            app.FMBursts_max_freq = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_max_freq.Limits = [0 1000];
            app.FMBursts_max_freq.ValueChangedFcn = createCallbackFcn(app, @FMBursts_max_freqValueChanged, true);
            app.FMBursts_max_freq.Value = 100;
            app.FMBursts_min_freq = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_min_freq.Limits = [0 1000];
            app.FMBursts_min_freq.ValueChangedFcn = createCallbackFcn(app, @FMBursts_min_freqValueChanged, true);
            app.FMBursts_min_freq.Value = 20;
            app.FMBursts_PW = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_PW.Limits = [0 100];
            app.FMBursts_PW.ValueChangedFcn = createCallbackFcn(app, @FMBursts_PWValueChanged, true);
            app.FMBursts_PW.Value = 50;
            app.FMBursts_amp = uieditfield(app.FMBursts_addStimChPanel, 'numeric');
            app.FMBursts_amp.Limits = [0 1000];
            app.FMBursts_amp.ValueChangedFcn = createCallbackFcn(app, @FMBursts_ampValueChanged, true);
            app.FMBursts_amp.Value = 100;
            app.FMBursts_freq_label = uilabel(app.FMBursts_addStimChPanel);
            app.FMBursts_freq_label.Text = 'Frequency [Hz]';
            app.FMBursts_cathodic = uicheckbox(app.FMBursts_addStimChPanel);
            app.FMBursts_cathodic.Text = 'cathodic-first';
            app.FMBursts_cathodic.Value = true;
            app.FMBursts_ch = uidropdown(app.FMBursts_addStimChPanel);
            app.FMBursts_ch.Items = {'1', '2,', '16'};
            app.FMBursts_ch.Value = '16';
            app.FMBursts_descr = uilabel(app.FMBurstsTab);
            app.FMBursts_descr.Text = 'Deliver frequency-modulated bursts.';
            app.FMBursts_icon = uiimage(app.FMBurstsTab);
            app.FMBursts_icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'FM.png');
            app.TonicTab = uitab(app.stimSetup);
            app.TonicTab.Title = 'Tonic';
            app.Tonic_activeStimChsPanel = uipanel(app.TonicTab);
            app.Tonic_activeStimChsPanel.Title = 'Active stimulation channels';
            app.Tonic_SaveProtocolButton = uibutton(app.Tonic_activeStimChsPanel, 'push');
            app.Tonic_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @Tonic_SaveProtocolButtonPushed, true);
            app.Tonic_SaveProtocolButton.Text = 'Save protocol';
            app.Tonic_LoadProtocolButton = uibutton(app.Tonic_activeStimChsPanel, 'push');
            app.Tonic_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @Tonic_LoadProtocolButtonPushed, true);
            app.Tonic_LoadProtocolButton.Text = 'Load protocol';
            app.Tonic_DeleteSelectedRowButton = uibutton(app.Tonic_activeStimChsPanel, 'push');
            app.Tonic_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @Tonic_DeleteSelectedRowButtonPushed, true);
            app.Tonic_DeleteSelectedRowButton.Text = 'Delete selected row';
            app.Tonic_activeStimTable = uitable(app.Tonic_activeStimChsPanel);
            app.Tonic_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'Amp [µA]'; 'PW [µs]'; 'Freq [Hz]'; 'Dur [s]'};
            app.Tonic_activeStimTable.RowName = {};
            app.Tonic_activeStimTable.SelectionType = 'row';
            app.Tonic_activeStimTable.ColumnEditable = true;
            app.Tonic_activeStimTable.CellEditCallback = createCallbackFcn(app, @Tonic_activeStimTableCellEdit, true);
            app.Tonic_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @Tonic_activeStimTableCellSelection, true);
            app.Tonic_activeStimTable.Multiselect = 'off';
            app.Tonic_addStimChPanel = uipanel(app.TonicTab);
            app.Tonic_addStimChPanel.Title = 'Add a stimulation channel';
            app.Tonic_addStimButton = uibutton(app.Tonic_addStimChPanel, 'push');
            app.Tonic_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @Tonic_addStimButtonPushed, true);
            app.Tonic_addStimButton.Text = '+';
            app.Tonic_TC = uibuttongroup(app.Tonic_addStimChPanel);
            app.Tonic_TC.Title = 'Termination Condition';
            app.Tonic_train_dur_button = uiradiobutton(app.Tonic_TC);
            app.Tonic_train_dur_button.Text = 'Train duration [s]';
            app.Tonic_stop_button = uiradiobutton(app.Tonic_TC);
            app.Tonic_stop_button.Text = 'Until STOP button pressed';
            app.Tonic_stop_button.Value = true;
            app.Tonic_train_dur = uieditfield(app.Tonic_TC, 'numeric');
            app.Tonic_train_dur.Limits = [0 Inf];
            app.Tonic_train_dur.Value = 5;
            app.StimChannelDropDownLabel_6 = uilabel(app.Tonic_addStimChPanel);
            app.StimChannelDropDownLabel_6.Text = 'Stim Channel';
            app.AmplitudeALabel = uilabel(app.Tonic_addStimChPanel);
            app.AmplitudeALabel.Text = 'Amplitude [µA]';
            app.DurationsLabel_3 = uilabel(app.Tonic_addStimChPanel);
            app.DurationsLabel_3.Text = 'PulseWidth [µs]';
            app.FrequencyHzEditFieldLabel_2 = uilabel(app.Tonic_addStimChPanel);
            app.FrequencyHzEditFieldLabel_2.Text = 'Frequency [Hz]';
            app.Tonic_freq = uieditfield(app.Tonic_addStimChPanel, 'numeric');
            app.Tonic_freq.Limits = [0 1000];
            app.Tonic_freq.ValueChangedFcn = createCallbackFcn(app, @Tonic_freqValueChanged, true);
            app.Tonic_freq.Value = 30;
            app.Tonic_PW = uieditfield(app.Tonic_addStimChPanel, 'numeric');
            app.Tonic_PW.Limits = [0 100];
            app.Tonic_PW.ValueChangedFcn = createCallbackFcn(app, @Tonic_PWValueChanged, true);
            app.Tonic_PW.Value = 50;
            app.Tonic_amp = uieditfield(app.Tonic_addStimChPanel, 'numeric');
            app.Tonic_amp.Limits = [0 1000];
            app.Tonic_amp.ValueChangedFcn = createCallbackFcn(app, @Tonic_ampValueChanged, true);
            app.Tonic_amp.Value = 100;
            app.Tonic_cathodic = uicheckbox(app.Tonic_addStimChPanel);
            app.Tonic_cathodic.Text = 'cathodic-first';
            app.Tonic_cathodic.Value = true;
            app.Tonic_ch = uidropdown(app.Tonic_addStimChPanel);
            app.Tonic_ch.Items = {'1', '2,', '16'};
            app.Tonic_ch.Value = '16';
            app.Tonic_icon = uiimage(app.TonicTab);
            app.Tonic_icon.ImageSource = fullfile(pathToMLAPP, 'icons', 'tonic2.png');
            app.Tonic_descr = uilabel(app.TonicTab);
            app.Tonic_descr.Text = {'Deliver tonic stimulation.'; ''};
            app.AMSineTab = uitab(app.stimSetup);
            app.AMSineTab.Title = 'AM Sine';
            app.AMSine_activeStimChsPanel = uipanel(app.AMSineTab);
            app.AMSine_activeStimChsPanel.Title = 'Active stimulation channels';
            app.AMSine_LoadProtocolButton = uibutton(app.AMSine_activeStimChsPanel, 'push');
            app.AMSine_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @AMSine_LoadProtocolButtonPushed, true);
            app.AMSine_LoadProtocolButton.Text = 'Load protocol';
            app.AMSine_DeleteSelectedRowButton = uibutton(app.AMSine_activeStimChsPanel, 'push');
            app.AMSine_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @AMSine_DeleteSelectedRowButtonPushed, true);
            app.AMSine_DeleteSelectedRowButton.Text = {'Delete'; 'selected row'};
            app.AMSine_activeStimTable = uitable(app.AMSine_activeStimChsPanel);
            app.AMSine_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'min amp [µA]'; 'max amp [µA]'; 'PW [µs]'; 'Freq [Hz]'; 'Sine period [s]'; 'Dur [s]'};
            app.AMSine_activeStimTable.RowName = {};
            app.AMSine_activeStimTable.SelectionType = 'row';
            app.AMSine_activeStimTable.ColumnEditable = true;
            app.AMSine_activeStimTable.CellEditCallback = createCallbackFcn(app, @AMSine_activeStimTableCellEdit, true);
            app.AMSine_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @AMSine_activeStimTableCellSelection, true);
            app.AMSine_activeStimTable.Multiselect = 'off';
            app.AMSine_SaveProtocolButton = uibutton(app.AMSine_activeStimChsPanel, 'push');
            app.AMSine_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @AMSine_SaveProtocolButtonPushed, true);
            app.AMSine_SaveProtocolButton.Text = 'Save protocol';
            app.AMSine_addStimChPanel = uipanel(app.AMSineTab);
            app.AMSine_addStimChPanel.Title = 'Add a stimulation channel';
            app.AMSine_addStimButton = uibutton(app.AMSine_addStimChPanel, 'push');
            app.AMSine_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @AMSine_addStimButtonPushed, true);
            app.AMSine_addStimButton.Text = '+';
            app.AMSine_dur = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_dur.Limits = [0 3600];
            app.AMSine_dur.Value = 15;
            app.AMSine_period = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_period.Limits = [1 10];
            app.AMSine_period.Value = 3;
            app.MinampLabel = uilabel(app.AMSine_addStimChPanel);
            app.MinampLabel.Text = 'Min amp [µA]';
            app.MaxampALabel = uilabel(app.AMSine_addStimChPanel);
            app.MaxampALabel.Text = 'Max amp [µA]';
            app.PulseWidthsEditField_2Label_2 = uilabel(app.AMSine_addStimChPanel);
            app.PulseWidthsEditField_2Label_2.Text = 'PulseWidth [µs]';
            app.IntraBurstFrequencyLabel_11 = uilabel(app.AMSine_addStimChPanel);
            app.IntraBurstFrequencyLabel_11.Text = 'Frequency [Hz]';
            app.AMSine_freq = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_freq.Limits = [0 1000];
            app.AMSine_freq.Value = 30;
            app.AMSine_PW = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_PW.Limits = [0 100];
            app.AMSine_PW.ValueChangedFcn = createCallbackFcn(app, @AMSine_PWValueChanged, true);
            app.AMSine_PW.Value = 50;
            app.AMSine_max_amp = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_max_amp.Limits = [0 1000];
            app.AMSine_max_amp.ValueChangedFcn = createCallbackFcn(app, @AMSine_max_ampValueChanged, true);
            app.AMSine_max_amp.Value = 500;
            app.AMSine_min_amp = uieditfield(app.AMSine_addStimChPanel, 'numeric');
            app.AMSine_min_amp.Limits = [0 1000];
            app.AMSine_min_amp.ValueChangedFcn = createCallbackFcn(app, @AMSine_min_ampValueChanged, true);
            app.AMSine_min_amp.Value = 100;
            app.BurstdurationmsLabel_5 = uilabel(app.AMSine_addStimChPanel);
            app.BurstdurationmsLabel_5.Text = 'Tot duration [s]';
            app.StimChannelDropDownLabel_7 = uilabel(app.AMSine_addStimChPanel);
            app.StimChannelDropDownLabel_7.Text = 'Stim Channel';
            app.SineperiodsEditFieldLabel = uilabel(app.AMSine_addStimChPanel);
            app.SineperiodsEditFieldLabel.Text = 'Sine period [s]';
            app.AMSine_cathodic = uicheckbox(app.AMSine_addStimChPanel);
            app.AMSine_cathodic.Text = 'cathodic-first';
            app.AMSine_cathodic.Value = true;
            app.AMSine_ch = uidropdown(app.AMSine_addStimChPanel);
            app.AMSine_ch.Items = {'1', '2,', '16'};
            app.AMSine_ch.Value = '16';
            app.FMSineTab = uitab(app.stimSetup);
            app.FMSineTab.Title = 'FM Sine';
            app.FMSine_activeStimChsPanel = uipanel(app.FMSineTab);
            app.FMSine_activeStimChsPanel.Title = 'Active stimulation channels';
            app.FMSine_SaveProtocolButton = uibutton(app.FMSine_activeStimChsPanel, 'push');
            app.FMSine_SaveProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @FMSine_SaveProtocolButtonPushed, true);
            app.FMSine_SaveProtocolButton.Text = 'Save protocol';
            app.FMSine_LoadProtocolButton = uibutton(app.FMSine_activeStimChsPanel, 'push');
            app.FMSine_LoadProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @FMSine_LoadProtocolButtonPushed, true);
            app.FMSine_LoadProtocolButton.Text = 'Load protocol';
            app.FMSine_DeleteSelectedRowButton = uibutton(app.FMSine_activeStimChsPanel, 'push');
            app.FMSine_DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @FMSine_DeleteSelectedRowButtonPushed, true);
            app.FMSine_DeleteSelectedRowButton.Text = {'Delete'; 'selected row'};
            app.FMSine_activeStimTable = uitable(app.FMSine_activeStimChsPanel);
            app.FMSine_activeStimTable.ColumnName = {'Ch'; 'cathodic'; 'Amp [µA]'; 'PW [µs]'; 'min freq [Hz]'; 'max freq [Hz]'; 'Sine period [s]'; 'Dur [ms]'};
            app.FMSine_activeStimTable.RowName = {};
            app.FMSine_activeStimTable.SelectionType = 'row';
            app.FMSine_activeStimTable.ColumnEditable = true;
            app.FMSine_activeStimTable.CellEditCallback = createCallbackFcn(app, @FMSine_activeStimTableCellEdit, true);
            app.FMSine_activeStimTable.CellSelectionCallback = createCallbackFcn(app, @FMSine_activeStimTableCellSelection, true);
            app.FMSine_activeStimTable.Multiselect = 'off';
            app.FMSine_addStimChPanel = uipanel(app.FMSineTab);
            app.FMSine_addStimChPanel.Title = 'Add a stimulation channel';
            app.FMSine_addStimButton = uibutton(app.FMSine_addStimChPanel, 'push');
            app.FMSine_addStimButton.ButtonPushedFcn = createCallbackFcn(app, @FMSine_addStimButtonPushed, true);
            app.FMSine_addStimButton.Text = '+';
            app.FMSine_dur = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_dur.Limits = [0 3600];
            app.FMSine_dur.Value = 15;
            app.BurstdurationmsLabel_6 = uilabel(app.FMSine_addStimChPanel);
            app.BurstdurationmsLabel_6.Text = 'Tot duration [s]';
            app.FMSine_period = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_period.Limits = [1 10];
            app.FMSine_period.Value = 3;
            app.SineperiodsEditFieldLabel_2 = uilabel(app.FMSine_addStimChPanel);
            app.SineperiodsEditFieldLabel_2.Text = 'Sine period [s]';
            app.MaxampALabel_2 = uilabel(app.FMSine_addStimChPanel);
            app.MaxampALabel_2.Text = 'Max freq [Hz]';
            app.FMSine_max_freq = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_max_freq.Limits = [0 1000];
            app.FMSine_max_freq.Value = 100;
            app.MinampLabel_2 = uilabel(app.FMSine_addStimChPanel);
            app.MinampLabel_2.Text = 'Min freq [Hz]';
            app.FMSine_min_freq = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_min_freq.Limits = [0 1000];
            app.FMSine_min_freq.Value = 20;
            app.PulseWidthsEditField_2Label_3 = uilabel(app.FMSine_addStimChPanel);
            app.PulseWidthsEditField_2Label_3.Text = 'PulseWidth [µs]';
            app.FMSine_PW = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_PW.Limits = [0 100];
            app.FMSine_PW.Value = 50;
            app.IntraBurstFrequencyLabel_12 = uilabel(app.FMSine_addStimChPanel);
            app.IntraBurstFrequencyLabel_12.Text = 'Amplitude [µA]';
            app.FMSine_amp = uieditfield(app.FMSine_addStimChPanel, 'numeric');
            app.FMSine_amp.Limits = [0 1000];
            app.FMSine_amp.Value = 200;
            app.StimChannelDropDownLabel_8 = uilabel(app.FMSine_addStimChPanel);
            app.StimChannelDropDownLabel_8.Text = 'Stim Channel';
            app.FMSine_ch = uidropdown(app.FMSine_addStimChPanel);
            app.FMSine_ch.Items = {'1', '2,', '16'};
            app.FMSine_ch.Value = '1';
            app.FMSine_cathodic = uicheckbox(app.FMSine_addStimChPanel);
            app.FMSine_cathodic.Text = 'cathodic-first';
            app.FMSine_cathodic.Value = true;
            app.StartStimButton = uibutton(app.StimulationTab, 'push');
            app.StartStimButton.ButtonPushedFcn = createCallbackFcn(app, @StartStimButtonPushed, true);
            app.StartStimButton.Icon = fullfile(pathToMLAPP, 'icons', 'start.jpeg');
            app.StartStimButton.Enable = 'off';
            app.StartStimButton.Text = '';
            app.StopStimButton = uibutton(app.StimulationTab, 'push');
            app.StopStimButton.ButtonPushedFcn = createCallbackFcn(app, @StopStimButtonPushed, true);
            app.StopStimButton.Icon = fullfile(pathToMLAPP, 'icons', 'stop.jpeg');
            app.StopStimButton.Text = '';
            app.StimLabel = uilabel(app.StimulationTab);
            app.StimLabel.Text = 'Stim: OFF';
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.pressureAxes = uiaxes(app.StreamingTab);
            ylabel(app.pressureAxes, 'Pressure level')
            zlabel(app.pressureAxes, 'Z')
            app.pressureAxes.YLim = [2000 20000];
            app.pressureAxes.XTick = [];
            app.pressureAxes.YTick = [];
            app.EMGaxes = uiaxes(app.StreamingTab);
            ylabel(app.EMGaxes, 'EMG channel')
            zlabel(app.EMGaxes, 'Z')
            app.EMGaxes.YLim = [2000 20000];
            app.EMGaxes.XTick = [];
            app.EMGaxes.YTick = [];
            app.stimAxes = uiaxes(app.StreamingTab);
            ylabel(app.stimAxes, 'Stim')
            zlabel(app.stimAxes, 'Z')
            app.stimAxes.XTick = [];
            app.stimAxes.YTick = [];
            app.ObjectshapeLabel = uilabel(app.StreamingTab);
            app.ObjectshapeLabel.Text = 'Object';
            app.ChannelsPanel = uipanel(app.StreamingTab);
            app.ChannelsPanel.Title = 'Channels';
            app.Ch1CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Ch2CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch3CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Text = 'Ch3';
            app.Ch4CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Text = 'Ch4';
            app.Ch5CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch5CheckBoxValueChanged, true);
            app.Ch5CheckBox.Text = 'Ch5';
            app.Ch6CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch6CheckBoxValueChanged, true);
            app.Ch6CheckBox.Text = 'Ch6';
            app.Ch7CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch7CheckBoxValueChanged, true);
            app.Ch7CheckBox.Text = 'Ch7';
            app.Ch8CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch8CheckBoxValueChanged, true);
            app.Ch8CheckBox.Text = 'Ch8';
            app.Ch9CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch9CheckBoxValueChanged, true);
            app.Ch9CheckBox.Text = 'Ch9';
            app.Ch10CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch10CheckBoxValueChanged, true);
            app.Ch10CheckBox.Text = 'Ch10';
            app.Ch11CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch11CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch11CheckBoxValueChanged, true);
            app.Ch11CheckBox.Text = 'Ch11';
            app.Ch12CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch12CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch12CheckBoxValueChanged, true);
            app.Ch12CheckBox.Text = 'Ch12';
            app.allChs = uicheckbox(app.ChannelsPanel);
            app.allChs.ValueChangedFcn = createCallbackFcn(app, @allChsValueChanged, true);
            app.allChs.Text = '';
            app.ObjectListBox = uilistbox(app.StreamingTab);
            app.ObjectListBox.Items = {'Pinch', 'Cyl_S', 'Cyl_L', 'Ball_S', 'Ball_L'};
            app.ObjectListBox.Value = 'Pinch';
            app.GainDropDownLabel = uilabel(app.StreamingTab);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.StreamingTab);
            app.GainDropDown.Items = {'1', '2', '8', '16', '32', '64'};
            app.GainDropDown.Value = '8';
            app.StreamButton = uibutton(app.StreamingTab, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @Stream, true);
            app.StreamButton.Text = 'Stream';
            % Show the figure after all components are created
            app.InterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_characterization_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceUIFigure)
        end
    end
end