classdef controlGUI_switchOnly < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ConfigTab                   matlab.ui.container.Tab
        InitializeDevicesPanel      matlab.ui.container.Panel
        SerialPorts                 matlab.ui.control.DropDown
        StimulatorConnectButton     matlab.ui.control.Button
        PillowConnectButton         matlab.ui.control.Button
        StimulatorLamp              matlab.ui.control.Lamp
        StatusLamp_3Label           matlab.ui.control.Label
        PillowLamp                  matlab.ui.control.Lamp
        StatusLamp_2Label           matlab.ui.control.Label
        StimulationParametersPanel  matlab.ui.container.Panel
        AddGesturePanel             matlab.ui.container.Panel
        Gesture                     matlab.ui.control.EditField
        StimCh                      matlab.ui.control.DropDown
        Dur                         matlab.ui.control.NumericEditField
        DurationsEditFieldLabel     matlab.ui.control.Label
        PW                          matlab.ui.control.DropDown
        PulseWidthsDropDownLabel    matlab.ui.control.Label
        Amp                         matlab.ui.control.NumericEditField
        AmpLabel                    matlab.ui.control.Label
        Freq                        matlab.ui.control.NumericEditField
        FreqLabel                   matlab.ui.control.Label
        addStimButton               matlab.ui.control.Button
        StimChannelDropDownLabel    matlab.ui.control.Label
        GestureEditFieldLabel       matlab.ui.control.Label
        ActiveStimProtocolsPanel    matlab.ui.container.Panel
        ActiveStimProtocolsTable    matlab.ui.control.Table
        DeleteSelectedRowButton     matlab.ui.control.Button
        SaveParametersButton        matlab.ui.control.Button
        LoadParametersButton        matlab.ui.control.Button
        DataStreamTab               matlab.ui.container.Tab
        BinaryCmdLabel              matlab.ui.control.Label
        StimulateButtonGroup        matlab.ui.container.ButtonGroup
        StimLabel                   matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        BinCmdAxes                  matlab.ui.control.UIAxes
        StimAxes                    matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end


    properties (Access = private)

        % Class instances
        xipp;                   % xippmex object
        pillow;                 % pillow object
        plots;                  % plot object

        % Ripple variables
        newRipple = 1;          % flag for New Ripple Summit
        stimRes = 4;            % stim resolution (1 = 1 uA/step, ......, 4 = 10 uA/step)
        
        % Parameters that are get from the GUI
        paramsGUI;

        % Stimulation variables
        safeLimit = 120*1e3;    % charge safety limit [pC]
        ampLimit = 1000;        % amplitude limit in the GUI [uA]
        pwLimit = 100;          % pulse-width limit in the GUI [us]
        stimEN = 0;             % flag for stimulation enable

        % Saving variables
        saveFolder = "C:\Users\m.ceradini\Desktop\Elena\Code Regrap\Rewire_Project\Data";    % folder where to save recordings. By defaut it is current directory

        % Other variables
        selectedRowTable = [];  % to select rows from the active stim protocols table

    end

    methods (Static)

        function outCell = Mystr2double(inCell)
            outCell = cellfun(@(x) strsplit(x, ','), inCell, 'UniformOutput', false);
            maxL = max(cellfun(@length, outCell));
            for i = 1:length(outCell)
                for ii = length(outCell{i})+1:maxL
                    outCell{i}{ii} = '';
                end
            end
            outCell = vertcat(outCell{:});
            outCell  = str2double(outCell);
        end
    end

    methods (Access = private)

        % Create filepath
        function filePath = computeFilePath(app)

            % Current date and time
            dateStr = string(datetime('now','Format','yyyy-MM-dd'));
            timeStr = string(datetime('now','Format','HH-mm-ss'));
            % Find all the files of today
            fileNameList = extractfield(dir(app.saveFolder),'name');
            filesToday = fileNameList(find(contains(fileNameList,dateStr)));
            % Find the recording numbers of today
            recsToday = [];
            for iT = 1:length(filesToday)
                recIdx = strfind(filesToday{iT},'rec');
                recsToday(iT) = str2double(regexp(filesToday{iT}(recIdx+3:recIdx+5),'\d*','Match'));
            end
            maxRecNb = max(recsToday);
            if isempty(maxRecNb)
                maxRecNb = 0;
            end
            % Compute recording number incrementally
            recNb = maxRecNb + 1;
            % create file name
            fileName = [dateStr '-' timeStr '-rec' num2str(recNb)];
            % update field with path + user-written text
            filePath = strjoin([app.saveFolder,filesep,fileName],'');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path for auxiliary functions
            addpath(genpath(fileparts(mfilename('fullpath'))));
            % instantiate the classes
            app.xipp = xippmex_handler_switchOnly;
            app.pillow = pillowbutton_handler_switchOnly;
            app.plots = plot_handler_switchOnly;
            % complete serial ports list
            app.SerialPorts.Items = serialportlist;
            app.SerialPorts.Value = app.SerialPorts.Items{end};

        end

        % Button pushed function: StimulatorConnectButton
        function StimulatorConnectButtonPushed(app, event)
            % Connect with stimulator and setup stimulation
            status = app.xipp.initializeXipp(app.newRipple);
            % If connected with xippmex ...
            if status
                app.StimulatorLamp.Color = 'g'; % ... turn on the lamp
                app.xipp.setupStim(app.newRipple,app.stimRes); % ... set up stimulation
                app.StimCh.Items = string(num2cell(1:length(app.xipp.stimChsID))); % ... fill StimChannelDropDown with available stim channels
            end
        end

        % Button pushed function: PillowConnectButton
        function PillowConnectButtonPushed(app, event)
            % Connect with Pillow
            status = app.pillow.openSerial(app.SerialPorts.Value);
            % If connected, turn on the lamp
            if status
                app.PillowLamp.Color = 'g';
            end
        end

        % Callback function
        function PWValueChanged(app, event)
            % check safety limits
            if  app.Amp.Value*app.PW.Value > app.safeLimit
                app.PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: Amp
        function AmpValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.Amp.Value*app.PW.Value > app.safeLimit
                app.Amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Button pushed function: addStimButton
        function addStimButtonPushed(app, event)
            % get the gestures already in the list of active protocols
            if isempty(app.ActiveStimProtocolsTable.Data)
                activeGestures = {};
                app.ActiveStimProtocolsTable.Data = {};
            else
                idxCol = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Gesture'));
                activeGestures = app.ActiveStimProtocolsTable.Data(:,idxCol);
            end
            % # active protocols
            nActive = length(activeGestures);
            % if the gesture is already present in the list, add the new protocol
            idxRow = find(strcmp(activeGestures,app.Gesture.Value));
            if ~isempty(idxRow)
                app.ActiveStimProtocolsTable.Data(idxRow,:) = {idxRow, app.Gesture.Value, ...
                    [app.ActiveStimProtocolsTable.Data{idxRow,3} ',' app.StimCh.Value], ...
                    [app.ActiveStimProtocolsTable.Data{idxRow,4} ',' app.PW.Value], ...
                    [app.ActiveStimProtocolsTable.Data{idxRow,5} ',' num2str(app.Amp.Value)], ...
                    [app.ActiveStimProtocolsTable.Data{idxRow,6} ',' num2str(app.Freq.Value)], ...
                    [app.ActiveStimProtocolsTable.Data{idxRow,7} ',' num2str(app.Dur.Value)]};
                % otherwise add the row
            else
                app.ActiveStimProtocolsTable.Data(end+1,:) = {num2str(nActive+1), ...
                    app.Gesture.Value, app.StimCh.Value, app.PW.Value, ...
                    num2str(app.Amp.Value), num2str(app.Freq.Value), ...
                    num2str(app.Dur.Value)};
            end
        end

        % Button pushed function: DeleteSelectedRowButton
        function DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRowTable;
            if ~isempty(row)
                app.ActiveStimProtocolsTable.Data(row,:) = [];
                app.selectedRowTable = [];
            end
            % if the table is empty, disable the START button
            if isempty(app.ActiveStimProtocolsTable.Data)
                app.StartButton.Enable = 'off';
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.ActiveStimProtocolsTable.Data;
            stim_params_name = app.ActiveStimProtocolsTable.ColumnName';
            % select folder and set file name
            path = uiputfile('*.mat');
            % save protocol
            save(path,'stim_params','stim_params_name');
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            % browse file
            path = uigetfile('*.mat');
            % load file
            load(path);
            % check if the parameters name match with those in the table
            if isequal(app.ActiveStimProtocolsTable.ColumnName,stim_params_name')
                % set the parameters in the stim table
                app.ActiveStimProtocolsTable.Data = stim_params;
            else
                errordlg('Parameters do not match with current stimulation protocol')
            end
        end

        % Cell selection callback: ActiveStimProtocolsTable
        function ActiveStimProtocolsTableCellSelection(app, event)
            if ~isempty(event.Indices)
                app.selectedRowTable = event.Indices(1,1);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, 'Data Stream')
                if app.StimulatorLamp.Color==[0 1 0] & app.PillowLamp.Color==[0 1 0] & ...
                        ~isempty(app.ActiveStimProtocolsTable.Data)
                    app.StartButton.Enable = 'on';
                else
                    app.StartButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Assign stimulation parameters
            app.paramsGUI.gestures = app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Gesture')));
            app.paramsGUI.nGestures = length(app.paramsGUI.gestures);
            app.paramsGUI.stimCh = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Ch'))));
            app.paramsGUI.stimPW = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'PW [µs]'))));
            app.paramsGUI.stimAmp = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Amp [µA]'))));
            app.paramsGUI.stimFreq = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Freq [Hz]'))));
            app.paramsGUI.stimDur = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Dur [s]'))));
            % Initialize the stimulation parameters
            app.xipp.gestureIdx = 1;
            app.xipp.gestureName = app.paramsGUI.gestures{1};
            app.xipp.stimCh = app.paramsGUI.stimCh(1,:);
            app.xipp.stimPW = app.paramsGUI.stimPW(1,:);
            app.xipp.stimAmp = app.paramsGUI.stimAmp(1,:);
            app.xipp.stimFreq = app.paramsGUI.stimFreq(1,:);
            % initialize plots
            loopFreq = app.pillow.loopFreq;
            cla(app.BinCmdAxes);
            cla(app.StimAxes);
            app.plots.initializePlot(app.BinCmdAxes, app.StimAxes, loopFreq, app.paramsGUI);
            % start the streaming
            ccPlot = 0;
            % Compute new file path
            newFilePath = computeFilePath(app);
            newFilePathParams = strjoin([newFilePath,'-params'],'');
            newFilePathState = strjoin([newFilePath,'-state'],'');
            % Save stim parameters in txt file
            writecell(app.ActiveStimProtocolsTable.ColumnName',newFilePathParams,'WriteMode','append');
            writecell(app.ActiveStimProtocolsTable.Data,newFilePathParams,'WriteMode','append');
            % Take current time and save it in txt file
            timeStart = datestr(now,'HH:MM:SS.FFF');
            writematrix('timeStart',newFilePath,'WriteMode','append','Quotestrings','none');
            writematrix(timeStart,newFilePath,'WriteMode','append');
            % Write labels in txt files
            writecell({'time','state'},newFilePathState,'WriteMode','append','Quotestrings','none');
            writecell({'time','binSig','stimCh','stimAmp','stimFreq'},newFilePath,'WriteMode','append','Quotestrings','none');
            % Set the stim commands of the gestures
            app.xipp.setGestureCmd(app.paramsGUI);
            % Enable stimulation
            app.xipp.enableStim();
            app.stimEN = 1;
            % Change the stim label on the GUI
            app.StimLabel.Text = 'Stim: ON';
            app.StimLabel.FontColor = 'r';
            % Send the first stim command
            app.xipp.sendStimCmd();
            % Start the control loop
            r = rateControl(loopFreq);
            reset(r);
            ticStart = tic;
            while(app.stimEN)
                ticLoop = tic;
                % -------------------- BINARY CONTROL --------------------
                app.pillow.readButtonState();
                app.BinaryCmdLabel.Text = [app.pillow.stateLabel app.xipp.gestureName];
                if app.pillow.binCmd
                    % Change the grasp type
                    app.xipp.switchStim(app.paramsGUI);
                    % Send stimulation cmd
                    app.xipp.sendStimCmd();
                end
                % --------------------------------------------------------
                % ---------------------- PLOT ----------------------------
                % ccPlot = ccPlot + 1;
                % app.plots.plotBinCmd(ccPlot, app.pillow.binSig);
                % app.plots.plotStimVar(ccPlot, app.xipp.stimCh)
                % --------------------------------------------------------
                % --------------------- SAVE DATA ------------------------
                writematrix([toc(ticStart) app.pillow.binSig app.xipp.stimCh ...
                     app.xipp.stimAmp app.xipp.stimFreq], newFilePath, 'WriteMode', 'append');
                writecell({num2str(toc(ticStart)) app.BinaryCmdLabel.Text}, newFilePathState, 'WriteMode', 'append');
                % ----------------------------------------------------
                waitfor(r);
                disp(toc(ticLoop))
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Disable stimulation
            app.xipp.disableStim();
            app.stimEN = 0;
            % Change the stim label in the GUI
            app.StimLabel.Text = 'Stim: OFF';
            app.StimLabel.FontColor = 'k';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disable stim and close xippmex if active
            if app.StimulatorLamp.Color==[0 1 0]
                app.xipp.disableStim();
                app.xipp.close();
            end
            % Close pillow serial port if active
            if app.PillowLamp.Color==[0 1 0]
                app.pillow.closeSerial();
            end
            % delete app
            delete(app)
        end

        % Cell edit callback: ActiveStimProtocolsTable
        function ActiveStimProtocolsTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.Mystr2double(app.ActiveStimProtocolsTable.Data(idx(1),idx(2))); % value of selected cell
            % find column idx of amp and pw
            amp_idx = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Amp [µA]'));
            pw_idx = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==amp_idx
                idx_overLimit = find(value>app.ampLimit);
                if ~isempty(idx_overLimit)
                    value(idx_overLimit) = app.ampLimit;
                    app.ActiveStimProtocolsTable.Data(idx(1),idx(2)) = {regexprep(num2str(value),'\s+',',')};
                end
            elseif idx(2)==pw_idx
                idx_overLimit = find(value>app.pwLimit);
                if ~isempty(idx_overLimit)
                    value(idx_overLimit) = app.pwLimit;
                    app.ActiveStimProtocolsTable.Data(idx(1),idx(2)) = {regexprep(num2str(value),'\s+',',')};
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.StimulationParametersPanel = uipanel(app.ConfigTab);
            app.StimulationParametersPanel.Title = 'Stimulation Parameters';
            app.ActiveStimProtocolsPanel = uipanel(app.StimulationParametersPanel);
            app.ActiveStimProtocolsPanel.Title = 'Active stim protocols';
            app.LoadParametersButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.SaveParametersButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.DeleteSelectedRowButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedRowButtonPushed, true);
            app.DeleteSelectedRowButton.Text = 'Delete selected row';
            app.ActiveStimProtocolsTable = uitable(app.ActiveStimProtocolsPanel);
            app.ActiveStimProtocolsTable.ColumnName = {'State Nb'; 'Gesture'; 'Ch'; 'PW [µs]'; 'Amp [µA]'; 'Freq [Hz]'; 'Dur [s]'};
            app.ActiveStimProtocolsTable.RowName = {};
            app.ActiveStimProtocolsTable.SelectionType = 'row';
            app.ActiveStimProtocolsTable.ColumnEditable = true;
            app.ActiveStimProtocolsTable.CellEditCallback = createCallbackFcn(app, @ActiveStimProtocolsTableCellEdit, true);
            app.ActiveStimProtocolsTable.CellSelectionCallback = createCallbackFcn(app, @ActiveStimProtocolsTableCellSelection, true);
            app.AddGesturePanel = uipanel(app.StimulationParametersPanel);
            app.AddGesturePanel.Title = 'Add stim protocol';
            app.GestureEditFieldLabel = uilabel(app.AddGesturePanel);
            app.GestureEditFieldLabel.Text = 'Gesture';
            app.StimChannelDropDownLabel = uilabel(app.AddGesturePanel);
            app.StimChannelDropDownLabel.Text = 'Stim Channel';
            app.addStimButton = uibutton(app.AddGesturePanel, 'push');
            app.addStimButton.ButtonPushedFcn = createCallbackFcn(app, @addStimButtonPushed, true);
            app.addStimButton.Text = '+';
            app.FreqLabel = uilabel(app.AddGesturePanel);
            app.FreqLabel.Text = 'Frequency [Hz]';
            app.Freq = uieditfield(app.AddGesturePanel, 'numeric');
            app.Freq.Limits = [10 1000];
            app.Freq.Value = 40;
            app.AmpLabel = uilabel(app.AddGesturePanel);
            app.AmpLabel.Text = 'Amplitude [µA]';
            app.Amp = uieditfield(app.AddGesturePanel, 'numeric');
            app.Amp.Limits = [0 1000];
            app.Amp.ValueChangedFcn = createCallbackFcn(app, @AmpValueChanged, true);
            app.Amp.Value = 300;
            app.PulseWidthsDropDownLabel = uilabel(app.AddGesturePanel);
            app.PulseWidthsDropDownLabel.Text = 'PulseWidth [µs]';
            app.PW = uidropdown(app.AddGesturePanel);
            app.PW.Items = {'33', '66', '99'};
            app.PW.Value = '66';
            app.DurationsEditFieldLabel = uilabel(app.AddGesturePanel);
            app.DurationsEditFieldLabel.Text = 'Duration [s]';
            app.Dur = uieditfield(app.AddGesturePanel, 'numeric');
            app.Dur.Limits = [0 60];
            app.Dur.Value = 60;
            app.StimCh = uidropdown(app.AddGesturePanel);
            app.StimCh.Items = {'1'};
            app.StimCh.Value = '1';
            app.Gesture = uieditfield(app.AddGesturePanel, 'text');
            app.InitializeDevicesPanel = uipanel(app.ConfigTab);
            app.InitializeDevicesPanel.Title = 'Initialize Devices';
            app.StatusLamp_2Label = uilabel(app.InitializeDevicesPanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.PillowLamp = uilamp(app.InitializeDevicesPanel);
            app.StatusLamp_3Label = uilabel(app.InitializeDevicesPanel);
            app.StatusLamp_3Label.Text = 'Status';
            app.StimulatorLamp = uilamp(app.InitializeDevicesPanel);
            app.PillowConnectButton = uibutton(app.InitializeDevicesPanel, 'push');
            app.PillowConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PillowConnectButtonPushed, true);
            app.PillowConnectButton.Text = 'Pillow Connect';
            app.StimulatorConnectButton = uibutton(app.InitializeDevicesPanel, 'push');
            app.StimulatorConnectButton.ButtonPushedFcn = createCallbackFcn(app, @StimulatorConnectButtonPushed, true);
            app.StimulatorConnectButton.Text = 'Stimulator Connect';
            app.SerialPorts = uidropdown(app.InitializeDevicesPanel);
            app.SerialPorts.Items = {};
            app.SerialPorts.Value = {};
            app.DataStreamTab = uitab(app.TabGroup);
            app.DataStreamTab.Title = 'Data Stream';
            app.StimAxes = uiaxes(app.DataStreamTab);
            ylabel(app.StimAxes, 'Stim channel')
            app.StimAxes.XTick = [];
            app.StimAxes.YTick = [];
            app.StimAxes.ZTick = [];
            app.BinCmdAxes = uiaxes(app.DataStreamTab);
            ylabel(app.BinCmdAxes, 'Binary Command')
            app.BinCmdAxes.XTick = [];
            app.BinCmdAxes.YTick = [0 1];
            app.BinCmdAxes.ZTick = [];
            app.StimulateButtonGroup = uibuttongroup(app.DataStreamTab);
            app.StimulateButtonGroup.Title = 'Stimulate';
            app.StopButton = uibutton(app.StimulateButtonGroup, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.StimulateButtonGroup, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StimLabel = uilabel(app.StimulateButtonGroup);
            app.StimLabel.Text = 'Stim: OFF';
            app.BinaryCmdLabel = uilabel(app.DataStreamTab);
            app.BinaryCmdLabel.Text = 'IDLE';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.StartButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlGUI_switchOnly

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end