classdef controlGUI_task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ConfigTab                   matlab.ui.container.Tab
        InitializeDevicesPanel      matlab.ui.container.Panel
        StimulatorConnectButton     matlab.ui.control.Button
        BinaryDeviceButtonGroup     matlab.ui.container.ButtonGroup
        PillowButton                matlab.ui.control.RadioButton
        SerialPorts                 matlab.ui.control.DropDown
        eegomylabButton             matlab.ui.control.RadioButton
        BinaryDeviceConnectButton   matlab.ui.control.Button
        PropDeviceButtonGroup       matlab.ui.container.ButtonGroup
        MTwAwindaButton             matlab.ui.control.RadioButton
        SessantaquattroButton       matlab.ui.control.RadioButton
        PropDeviceConnectButton     matlab.ui.control.Button
        StimulatorLamp              matlab.ui.control.Lamp
        StatusLamp_3Label           matlab.ui.control.Label
        BinaryDeviceLamp            matlab.ui.control.Lamp
        StatusLamp_2Label           matlab.ui.control.Label
        PropDeviceLamp              matlab.ui.control.Lamp
        StatusLampLabel             matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        PropThr                     matlab.ui.control.NumericEditField
        PropSat                     matlab.ui.control.NumericEditField
        PropSatLabel                matlab.ui.control.Label
        PropThrLabel                matlab.ui.control.Label
        ControlParametersPanel      matlab.ui.container.Panel
        CalibrationButton           matlab.ui.control.Button
        emgMVC                      matlab.ui.control.NumericEditField
        emgMVCLabel                 matlab.ui.control.Label
        minPitch                    matlab.ui.control.NumericEditField
        maxPitch                    matlab.ui.control.NumericEditField
        minPitchLabel               matlab.ui.control.Label
        maxPitchLabel               matlab.ui.control.Label
        StimulationParametersPanel  matlab.ui.container.Panel
        AMorFM                      matlab.ui.control.DropDown
        AMorFMDropDownLabel         matlab.ui.control.Label
        AddGesturePanel             matlab.ui.container.Panel
        PW                          matlab.ui.control.DropDown
        PulseWidthsDropDownLabel    matlab.ui.control.Label
        Amp                         matlab.ui.control.NumericEditField
        AmpLabel                    matlab.ui.control.Label
        MinAmp                      matlab.ui.control.NumericEditField
        MinAmpLabel                 matlab.ui.control.Label
        MaxAmp                      matlab.ui.control.NumericEditField
        MaxAmpLabel                 matlab.ui.control.Label
        Freq                        matlab.ui.control.NumericEditField
        FreqLabel                   matlab.ui.control.Label
        MinFreq                     matlab.ui.control.NumericEditField
        MinFreqLabel                matlab.ui.control.Label
        MaxFreq                     matlab.ui.control.NumericEditField
        MaxFreqLabel                matlab.ui.control.Label
        addStimButton               matlab.ui.control.Button
        StimCh                      matlab.ui.control.DropDown
        StimChannelDropDownLabel    matlab.ui.control.Label
        Gesture                     matlab.ui.control.EditField
        GestureEditFieldLabel       matlab.ui.control.Label
        ActiveStimProtocolsPanel    matlab.ui.container.Panel
        ActiveStimProtocolsTable    matlab.ui.control.Table
        DeleteSelectedRowButton     matlab.ui.control.Button
        SaveParametersButton        matlab.ui.control.Button
        LoadParametersButton        matlab.ui.control.Button
        DataStreamTab               matlab.ui.container.Tab
        StartTaskButton             matlab.ui.control.Button
        BinaryCmdLabel              matlab.ui.control.Label
        StimulateButtonGroup        matlab.ui.container.ButtonGroup
        StimLabel                   matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        BinCmdAxes                  matlab.ui.control.UIAxes
        PropCmdAxes                 matlab.ui.control.UIAxes
        StimAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)

        % Class instances
        xipp;                   % xippmex object
        pillow;                 % pillow object
        sessantaquattro;        % sessantaquattro object
        imu;                    % xsens IMU object
        plots;                  % plot object

        % Ripple variables
        newRipple = 1;          % flag for New Ripple Summit
        stimRes = 4;            % stim resolution (1 = 1 uA/step, ......, 4 = 10 uA/step)

        % Calibration variables
        calibTime = 10;         % [s] calibration time [s]

        % Parameters that are get from the GUI
        paramsGUI = struct('emgMVC',1,'minPitch',1,'maxPitch',1); % only calibration parameters are initialized at 1 (needed for the calibration phase)

        % Stimulation variables
        safeLimit = 120*1e3;    % charge safety limit [pC]
        ampLimit = 1000;        % amplitude limit in the GUI [uA]
        pwLimit = 100;          % pulse-width limit in the GUI [us]
        stimEN = 0;             % flag for stimulation enable

        % Saving variables
        saveFolder = "C:\Users\m.ceradini\Desktop\Elena\Code Regrap\Rewire_Project\Data";     % folder where to save recordings. By defaut it is current directory

        % Other variables
        selectedRowTable = [];  % to select rows from the active stim protocols table

        % Plot variables
        scopeControl;

    end

    methods (Static)

        function outCell = Mystr2double(inCell)
            outCell = cellfun(@(x) strsplit(x, ','), inCell, 'UniformOutput', false);
            maxL = max(cellfun(@length, outCell));
            for i = 1:length(outCell)
                for ii = length(outCell{i})+1:maxL
                    outCell{i}{ii} = '';
                end
            end
            outCell = vertcat(outCell{:});
            outCell  = str2double(outCell);
        end
    end

    methods (Access = private)

        % Create filepath
        function filePath = computeFilePath(app)

            % Current date and time
            dateStr = string(datetime('now','Format','yyyy-MM-dd'));
            timeStr = string(datetime('now','Format','HH-mm-ss'));
            % Find all the files of today
            fileNameList = extractfield(dir(app.saveFolder),'name');
            filesToday = fileNameList(find(contains(fileNameList,dateStr)));
            % Find the recording numbers of today
            recsToday = [];
            for iT = 1:length(filesToday)
                recIdx = strfind(filesToday{iT},'rec');
                recsToday(iT) = str2double(regexp(filesToday{iT}(recIdx+3:recIdx+5),'\d*','Match'));
            end
            maxRecNb = max(recsToday);
            if isempty(maxRecNb)
                maxRecNb = 0;
            end
            % Compute recording number incrementally
            recNb = maxRecNb + 1;
            % create file name
            fileName = [dateStr '-' timeStr '-rec' num2str(recNb)];
            % update field with path + user-written text
            filePath = strjoin([app.saveFolder,filesep,fileName],'');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add path for auxiliary functions
            addpath(genpath(fileparts(mfilename('fullpath'))));
            % instantiate the classes
            app.xipp = xippmex_handler;
            app.pillow = pillowbutton_handler;
            %app.sessantaquattro = sessantaquattroplus_handler;
            app.sessantaquattro = rippleEMG_handler;
            app.imu = xsensimus_handler;
            app.plots = plot_handler;
            % complete serial ports list
            app.SerialPorts.Items = serialportlist;
            app.SerialPorts.Value = app.SerialPorts.Items{end};

        end

        % Button pushed function: StimulatorConnectButton
        function StimulatorConnectButtonPushed(app, event)
            % Connect with stimulator and setup stimulation
            status = app.xipp.initializeXipp(app.newRipple);
            % If connected with xippmex ...
            if status
                app.StimulatorLamp.Color = 'g'; % ... turn on the lamp
                app.xipp.setupStim(app.newRipple,app.stimRes); % ... set up stimulation
                app.StimCh.Items = string(num2cell(1:length(app.xipp.stimChsID))); % ... fill StimChannelDropDown with available stim channels
            end
        end

        % Selection changed function: BinaryDeviceButtonGroup
        function BinaryDeviceButtonGroupSelectionChanged(app, event)
            % Enbale/disable serial port lists
            if app.PillowButton.Value
                app.SerialPorts.Enable = 'on';
            elseif app.eegomylabButton.Value
                app.SerialPorts.Enable = 'off';
            end
            % Deactivate the lamp
            app.BinDeviceLamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: BinaryDeviceConnectButton
        function BinaryDeviceConnectButtonPushed(app, event)
            if app.PillowButton.Value % Pillow
                status = app.pillow.openSerial(app.SerialPorts.Value);
            elseif app.eegomylabButton.Value % eeg
            end
            % If connected, turn on the lamp
            if status
                app.BinaryDeviceLamp.Color = 'g';
            end
        end

        % Selection changed function: PropDeviceButtonGroup
        function PropDeviceButtonGroupSelectionChanged(app, event)
            % Deactivate the lamp
            app.PropDeviceLamp.Color = [0.9 0.9 0.9];
            % Enable the adequate calibration parameters
            if app.SessantaquattroButton.Value % Sessantaquattro+
                app.emgMVC.Enable = "on";
                app.emgMVCLabel.Enable = "on";
                app.minPitch.Enable = "off";
                app.minPitchLabel.Enable = "off";
                app.maxPitch.Enable = "off";
                app.maxPitchLabel.Enable = "off";
            elseif app.MTwAwindaButton.Value % MTw Awinda
                app.emgMVC.Enable = "off";
                app.emgMVCLabel.Enable = "off";
                app.minPitch.Enable = "on";
                app.minPitchLabel.Enable = "on";
                app.maxPitch.Enable = "on";
                app.maxPitchLabel.Enable = "on";
            end
        end

        % Button pushed function: PropDeviceConnectButton
        function PropDeviceConnectButtonPushed(app, event)
            % Connect with device
            if app.SessantaquattroButton.Value % Sessantaquattro+
                status = app.sessantaquattro.openSocket();
                if status
                    app.sessantaquattro.configure();
                end
            elseif app.MTwAwindaButton.Value % MTw Awinda
                status = app.imu.connect();
            end
            % If the connection is established ...
            if status
                app.PropDeviceLamp.Color = 'g'; % ... activate the lamp
                app.CalibrationButton.Enable = 'on'; % ... enable the calibration button
            end
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            % Calibration of 64+
            if app.SessantaquattroButton.Value
                % Create calibration scope
                scopeCalib = timescope("SampleRate",[app.sessantaquattro.EMGsFreq, app.sessantaquattro.EMGsFreq], ...
                    "TimeSpanOverrunAction","scroll",...
                    'PlotType','line', ...
                    'TimeUnits','seconds', ...
                    'TimeSpan',app.calibTime,...
                    'Name','EMG', ...
                    'NumInputPorts',2, ...
                    'ChannelNames',{'Raw','RMS Env'}, ...
                    'YLimits',[-200,200]);
                % Initialize data vectors
                EMGrawMVC = [];
                EMGenvMVC = [];
                % Start timer
                startCalibTimer = tic;
                timerCalib = toc(startCalibTimer);
                % Start the acquisition
                r = rateControl(1/app.sessantaquattro.readWind);
                reset(r);
                while(timerCalib<=app.calibTime)
                    % Read the data
                    app.sessantaquattro.receiveData();
                    % Process the data
                    app.sessantaquattro.processData(app.paramsGUI);
                    % Plot the data
                    scopeCalib(app.sessantaquattro.data',app.sessantaquattro.dataEnv*ones(length(app.sessantaquattro.data),1));
                    % Concatenate the data
                    EMGrawMVC = [EMGrawMVC ; app.sessantaquattro.data'];
                    EMGenvMVC = [EMGenvMVC ; app.sessantaquattro.dataEnv];
                    % Check the remaining time
                    timerCalib = toc(startCalibTimer);
                    % ...
                    waitfor(r);
                end
                release(scopeCalib);
                % Save the data into the txt files
                %writematrix(EMGrawMVC,strcat(datafolder,new_file_EMGraw_MVC));
                %writematrix(EMGenvMVC,strcat(datafolder,new_file_EMGenv_MVC));
                % Compute MVC and insert in the editfield
                [MVC,sampleMVC] = max(EMGenvMVC);
                app.emgMVC.Value = MVC;
                % Plot the data
                figure;
                hold on;
                plot([0:length(EMGrawMVC)-1]/app.sessantaquattro.EMGsFreq,EMGrawMVC,'b');
                plot([0:length(EMGenvMVC)-1]*app.sessantaquattro.readWind-app.sessantaquattro.readWind/2,EMGenvMVC,'k');
                plot((sampleMVC-1)*app.sessantaquattro.readWind-app.sessantaquattro.readWind/2,MVC,'r*');
                xlim([0 (length(EMGrawMVC)-1)/app.sessantaquattro.EMGsFreq]);

                % Calibration of MTw Awinda
            elseif app.MTwAwindaButton.Value
                % Create calibration scope
                scopeCalib = timescope("SampleRate",app.imu.loopFreq, ...
                    "TimeSpanOverrunAction","scroll",...
                    'PlotType','line', ...
                    'TimeUnits','seconds', ...
                    'TimeSpan',app.calibTime,...
                    'Name','Pich', ...
                    'NumInputPorts',1, ...
                    'YLimits',[-30,30]);
                % Initialize data vectors
                pitchCalib = [];
                % Start timer
                startCalibTimer = tic;
                timerCalib = toc(startCalibTimer);
                % Start the acquisition
                r = rateControl(app.imu.loopFreq);
                reset(r);
                while(timerCalib<=app.calibTime)
                    % Read the data
                    app.imu.receiveData();
                    % Process the data
                    app.imu.processData(app.paramsGUI);
                    % Plot the data
                    scopeCalib(app.imu.pitch);
                    % Concatenate the data
                    pitchCalib = [pitchCalib ; app.imu.pitch];
                    % Check the remaining time
                    timerCalib = toc(startCalibTimer);
                    % ...
                    waitfor(r);
                end
                release(scopeCalib);
                % Save the data into the txt files
                % writematrix(....
                % Compute min and max and insert in the editfields
                [minPitch,sampleMinPitch] = min(pitchCalib);
                [maxPitch,sampleMaxPitch] = max(pitchCalib);
                app.minPitch.Value = minPitch;
                app.maxPitch.Value = maxPitch;
                % Plot the data
                figure;
                hold on;
                plot([0:length(pitchCalib)-1]/app.imu.loopFreq,pitchCalib,'b');
                plot((sampleMinPitch-1)/app.imu.loopFreq,minPitch,'k*');
                plot((sampleMaxPitch-1)/app.imu.loopFreq,maxPitch,'r*');
                xlim([0 (length(pitchCalib)-1)/app.imu.loopFreq]);
            end
        end

        % Value changed function: PropThr
        function PropThrValueChanged(app, event)
            % PropThr > PropSat
            if app.PropThr.Value > app.PropSat.Value
                app.PropThr.Value = app.PropSat.Value;
                warndlg('PropThr is higher than PropSat!','Parameter Warning')
            end
        end

        % Value changed function: PropSat
        function PropSatValueChanged(app, event)
            % PropSat < PropThr
            if app.PropSat.Value < app.PropThr.Value
                app.PropSat.Value = app.PropThr.Value;
                warndlg('PropSat is lower than PropThr!','Parameter Warning')
            end
        end

        % Value changed function: AMorFM
        function AMorFMValueChanged(app, event)
            % Enable/disable the fields depending on the modulation type
            value = app.AMorFM.Value;
            if strcmp(value,'AM')
                app.Amp.Enable = 'off';         app.AmpLabel.Enable = 'off';
                app.MinAmp.Enable = 'on';       app.MinAmpLabel.Enable = 'on';
                app.MaxAmp.Enable = 'on';       app.MaxAmpLabel.Enable = 'on';
                app.Freq.Enable = 'on';         app.FreqLabel.Enable = 'on';
                app.MinFreq.Enable = 'off';     app.MinFreqLabel.Enable = 'off';
                app.MaxFreq.Enable = 'off';     app.MaxFreqLabel.Enable = 'off';
                app.ActiveStimProtocolsTable.ColumnName = {'State Nb','Gesture','Ch','PW [µs]','Min Amp [µA]','Max Amp [µA]','Freq [Hz]'};
                app.ActiveStimProtocolsTable.Data = {};
            elseif strcmp(value,'FM')
                app.Amp.Enable = 'on';          app.AmpLabel.Enable = 'on';
                app.MinAmp.Enable = 'off';      app.MinAmpLabel.Enable = 'off';
                app.MaxAmp.Enable = 'off';      app.MaxAmpLabel.Enable = 'off';
                app.Freq.Enable = 'off';        app.FreqLabel.Enable = 'off';
                app.MinFreq.Enable = 'on';      app.MinFreqLabel.Enable = 'on';
                app.MaxFreq.Enable = 'on';      app.MaxFreqLabel.Enable = 'on';
                app.ActiveStimProtocolsTable.ColumnName = {'State Nb','Gesture','Ch','PW [µs]','Amp [µA]','Min Freq [Hz]','Max Freq [Hz]'};
                app.ActiveStimProtocolsTable.Data = {};
            end
        end

        % Value changed function: Amp
        function AmpValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.Amp.Value*app.PW.Value > app.safeLimit
                app.Amp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
        end

        % Value changed function: MinAmp
        function MinAmpValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.MinAmp.Value*app.PW.Value > app.safeLimit
                app.MinAmp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.MinAmp.Value > app.MaxAmp.Value
                app.MinAmp.Value = app.MaxAmp.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
        end

        % Value changed function: MaxAmp
        function MaxAmpValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.MaxAmp.Value*app.PW.Value > app.safeLimit
                app.MaxAmp.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.MaxAmp.Value < app.MinAmp.Value
                app.MaxAmp.Value = app.MinAmp.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
        end

        % Value changed function: MinFreq
        function MinFreqValueChanged(app, event)
            % Min freq > max freq
            if app.MinFreq.Value > app.MaxFreq.Value
                app.MinFreq.Value = app.MaxFreq.Value;
                warndlg('Min freq is higher than max freq!','Parameter Warning')
            end
        end

        % Button pushed function: addStimButton
        function addStimButtonPushed(app, event)
            % get the gestures already in the list of active protocols
            if isempty(app.ActiveStimProtocolsTable.Data)
                activeGestures = {};
                app.ActiveStimProtocolsTable.Data = {};
            else
                idxCol = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Gesture'));
                activeGestures = app.ActiveStimProtocolsTable.Data(:,idxCol);
            end
            % # active protocols
            nActive = length(activeGestures);
            % if the gesture is already present in the list, add the new protocol
            idxRow = find(strcmp(activeGestures,app.Gesture.Value));
            if ~isempty(idxRow)
                if strcmp(app.AMorFM.Value,'AM')
                    app.ActiveStimProtocolsTable.Data(idxRow,:) = {idxRow, app.Gesture.Value, ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,3} ',' app.StimCh.Value], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,4} ',' app.PW.Value], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,5} ',' num2str(app.MinAmp.Value)], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,6} ',' num2str(app.MaxAmp.Value)], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,7} ',' num2str(app.Freq.Value)]};
                elseif strcmp(app.AMorFM.Value,'FM')
                    app.ActiveStimProtocolsTable.Data(idxRow,:) = {idxRow, app.Gesture.Value, ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,3} ',' app.StimCh.Value], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,4} ',' app.PW.Value], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,5} ',' num2str(app.Amp.Value)], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,6} ',' num2str(app.MinFreq.Value)], ...
                        [app.ActiveStimProtocolsTable.Data{idxRow,7} ',' num2str(app.MaxFreq.Value)]};
                end
                % otherwise add the row
            else
                if strcmp(app.AMorFM.Value,'AM')
                    app.ActiveStimProtocolsTable.Data(end+1,:) = {num2str(nActive+1), ...
                        app.Gesture.Value, app.StimCh.Value, app.PW.Value, ...
                        num2str(app.MinAmp.Value), num2str(app.MaxAmp.Value), num2str(app.Freq.Value)};
                elseif strcmp(app.AMorFM.Value,'FM')
                    app.ActiveStimProtocolsTable.Data(end+1,:) = {num2str(nActive+1), ...
                        app.Gesture.Value, app.StimCh.Value, app.PW.Value, ...
                        num2str(app.Amp.Value), num2str(app.MinFreq.Value), num2str(app.MaxFreq.Value)};
                end
            end
        end

        % Button pushed function: DeleteSelectedRowButton
        function DeleteSelectedRowButtonPushed(app, event)
            % get selected row and delete
            row = app.selectedRowTable;
            if ~isempty(row)
                app.ActiveStimProtocolsTable.Data(row,:) = [];
                app.selectedRowTable = [];
            end
            % if the table is empty, disable the START button
            if isempty(app.ActiveStimProtocolsTable.Data)
                app.StartButton.Enable = 'off';
            end
        end

        % Button pushed function: SaveParametersButton
        function SaveParametersButtonPushed(app, event)
            % get stim parameters from table
            stim_params = app.ActiveStimProtocolsTable.Data;
            stim_params_name = app.ActiveStimProtocolsTable.ColumnName';
            % select folder and set file name
            path = uiputfile('*.mat');
            % save protocol
            save(path,'stim_params','stim_params_name');
        end

        % Button pushed function: LoadParametersButton
        function LoadParametersButtonPushed(app, event)
            % browse file
            path = uigetfile('*.mat');
            % load file
            load(path);
            % check if the parameters name match with those in the table
            if isequal(app.ActiveStimProtocolsTable.ColumnName,stim_params_name')
                % set the parameters in the stim table
                app.ActiveStimProtocolsTable.Data = stim_params;
            else
                errordlg('Parameters do not match with current stimulation protocol')
            end
        end

        % Cell selection callback: ActiveStimProtocolsTable
        function ActiveStimProtocolsTableCellSelection(app, event)
            if ~isempty(event.Indices)
                app.selectedRowTable = event.Indices(1,1);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if strcmp(app.TabGroup.SelectedTab.Title, 'Data Stream')
                if app.StimulatorLamp.Color==[0 1 0] & app.BinaryDeviceLamp.Color==[0 1 0] & app.PropDeviceLamp.Color==[0 1 0] & ...
                        ~isempty(app.ActiveStimProtocolsTable.Data)
                    app.StartButton.Enable = 'on';
                else
                    app.StartButton.Enable = 'off';
                end
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Assign control parameters
            if app.PillowButton.Value
                app.paramsGUI.binDevice = 'Pillow';
            elseif app.eegomylabButton.Value
                app.paramsGUI.binDevice = 'eego';
            end
            if app.SessantaquattroButton.Value
                app.paramsGUI.propDevice = 'Sessantaquattro+';
            elseif app.MTwAwindaButton.Value
                app.paramsGUI.propDevice = 'MTw Awinda';
            end
            app.paramsGUI.propThr = app.PropThr.Value;
            app.paramsGUI.propSat = app.PropSat.Value;
            % Assign calibration parameters
            app.paramsGUI.emgMVC = app.emgMVC.Value;
            app.paramsGUI.minPitch = app.minPitch.Value;
            app.paramsGUI.maxPitch = app.maxPitch.Value;
            % Assign stimulation parameters
            app.paramsGUI.modType = app.AMorFM.Value;
            app.paramsGUI.gestures = app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Gesture')));
            app.paramsGUI.nGestures = length(app.paramsGUI.gestures);
            app.paramsGUI.stimCh = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Ch'))));
            app.paramsGUI.stimPW = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'PW [µs]'))));
            if strcmp(app.paramsGUI.modType,'AM')
                app.paramsGUI.stimAmp = [];
                app.paramsGUI.minAmp = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Min amp [µA]'))));
                app.paramsGUI.maxAmp = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Max amp [µA]'))));
                app.paramsGUI.stimFreq = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Freq [Hz]'))));
                app.paramsGUI.minFreq = [];
                app.paramsGUI.maxFreq = [];
            elseif strcmp(app.paramsGUI.modType,'FM')
                app.paramsGUI.stimAmp = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Amp [µA]'))));
                app.paramsGUI.minAmp = [];
                app.paramsGUI.maxAmp = [];
                app.paramsGUI.stimFreq = [];
                app.paramsGUI.minFreq = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Min Freq [Hz]'))));
                app.paramsGUI.maxFreq = app.Mystr2double(app.ActiveStimProtocolsTable.Data(:,find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Max Freq [Hz]'))));
            end
            % Set the stim commands of the gestures
            app.xipp.setGestureCmd(app.paramsGUI);
            % Initialize the stimulation parameters
            app.xipp.gestureIdx = 1;
            app.xipp.gestureName = app.paramsGUI.gestures{1};
            app.xipp.stimCh = app.paramsGUI.stimCh(1,:);
            app.xipp.stimPW = app.paramsGUI.stimPW(1,:);
            app.xipp.stimAmp = zeros(size(app.paramsGUI.stimCh(1,:)));
            app.xipp.stimFreq = ones(size(app.paramsGUI.stimCh(1,:)));
            app.xipp.stimCmd = app.xipp.gestureCmd{1};
            % initialize plots
            if app.SessantaquattroButton.Value
                loopFreq = 1/app.sessantaquattro.readWind;
            elseif app.MTwAwindaButton.Value
                loopFreq = app.imu.loopFreq;
            end
            cla(app.BinCmdAxes);
            cla(app.PropCmdAxes);
            cla(app.StimAxes);
            app.plots.initializePlot(app.BinCmdAxes, app.PropCmdAxes, app.StimAxes, loopFreq, app.paramsGUI);
            if strcmp(app.paramsGUI.modType,'AM')
                app.StimAxes.YLim = [0 max(max(app.paramsGUI.maxAmp))];
                app.StimAxes.YTick = 0:100:max(max(app.paramsGUI.maxAmp));
                app.StimAxes.YTickLabel = 0:100:max(max(app.paramsGUI.maxAmp));
                app.StimAxes.YLabel.String = 'Stim amp [uA]';
            elseif strcmp(app.paramsGUI.modType,'FM')
                app.StimAxes.YLim = [0 max(max(app.paramsGUI.maxFreq))];
                app.StimAxes.YTick = 0:10:max(max(app.paramsGUI.maxFreq));
                app.StimAxes.YTickLabel = 0:10:max(max(app.paramsGUI.maxFreq));
                app.StimAxes.YLabel.String = 'Stim freq [Hz]';
            end
            app.PropCmdAxes.YTick = [0 app.paramsGUI.propThr app.paramsGUI.propSat 1];
            app.PropCmdAxes.YTickLabel = [0 app.paramsGUI.propThr app.paramsGUI.propSat 1];
            % start the streaming
            ccPlot = 0;
            % Initialize scope
            if app.SessantaquattroButton.Value
                app.scopeControl = timescope("SampleRate",[app.sessantaquattro.EMGsFreq, app.sessantaquattro.EMGsFreq], ...
                    "TimeSpanOverrunAction","scroll",...
                    'PlotType','line', ...
                    'TimeUnits','seconds', ...
                    'TimeSpan',5,...
                    'Name','EMG', ...
                    'NumInputPorts',2, ...
                    'ChannelNames',{'Raw','RMS Env'}, ...
                    'YLimits',[0,1]);
            elseif app.MTwAwindaButton.Value
                app.scopeControl = timescope("SampleRate",app.imu.loopFreq, ...
                    "TimeSpanOverrunAction","scroll",...
                    'PlotType','line', ...
                    'TimeUnits','seconds', ...
                    'TimeSpan',5,...
                    'Name','Pich', ...
                    'NumInputPorts',1, ...
                    'YLimits',[0,1]);
            end
            % Compute new file path
            newFilePath = computeFilePath(app);
            newFilePathParams = strjoin([newFilePath,'-params'],'');
            newFilePathState = strjoin([newFilePath,'-state'],'');
            % Save control parameters in txt file
            if strcmp(app.paramsGUI.propDevice,'Sessantaquattro+') % 64+
                writecell({'MVC','propThr','propSat'},newFilePathParams,'WriteMode','append','Quotestrings','none');
                writematrix([app.paramsGUI.emgMVC,app.paramsGUI.propThr,app.paramsGUI.propSat],newFilePathParams,'WriteMode','append');
            elseif strcmp(app.paramsGUI.propDevice,'MTw Awinda') % MTw Awinda
                writecell({'minPitch','maxPitch','propThr','propSat'},newFilePathParams,'WriteMode','append','Quotestrings','none');
                writematrix([app.paramsGUI.minPitch,app.paramsGUI.maxPitch,app.paramsGUI.propThr,app.paramsGUI.propSat],newFilePathParams,'WriteMode','append');
            end
            % Save stim parameters in txt file
            writematrix('modType',newFilePathParams,'WriteMode','append');
            writematrix(app.paramsGUI.modType,newFilePathParams,'WriteMode','append');
            writecell(app.ActiveStimProtocolsTable.ColumnName',newFilePathParams,'WriteMode','append');
            writecell(app.ActiveStimProtocolsTable.Data,newFilePathParams,'WriteMode','append');
            % Take current time and save it in txt file
            timeStart = datestr(now,'HH:MM:SS.FFF');
            writematrix('timeStart',newFilePath,'WriteMode','append','Quotestrings','none');
            writematrix(timeStart,newFilePath,'WriteMode','append');
            % Write labels in txt files
            writecell({'time','state'},newFilePathState,'WriteMode','append','Quotestrings','none');
            writecell({'time','binSig','propCmd','stimCh','stimAmp','stimFreq'},newFilePath,'WriteMode','append','Quotestrings','none');
            % Enable stimulation
            app.xipp.enableStim();
            app.stimEN = 1;
            % Change the stim label on the GUI
            app.StimLabel.Text = 'Stim: ON';
            app.StimLabel.FontColor = 'r';
            % Send the first stim command
            app.xipp.sendStimCmd();
            % ......
            figTask = figure;
            % Start the control loop
            if strcmp(app.paramsGUI.propDevice,'Sessantaquattro+') % 64+
                r = rateControl(1/app.sessantaquattro.readWind);
                reset(r);
                ticStart = tic;
                % task variables
                ccTask = 0;
                %force_bounds = [0,0.2;0.2,0.4;0.4,0.6;0.6,0.8;0.8,5];
                force_step = (app.paramsGUI.propSat - app.paramsGUI.propThr) / 5 ;
                force_bound_low = [app.paramsGUI.propThr:force_step:(app.paramsGUI.propSat-force_step)]';
                force_bounds = [force_bound_low,force_bound_low+force_step];
                jj = ceil(random('unif',0,5));
                success = 0;
                f_in = [];
                hold_time = 0.5; %s
                ratio_hold = floor(hold_time*app.imu.loopFreq);
                while(app.stimEN)
                    ticLoop = tic;
                    % -------------------- BINARY CONTROL ----------------
                    app.pillow.readButtonState();
                    app.BinaryCmdLabel.Text = [app.pillow.stateLabel app.xipp.gestureName];
                    if app.pillow.binCmd
                        % Change the grasp type
                        app.xipp.switchStim(app.paramsGUI);
                        % Send clear command
                        app.xipp.sendClearCmd();
                    end
                    % -----------------------------------------------------
                    % ----------------- PROPORTIONAL CONTROL --------------
                    if ~app.pillow.blockStim
                        % Read EMG data
                        app.sessantaquattro.receiveData();
                        % Process EMG data to compute the proportional cmd
                        app.sessantaquattro.processData(app.paramsGUI);
                        % Compute stimulation output based on proportional cmd
                        app.xipp.stimOutput(app.sessantaquattro.propCmd, app.paramsGUI);
                    else
                        pause(app.sessantaquattro.readWind)
                    end
                    % -----------------------------------------------------
                    % ------------------ SEND STIM COMMAND ----------------
                    % Check if NIP is connected
                    %app.xipp.checkNIP();
                    % Send stimulation cmd
                    app.xipp.sendStimCmd();
                    % -----------------------------------------------------
                    % ---------------------- PLOT -------------------------
                    % ccPlot = ccPlot + 1;
                    % app.plots.plotBinCmd(ccPlot, app.pillow.binSig);
                    % app.plots.plotPropCmd(ccPlot, app.sessantaquattro.propCmd)
                    % app.plots.plotStimVar(ccPlot, app.paramsGUI, app.xipp.stimAmp, app.xipp.stimFreq)
                    % -----------------------------------------------------
                    % --------------------- SAVE DATA ---------------------
                    writematrix([toc(ticStart) app.pillow.binSig app.sessantaquattro.propCmd ...
                        app.xipp.stimCh app.xipp.stimAmp app.xipp.stimFreq], newFilePath, 'WriteMode', 'append');
                    writecell({num2str(toc(ticStart)) app.BinaryCmdLabel.Text}, newFilePathState, 'WriteMode', 'append');
                    % ----------------------------------------------------
                    % ---------------- MAKE TASK -------------------------
                    ccTask = ccTask + 1;
                    tt = tic;
                    if isempty(app.sessantaquattro.propCmd)
                        f_in(ccTask) = -1;
                    elseif app.sessantaquattro.propCmd>app.paramsGUI.propSat
                        f_in(ccTask) = app.paramsGUI.propSat;
                    else
                        f_in(ccTask) = app.sessantaquattro.propCmd; % acquires new datapoint (creates array keeping track of all data received) - substitute right of = with proper code
                    end

                    if ccTask==1
                        figure(figTask);
                        xlim([0,1]),ylim([app.paramsGUI.propThr,app.paramsGUI.propSat]);
                        xlabel('Time (s)'); ylabel('Force (A.U.)') % vogliamo fare la conversione in N?
                        hold on
                        HP1 = plot(0.5, f_in(ccTask), 'bo', 'LineWidth',10); % initializes plot
                        HP2 = plot([0,1],[force_bounds(jj,2),force_bounds(jj,2)],'g','LineWidth',3);
                        HP3 = plot([0,1],[force_bounds(jj,1),force_bounds(jj,1)],'g','LineWidth',3);
                        drawnow;
                    else
                        set(HP1,'YData',f_in(ccTask));
                        drawnow;
                    end
                    if length(f_in)>ratio_hold % if there is enough previous data to fill a hold time
                        if min(f_in(ccTask-ratio_hold:ccTask))>=force_bounds(jj,1) && max(f_in(ccTask-ratio_hold:ccTask))<=force_bounds(jj,2) % if the force has been in the bounds for the required hold time
                            success = success + 1;
                            jj_temp = ceil(random('unif',0,5)); % ensures the same level is not requested 2 times in a row
                            while jj_temp==jj
                                jj_temp = ceil(random('unif',0,5));
                            end
                            jj = jj_temp;
                            set(HP2,'YData',[force_bounds(jj,2),force_bounds(jj,2)]');
                            set(HP3,'YData',[force_bounds(jj,1),force_bounds(jj,1)]');
                        end
                    end
                    % ----------------------------------------------------
                    disp(success);
                    waitfor(r);
                    disp(toc(ticLoop));
                end
            elseif strcmp(app.paramsGUI.propDevice,'MTw Awinda') % MTw Awinda
                r = rateControl(app.imu.loopFreq);
                reset(r);
                ticStart = tic;
                % task variables
                ccTask = 0;
                %force_bounds = [0,0.2;0.2,0.4;0.4,0.6;0.6,0.8;0.8,5];
                force_step = (app.paramsGUI.propSat - app.paramsGUI.propThr) / 5 ;
                force_bound_low = [app.paramsGUI.propThr:force_step:(app.paramsGUI.propSat-force_step)]';
                force_bounds = [force_bound_low,force_bound_low+force_step];
                jj = ceil(random('unif',0,5));
                success = 0;
                f_in = [];
                hold_time = 1; %s
                ratio_hold = floor(hold_time*app.imu.loopFreq);
                while(app.stimEN)
                    ticLoop = tic;
                    % -------------------- BINARY CONTROL ----------------
                    app.pillow.readButtonState();
                    app.BinaryCmdLabel.Text = [app.pillow.stateLabel app.xipp.gestureName];
                    if app.pillow.binCmd
                        % Change the grasp type
                        app.xipp.switchStim(app.paramsGUI);
                        % Send clear command
                        app.xipp.sendClearCmd();
                    end
                    % -----------------------------------------------------
                    % -------------------- PROPORTIONAL CONTROL -----------
                    if ~app.pillow.blockStim
                        % Read IMU data
                        app.imu.receiveData();
                        % Process IMU data to compute the proportional cmd
                        app.imu.processData(app.paramsGUI);
                        % Compute stimulation output based on proportional cmd
                        app.xipp.stimOutput(app.imu.propCmd, app.paramsGUI);
                    end
                    % ----------------------------------------------------
                    % --------------------- SEND STIM COMMAND ------------
                    % Check if NIP is connected
                    %app.xipp.checkNIP();
                    % Send stimulation cmd
                    app.xipp.sendStimCmd();
                    % ----------------------------------------------------
                    % ---------------------- PLOT ------------------------
                    % ccPlot = ccPlot + 1;
                    % app.plots.plotBinCmd(ccPlot, app.pillow.binSig);
                    % app.plots.plotPropCmd(ccPlot, app.imu.propCmd)
                    % app.plots.plotStimVar(ccPlot, app.paramsGUI, app.xipp.stimAmp, app.xipp.stimFreq)
                    app.scopeControl(app.imu.propCmd);
                    % ----------------------------------------------------
                    % --------------------- SAVE DATA ---------------------
                    writematrix([toc(ticStart) app.pillow.binSig app.sessantaquattro.propCmd ...
                        app.xipp.stimCh app.xipp.stimAmp app.xipp.stimFreq], newFilePath, 'WriteMode', 'append');
                    writecell({num2str(toc(ticStart)) app.BinaryCmdLabel.Text}, newFilePathState, 'WriteMode', 'append');
                    % ----------------------------------------------------
                    % ---------------- MAKE TASK -------------------------
                    ccTask = ccTask + 1;
                    tt = tic;
                    f_in(ccTask) = app.imu.propCmd; % acquires new datapoint (creates array keeping track of all data received) - substitute right of = with proper code
                    if ccTask==1
                        figure(figTask);
                        xlim([0,1]),ylim([app.paramsGUI.propThr,app.paramsGUI.propSat]);
                        xlabel('Time (s)'); ylabel('Force (A.U.)') % vogliamo fare la conversione in N?
                        hold on
                        HP1 = plot(0.5, f_in(ccTask), 'bo', 'LineWidth',10); % initializes plot
                        HP2 = plot([0,1],[force_bounds(jj,2),force_bounds(jj,2)],'g','LineWidth',3);
                        HP3 = plot([0,1],[force_bounds(jj,1),force_bounds(jj,1)],'g','LineWidth',3);
                        drawnow;
                    else
                        set(HP1,'YData',f_in(ccTask));
                        drawnow;
                    end
                    if length(f_in)>ratio_hold % if there is enough previous data to fill a hold time
                        if min(f_in(ccTask-ratio_hold:ccTask))>=force_bounds(jj,1) && max(f_in(ccTask-ratio_hold:ccTask))<=force_bounds(jj,2) % if the force has been in the bounds for the required hold time
                            success = success + 1;
                            jj_temp = ceil(random('unif',0,5)); % ensures the same level is not requested 2 times in a row
                            while jj_temp==jj
                                jj_temp = ceil(random('unif',0,5));
                            end
                            jj = jj_temp;
                            set(HP2,'YData',[force_bounds(jj,2),force_bounds(jj,2)]');
                            set(HP3,'YData',[force_bounds(jj,1),force_bounds(jj,1)]');
                        end
                    end
                    % ----------------------------------------------------
                    disp(success);
                    waitfor(r);
                    disp(toc(ticLoop));
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Disable stimulation
            app.xipp.disableStim
            app.stimEN = 0;
            % Change the stim label in the GUI
            app.StimLabel.Text = 'Stim: OFF';
            app.StimLabel.FontColor = 'k';
            % Release scope
            release(app.scopeControl);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Disable stim and close xippmex if active
            if app.StimulatorLamp.Color==[0 1 0]
                app.xipp.disableStim();
                app.xipp.close();
            end
            % Close pillow serial port if active
            if app.PillowButton.Value & app.BinaryDeviceLamp.Color==[0 1 0]
                app.pillow.closeSerial();
            end
            % Close 64+ TCP socket if active
            if app.SessantaquattroButton.Value & app.PropDeviceLamp.Color==[0 1 0]
                app.sessantaquattro.close();
            end
            % Close MTw Awinda TCP socket if active
            if app.MTwAwindaButton.Value & app.PropDeviceLamp.Color==[0 1 0]
                app.imu.disconnect();
            end
            % delete app
            delete(app)
        end

        % Value changed function: MaxFreq
        function MaxFreqValueChanged(app, event)
            % Max freq < min freq
            if app.MaxFreq.Value < app.MinFreq.Value
                app.MaxFreq.Value = app.MinFreq.Value;
                warndlg('Max freq is lower than min freq!','Parameter Warning')
            end
        end

        % Cell edit callback: ActiveStimProtocolsTable
        function ActiveStimProtocolsTableCellEdit(app, event)
            % take changed cell and value
            idx = event.Indices; % indices of selected cell
            value = app.Mystr2double(app.ActiveStimProtocolsTable.Data(idx(1),idx(2))); % value of selected cell
            % find column idx of min amp, max amp, and pw
            min_amp_idx = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Min amp [µA]'));
            max_amp_idx = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'Max amp [µA]'));
            pw_idx = find(strcmp(app.ActiveStimProtocolsTable.ColumnName,'PW [µs]'));
            % check for limit exceed
            if idx(2)==min_amp_idx
                idx_overLimit = find(value>app.ampLimit);
                if ~isempty(idx_overLimit)
                    value(idx_overLimit) = app.ampLimit;
                    app.ActiveStimProtocolsTable.Data(idx(1),idx(2)) = {regexprep(num2str(value),'\s+',',')};
                end
            elseif idx(2)==max_amp_idx
                idx_overLimit = find(value>app.ampLimit);
                if ~isempty(idx_overLimit)
                    value(idx_overLimit) = app.ampLimit;
                    app.ActiveStimProtocolsTable.Data(idx(1),idx(2)) = {regexprep(num2str(value),'\s+',',')};
                end
            elseif idx(2)==pw_idx
                idx_overLimit = find(value>app.pwLimit);
                if ~isempty(idx_overLimit)
                    value(idx_overLimit) = app.pwLimit;
                    app.ActiveStimProtocolsTable.Data(idx(1),idx(2)) = {regexprep(num2str(value),'\s+',',')};
                end
            end
        end

        % Button pushed function: StartTaskButton
        function StartTaskButtonPushed(app, event)
            %
            % figTask = figure;
            % ticStartTask = tic;
            % ccStart = 1;
            %
            % while(toc(ticStartTask))
            %    plot(app.imu.propCmd, 'bo', 'LineWidth',10);
            %    drawnow;
            % end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.StimulationParametersPanel = uipanel(app.ConfigTab);
            app.StimulationParametersPanel.Title = 'Stimulation Parameters';
            app.ActiveStimProtocolsPanel = uipanel(app.StimulationParametersPanel);
            app.ActiveStimProtocolsPanel.Title = 'Active stim protocols';
            app.LoadParametersButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.LoadParametersButton.ButtonPushedFcn = createCallbackFcn(app, @LoadParametersButtonPushed, true);
            app.LoadParametersButton.Text = 'Load Parameters';
            app.SaveParametersButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.SaveParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParametersButtonPushed, true);
            app.SaveParametersButton.Text = 'Save Parameters';
            app.DeleteSelectedRowButton = uibutton(app.ActiveStimProtocolsPanel, 'push');
            app.DeleteSelectedRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedRowButtonPushed, true);
            app.DeleteSelectedRowButton.Text = 'Delete selected row';
            app.ActiveStimProtocolsTable = uitable(app.ActiveStimProtocolsPanel);
            app.ActiveStimProtocolsTable.ColumnName = {'State Nb'; 'Gesture'; 'Ch'; 'PW [µs]'; 'Min amp [µA]'; 'Max amp [µA]'; 'Freq [Hz]'};
            app.ActiveStimProtocolsTable.RowName = {};
            app.ActiveStimProtocolsTable.SelectionType = 'row';
            app.ActiveStimProtocolsTable.ColumnEditable = true;
            app.ActiveStimProtocolsTable.CellEditCallback = createCallbackFcn(app, @ActiveStimProtocolsTableCellEdit, true);
            app.ActiveStimProtocolsTable.CellSelectionCallback = createCallbackFcn(app, @ActiveStimProtocolsTableCellSelection, true);
            app.AddGesturePanel = uipanel(app.StimulationParametersPanel);
            app.AddGesturePanel.Title = 'Add stim protocol';
            app.GestureEditFieldLabel = uilabel(app.AddGesturePanel);
            app.GestureEditFieldLabel.Text = 'Gesture';
            app.Gesture = uieditfield(app.AddGesturePanel, 'text');
            app.StimChannelDropDownLabel = uilabel(app.AddGesturePanel);
            app.StimChannelDropDownLabel.Text = 'Stim Channel';
            app.StimCh = uidropdown(app.AddGesturePanel);
            app.StimCh.Items = {'1'};
            app.StimCh.Value = '1';
            app.addStimButton = uibutton(app.AddGesturePanel, 'push');
            app.addStimButton.ButtonPushedFcn = createCallbackFcn(app, @addStimButtonPushed, true);
            app.addStimButton.Text = '+';
            app.MaxFreqLabel = uilabel(app.AddGesturePanel);
            app.MaxFreqLabel.Enable = 'off';
            app.MaxFreqLabel.Text = 'Max Freq [Hz]';
            app.MaxFreq = uieditfield(app.AddGesturePanel, 'numeric');
            app.MaxFreq.Limits = [10 1000];
            app.MaxFreq.ValueChangedFcn = createCallbackFcn(app, @MaxFreqValueChanged, true);
            app.MaxFreq.Enable = 'off';
            app.MaxFreq.Value = 100;
            app.MinFreqLabel = uilabel(app.AddGesturePanel);
            app.MinFreqLabel.Enable = 'off';
            app.MinFreqLabel.Text = 'Min Freq [Hz]';
            app.MinFreq = uieditfield(app.AddGesturePanel, 'numeric');
            app.MinFreq.Limits = [10 1000];
            app.MinFreq.ValueChangedFcn = createCallbackFcn(app, @MinFreqValueChanged, true);
            app.MinFreq.Enable = 'off';
            app.MinFreq.Value = 30;
            app.FreqLabel = uilabel(app.AddGesturePanel);
            app.FreqLabel.Text = 'Frequency [Hz]';
            app.Freq = uieditfield(app.AddGesturePanel, 'numeric');
            app.Freq.Limits = [10 1000];
            app.Freq.Value = 30;
            app.MaxAmpLabel = uilabel(app.AddGesturePanel);
            app.MaxAmpLabel.Text = 'Max Amp [µA]';
            app.MaxAmp = uieditfield(app.AddGesturePanel, 'numeric');
            app.MaxAmp.Limits = [0 1000];
            app.MaxAmp.ValueChangedFcn = createCallbackFcn(app, @MaxAmpValueChanged, true);
            app.MaxAmp.Value = 500;
            app.MinAmpLabel = uilabel(app.AddGesturePanel);
            app.MinAmpLabel.Text = 'Min Amp [µA]';
            app.MinAmp = uieditfield(app.AddGesturePanel, 'numeric');
            app.MinAmp.Limits = [0 1000];
            app.MinAmp.ValueChangedFcn = createCallbackFcn(app, @MinAmpValueChanged, true);
            app.MinAmp.Value = 100;
            app.AmpLabel = uilabel(app.AddGesturePanel);
            app.AmpLabel.Enable = 'off';
            app.AmpLabel.Text = 'Amplitude [µA]';
            app.Amp = uieditfield(app.AddGesturePanel, 'numeric');
            app.Amp.Limits = [0 1000];
            app.Amp.ValueChangedFcn = createCallbackFcn(app, @AmpValueChanged, true);
            app.Amp.Enable = 'off';
            app.Amp.Value = 300;
            app.PulseWidthsDropDownLabel = uilabel(app.AddGesturePanel);
            app.PulseWidthsDropDownLabel.Text = 'PulseWidth [µs]';
            app.PW = uidropdown(app.AddGesturePanel);
            app.PW.Items = {'33', '66', '99'};
            app.PW.Value = '33';
            app.AMorFMDropDownLabel = uilabel(app.StimulationParametersPanel);
            app.AMorFMDropDownLabel.Text = 'AM or FM?';
            app.AMorFM = uidropdown(app.StimulationParametersPanel);
            app.AMorFM.Items = {'AM', 'FM'};
            app.AMorFM.ValueChangedFcn = createCallbackFcn(app, @AMorFMValueChanged, true);
            app.AMorFM.Value = 'AM';
            app.ControlParametersPanel = uipanel(app.ConfigTab);
            app.ControlParametersPanel.Title = 'Control Parameters';
            app.maxPitchLabel = uilabel(app.ControlParametersPanel);
            app.maxPitchLabel.Enable = 'off';
            app.maxPitchLabel.Text = 'maxPitch';
            app.minPitchLabel = uilabel(app.ControlParametersPanel);
            app.minPitchLabel.Enable = 'off';
            app.minPitchLabel.Text = 'minPitch';
            app.maxPitch = uieditfield(app.ControlParametersPanel, 'numeric');
            app.maxPitch.Enable = 'off';
            app.minPitch = uieditfield(app.ControlParametersPanel, 'numeric');
            app.minPitch.Enable = 'off';
            app.minPitch.Value = -20;
            app.emgMVCLabel = uilabel(app.ControlParametersPanel);
            app.emgMVCLabel.Text = 'MVC';
            app.emgMVC = uieditfield(app.ControlParametersPanel, 'numeric');
            app.emgMVC.Value = 150;
            app.CalibrationButton = uibutton(app.ControlParametersPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
            app.Panel = uipanel(app.ConfigTab);
            app.PropThrLabel = uilabel(app.Panel);
            app.PropThrLabel.Text = 'Prop Thr';
            app.PropSatLabel = uilabel(app.Panel);
            app.PropSatLabel.Text = 'Prop Sat';
            app.PropSat = uieditfield(app.Panel, 'numeric');
            app.PropSat.Limits = [0 1];
            app.PropSat.ValueChangedFcn = createCallbackFcn(app, @PropSatValueChanged, true);
            app.PropSat.Value = 0.7;
            app.PropThr = uieditfield(app.Panel, 'numeric');
            app.PropThr.Limits = [0 1];
            app.PropThr.ValueChangedFcn = createCallbackFcn(app, @PropThrValueChanged, true);
            app.PropThr.Value = 0.3;
            app.InitializeDevicesPanel = uipanel(app.ConfigTab);
            app.InitializeDevicesPanel.Title = 'Initialize Devices';
            app.StatusLampLabel = uilabel(app.InitializeDevicesPanel);
            app.StatusLampLabel.Text = 'Status';
            app.PropDeviceLamp = uilamp(app.InitializeDevicesPanel);
            app.StatusLamp_2Label = uilabel(app.InitializeDevicesPanel);
            app.StatusLamp_2Label.Text = 'Status';
            app.BinaryDeviceLamp = uilamp(app.InitializeDevicesPanel);
            app.StatusLamp_3Label = uilabel(app.InitializeDevicesPanel);
            app.StatusLamp_3Label.Text = 'Status';
            app.StimulatorLamp = uilamp(app.InitializeDevicesPanel);
            app.PropDeviceConnectButton = uibutton(app.InitializeDevicesPanel, 'push');
            app.PropDeviceConnectButton.ButtonPushedFcn = createCallbackFcn(app, @PropDeviceConnectButtonPushed, true);
            app.PropDeviceConnectButton.Text = 'Connect';
            app.PropDeviceButtonGroup = uibuttongroup(app.InitializeDevicesPanel);
            app.PropDeviceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PropDeviceButtonGroupSelectionChanged, true);
            app.PropDeviceButtonGroup.Title = 'Proportional Control';
            app.SessantaquattroButton = uiradiobutton(app.PropDeviceButtonGroup);
            app.SessantaquattroButton.Text = 'Sessantaquattro+';
            app.MTwAwindaButton = uiradiobutton(app.PropDeviceButtonGroup);
            app.MTwAwindaButton.Text = 'MTw Awinda';
            app.MTwAwindaButton.Value = true;
            app.BinaryDeviceConnectButton = uibutton(app.InitializeDevicesPanel, 'push');
            app.BinaryDeviceConnectButton.ButtonPushedFcn = createCallbackFcn(app, @BinaryDeviceConnectButtonPushed, true);
            app.BinaryDeviceConnectButton.Text = 'Connect';
            app.BinaryDeviceButtonGroup = uibuttongroup(app.InitializeDevicesPanel);
            app.BinaryDeviceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BinaryDeviceButtonGroupSelectionChanged, true);
            app.BinaryDeviceButtonGroup.Title = 'Binary Control';
            app.eegomylabButton = uiradiobutton(app.BinaryDeviceButtonGroup);
            app.eegomylabButton.Text = 'eego mylab';
            app.SerialPorts = uidropdown(app.BinaryDeviceButtonGroup);
            app.SerialPorts.Items = {};
            app.SerialPorts.Value = {};
            app.PillowButton = uiradiobutton(app.BinaryDeviceButtonGroup);
            app.PillowButton.Text = 'Pillow';
            app.PillowButton.Value = true;
            app.StimulatorConnectButton = uibutton(app.InitializeDevicesPanel, 'push');
            app.StimulatorConnectButton.ButtonPushedFcn = createCallbackFcn(app, @StimulatorConnectButtonPushed, true);
            app.StimulatorConnectButton.Text = 'Stimulator Connect';
            app.DataStreamTab = uitab(app.TabGroup);
            app.DataStreamTab.Title = 'Data Stream';
            app.StimAxes = uiaxes(app.DataStreamTab);
            ylabel(app.StimAxes, 'Modulated stim parameter')
            app.StimAxes.XTick = [];
            app.StimAxes.YTick = [];
            app.StimAxes.ZTick = [];
            app.PropCmdAxes = uiaxes(app.DataStreamTab);
            ylabel(app.PropCmdAxes, 'Proportional Command')
            app.PropCmdAxes.XTick = [];
            app.PropCmdAxes.YTick = [0 1];
            app.PropCmdAxes.ZTick = [];
            app.BinCmdAxes = uiaxes(app.DataStreamTab);
            ylabel(app.BinCmdAxes, 'Binary Command')
            app.BinCmdAxes.XTick = [];
            app.BinCmdAxes.YTick = [0 1];
            app.BinCmdAxes.ZTick = [];
            app.StimulateButtonGroup = uibuttongroup(app.DataStreamTab);
            app.StimulateButtonGroup.Title = 'Stimulate';
            app.StopButton = uibutton(app.StimulateButtonGroup, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.StimulateButtonGroup, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StimLabel = uilabel(app.StimulateButtonGroup);
            app.StimLabel.Text = 'Stim: OFF';
            app.BinaryCmdLabel = uilabel(app.DataStreamTab);
            app.BinaryCmdLabel.Text = 'IDLE';
            app.StartTaskButton = uibutton(app.DataStreamTab, 'push');
            app.StartTaskButton.ButtonPushedFcn = createCallbackFcn(app, @StartTaskButtonPushed, true);
            app.StartTaskButton.Text = 'Start task';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlGUI_task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end