classdef stream_plot_save_64_win < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StreamSessantaquattroUIFigure  matlab.ui.Figure
        task                           matlab.ui.control.DropDown
        TaskLabel                      matlab.ui.control.Label
        trial                          matlab.ui.control.NumericEditField
        TrialEditFieldLabel            matlab.ui.control.Label
        subj                           matlab.ui.control.DropDown
        SubjectDropDownLabel           matlab.ui.control.Label
        file_name                      matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        MVC_list                       matlab.ui.container.CheckBoxTree
        Disp                           matlab.ui.control.TextArea
        Connected                      matlab.ui.control.Lamp
        ConnectedLampLabel             matlab.ui.control.Label
        port                           matlab.ui.control.NumericEditField
        PortEditFieldLabel             matlab.ui.control.Label
        IP                             matlab.ui.control.EditField
        IPEditFieldLabel               matlab.ui.control.Label
        TCP                            matlab.ui.control.Button
    end

    properties (Access = private)
        % -----------------------------------------------------------------
        % TCP connection objects
        tcp_on = 0;         % flag toggled every time Open TCP button is pressed
        start_stop = 0;     % flag toggled evert time Start button is pressed
        t                   % tcpserver object
        open_socket_fail = 1;
        establish_connection_fail = 1;
        % -----------------------------------------------------------------
        % Sessantaquattro settings
        Fs                  % sampling frequency of Sessantaquattro
        NumChan             % no. channels set by command line in 64
        ConvFact            % conversion factor to ensure mV output
        Command
        % -----------------------------------------------------------------
        % Read window length (sets EMG acquisition frequency) and buffer
        % window (over which to compute the RMS envelope)
        read_win = 0.08;    % read window
        buf_win = 0.72;     % buffer window [s]; In Knutson 0.24 s
        % -----------------------------------------------------------------
        % raw data, envelope and MVC handling
        first_run = 1;       % it's set to 1, so that timer is started when
        % first sample is received during MVC computation
        MVC = 1;
        curr_MVC = 1;
        stored_MVCs = []
        MVC_rec = [];       % temporarily stores MVC acquisition to plot it back once
        raw_rec = [];       % temporarily stores raw acquisition to plot it back once
        % subject finished experiment
        prev_checked_nodes % store checked nodes from MVC_list to compare
        % with next selection and eventually remove previous check
        MVC_start
        scope1
        % -----------------------------------------------------------------
        % directories' paths
        app_path
        recordings_path
        MVCs_path
        % -----------------------------------------------------------------
        % lsl structures
        info_sig
        outlet_sig
        comp_time           % time spent during data acquisition/processing loop
        % -----------------------------------------------------------------
    end
    
    methods (Access = private)
        
        function [NumChan,sampFreq,Command] = set_64(app)
            % Refer to the communication protocol for details about these variables:
            FSAMP = 0;      % if MODE != 3: 0 = 500 Hz,  1 = 1000 Hz, 2 = 2000 Hz
            % if MODE == 3: 0 = 2000 Hz, 1 = 4000 Hz, 2 = 8000 Hz
            NCH  = 0;       % 0 = 8 channels, 1 = 16 channels, 2 = 32 channels, 3 = 64 channels
            MODE = 1;       % 0 = Monopolar, 1 = Bipolar, 2 = Differential, 3 = Accelerometers, 6 = Impedance check, 7 = Test Mode
            HRES = 0;       % 0 = 16 bits, 1 = 24 bits
            HPF  = 1;       % 0 = DC coupled, 1 = High pass filter active
            EXTEN = 0;      % 0 = standard input range, 1 = double range, 2 = range x 4, 3 = range x 8
            TRIG = 0;       % 0 = Data transfer and REC on SD controlled remotely, 3 = REC on SD controlled from the pushbutton
            GO   = 1;       % 0 = just send the settings, 1 = send settings and start the data transfer
            
            % Conversion factor for the bioelectrical signals to get the values in mV
            app.ConvFact = 0.000286;
            
            % Create the command to send to sessantaquattro
            Command = 0;
            Command = Command + GO;
            Command = Command + TRIG * 4;
            Command = Command + EXTEN * 16;
            Command = Command + HPF * 64;
            Command = Command + HRES * 128;
            Command = Command + MODE * 256;
            Command = Command + NCH * 2048;
            Command = Command + FSAMP * 8192;
            dec2bin(Command);
            
            % Set the variables for the script
            switch NCH
                case 0
                    if(MODE == 1)
                        NumChan = 8;
                    else
                        NumChan = 12;
                    end
                case 1
                    if(MODE == 1)
                        NumChan = 12;
                    else
                        NumChan = 20;
                    end
                case 2
                    if(MODE == 1)
                        NumChan = 20;
                    else
                        NumChan = 36;
                    end
                case 3
                    if(MODE == 1)
                        NumChan = 36;
                    else
                        NumChan = 68;
                    end
            end
            
            switch FSAMP
                case 0
                    if(MODE == 3)
                        sampFreq = 2000;
                    else
                        sampFreq = 500;
                    end
                case 1
                    if(MODE == 3)
                        sampFreq = 4000;
                    else
                        sampFreq = 1000;
                    end
                case 2
                    if(MODE == 3)
                        sampFreq = 8000;
                    else
                        sampFreq = 2000;
                    end
                case 3
                    if(MODE == 3)
                        sampFreq = 16000;
                    else
                        sampFreq = 4000;
                    end
                otherwise
                    disp('wrong value for FSAMP')
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialization libraries
            addpath(genpath("liblsl-Matlab-master"));

            % CAMBIA app_path QUANDO CAMBI POSIZIONE DELL'APP
            app.app_path = 'VR-grasp-control-strategies\Matlab code\GUI';
            cd(app.app_path);
            app.recordings_path = strcat(app.app_path,'\Recordings');
            app.MVCs_path = strcat(app.app_path,'\MVCs');


            % POTRESTI VOLER CAMBIARE #path, #directory, #pwd
            % change windowStyle property of the app
            app.StreamSessantaquattroUIFigure.WindowStyle = 'alwaysontop';

            % disable Start button until user chooses MVC (stored or new)
            % and communication is established
            app.StartButton.Enable = 'off';
            % set TCP button
            app.TCP.BackgroundColor = [0 1 0];
            % load and display MVCs from saved ones (file 'MVC.txt')
            app.stored_MVCs = readlines(strcat(pwd,'\MVC.txt'));
            % create as many nodes on the tree checkbox (will have to
            % update file and tree after every new MVC is computed and
            % stored)
            for i = 1:length(app.stored_MVCs)
                if app.stored_MVCs(i)~=""
                    uitreenode(app.MVC_list,'Text',num2str(app.stored_MVCs(i)));
                end
            end
            % last node is 'Compute new MVC'
            uitreenode(app.MVC_list,'Text','Compute new MVC');
            % scroll to bottom of checklist for simplicity of use
            scroll(app.MVC_list,'bottom');

            % fill Filename edit field -  reads filesystem and finds last
            % recorded trial for selected task, subject for today (day to
            % day basis)
            % automatically increments trial number for each subject, task
            % build date string for new file
            dateStr = strrep(strrep(string(datetime('now'),'y-MM-dd'),':','-'),' ','-');
            % read folder for previous stored trials for the same day and
            % subject and find trial number
            if ~isfolder(app.recordings_path)
                mkdir(app.recordings_path);
            end
            files_in_pwd_folder = dir(app.recordings_path);
            % string with today date (es. '2023-02-10')
            today_str = string(datetime('now','Format','y-MM-dd'));
            % string to match for selected task search
            selected_task = string(['task-', app.task.Value]);
            % temporarily store trial number
            temp = app.trial.Value;
            % check if stored files in current folder (which you can set
            % in app properties on top of this code) 
            % contain trials performed by a subject on same
            % day
            for i = 1:length(files_in_pwd_folder)
                if contains(files_in_pwd_folder(i).name,today_str) && contains(files_in_pwd_folder(i).name,app.subj.Value) && contains(files_in_pwd_folder(i).name,selected_task)
                    start_ind = regexp(files_in_pwd_folder(i).name,'trial-','end');
                    end_ind = regexp(files_in_pwd_folder(i).name,'-EMG_raw');
                    % if trial number of file > local variable, increase it
                    % and warn user
                    if str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1))>=app.trial.Value
                        app.trial.Value = str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1));
                        % display user already performed this trial number
                        % today, so starting from next one
                        txt = sprintf('Subject: %s\nTask: %s\nTrial %d already present for today.\nStarting from trial %d', ...
                            app.subj.Value, app.task.Value, app.trial.Value, app.trial.Value+1);
                        app.Disp.Value = txt;
                        temp = app.trial.Value+1;
                    end
                end
            end
            app.trial.Value = temp;
            txt = strcat(dateStr, '-', app.subj.Value, '-task-', app.task.Value,'-trial-', num2str(app.trial.Value), '-EMG_raw');
            txt = strcat(txt,'.txt');
            % show filename in the edit field
            app.file_name.Value = txt;

            % now stop, waiting for user to check 1 of the already available
            % MVCs or 'Compute new MVC', and establish the TCP connection
        end

        % Button pushed function: TCP
        function TCPButtonPushed(app, event)
            app.tcp_on = ~app.tcp_on;
            % turn off communication
            if ~app.tcp_on
                fwrite(app.t,app.Command-1, 'int16');
                fclose(app.t);
                delete(app.t);
                clear app.t
                app.Connected.Color = [0.8 0.8 0.8];
                app.TCP.Text = 'Open TCP';
                app.TCP.BackgroundColor = [0 1 0];
                app.StartButton.Enable = 'off';
            % enable communication
            else
                app.TCP.Enable = 'off';
                % until it is fixed, TCP button won't be turned on during
                % app functioning, because it would't allow to be resumed
                % (#check1#fix1#bug1) once again
                app.TCP.BackgroundColor = [1 0 0];
                % set 64 for enabling communication
                [app.NumChan,app.Fs,app.Command] = set_64(app);
                % Open the TCP socket as server
                app.open_socket_fail = 1;
                app.establish_connection_fail = 1;
                while (app.open_socket_fail || app.establish_connection_fail)
                    try
                        app.t = tcpip(app.IP.Value, app.port.Value, 'NetworkRole', 'server');
                        app.open_socket_fail = 0;
                        % Increase the input buffer size
                        app.t.InputBufferSize = 2*app.NumChan*app.Fs*10;
                    catch ME
                        report = getReport(ME);
                        errMsg = 'Address already used, try change it or restart device.';
                        app.Disp.Value = errMsg;
                    end
                
                    % Wait into this loop until a client is successfully connected
                    try
                        fopen(app.t);
                        % Send the command to sessantaquattro
                        fwrite(app.t, app.Command, 'int16');
                        app.establish_connection_fail = 0;
                    catch ME
                        report = getReport(ME);
                        errMsg = 'Invalid or deleted object.\nTry changing IP or port.';
                        app.Disp.Value = errMsg;
                        clear app.t
                    end
                end

                % Once out of previous loop for engaging connection, set
                % connected lamp to green ..
                app.Connected.Color = [0 1 0];
% %                 app.TCP.Enable = 'on';  don't enable it as said in
                % (#check1#fix1#bug1), until i solve it
                app.TCP.Text = '';
                app.TCP.BackgroundColor = [0.8 0.8 0.8];
                
                % Now open LSL connection
                % Instantiate the library
                lib = lsl_loadlib();
                
                % Make a new stream outlet
                app.info_sig =  lsl_streaminfo(lib, 'prop_signal', 'Stream', 1, app.Fs*app.read_win);
                app.outlet_sig = lsl_outlet(app.info_sig);

                % and only after user checked a node in app.MVC_list, Start 
                % button is enabled
                if length(app.MVC_list.CheckedNodes)==1
                    app.StartButton.Enable = 'on';
                else
                    app.Disp.Value = 'Choose among already stored MVCs or compute a new one';
                end

                % now stop, waiting for user to check 1 of the already available
                % MVCs or 'Compute new MVC'
            end
        end

        % Close request function: StreamSessantaquattroUIFigure
        function StreamSessantaquattroUIFigureCloseRequest(app, event)
            %% clear communication
            try
                fwrite(app.t, app.Command-1, 'int16');
                fclose(app.t);
                delete(app.t);
                clear app.t
                close all
                delete(app)
                clear
            catch
                delete(app)
            end
        end

        % Callback function: MVC_list
        function MVC_listCheckedNodesChanged(app, event)
            % uncheck previous choice after new selection (this behaviour
            % can't be set as a property of the checklist tree)
            allnodes = findall(app.MVC_list);
            checked_nodes = ismember(allnodes,app.MVC_list.CheckedNodes);
            if sum(checked_nodes)==1
                app.prev_checked_nodes = checked_nodes;
            elseif sum(checked_nodes)>1
                % clear previous selection ..
                node_to_keep = checked_nodes-app.prev_checked_nodes;
                app.MVC_list.CheckedNodes = allnodes(find(node_to_keep));
                app.prev_checked_nodes = node_to_keep;
                app.Disp.Value = '';
            end
            % check which node is marked, display text and if communication
            % is already set, enable start button
            if ~isempty(app.MVC_list.CheckedNodes)
                if strcmp(app.MVC_list.CheckedNodes.Text,'Compute new MVC')
                    % start new MVC acquisition by displaying text in app.Disp
                    % enabling 'Start' button
                    % if connection is already set (green lamp)
                    if isequal(app.Connected.Color,[0 1 0]) 
                        txt = 'Push Start button when you are ready';
                        app.Disp.Value = txt;
                        app.StartButton.Enable = 'on';
                    else
                        txt = 'Push Open TCP Button, then Start button';
                        app.Disp.Value = txt;
                    end
                else
                    % checked one of available MVCs
                    % line contains both name and MVC, must only load MVC
                    txt = app.MVC_list.CheckedNodes.Text; 
                    ind = regexp(txt,' ','end');
                    app.MVC = str2double(txt(ind+1:end));
                    % display it
                    txt = sprintf('MVC: %.2f\n',app.MVC);
                    app.Disp.Value = txt;
                    if isequal(app.Connected.Color,[0 1 0]) 
                        txt = [txt newline 'Push Start button when you are ready'];
                        app.Disp.Value = txt;
                        app.StartButton.Enable = 'on';
                    else
                        txt = [txt newline 'Push Open TCP Button, then Start button'];
                        app.Disp.Value = txt;
                    end
                end
            else
                app.StartButton.Enable = 'off';
                app.Disp.Value = '';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.start_stop = ~app.start_stop;
            % start data acquisition and processing
            if app.start_stop
                close all
                % disable list to prevent user checking other MVCs 
                app.MVC_list.Enable = 'off';
                app.StartButton.Text = 'Stop';
                % prevent user from stopping communication while streaming
                app.TCP.Enable = 'off';         
                % prevent user changing subject or task during recording
                app.subj.Enable = 'off';
                app.task.Enable = 'off';

                % Buffering [Knutson et al. 2004]
                Ts = 1/app.Fs; %[s]
                bufdata = dsp.AsyncBuffer(app.buf_win*app.Fs); % buffer

                % Set plot time span
                T = 2;  % [s] plot time span
                y_range = [2000 1];
                % create scope
                % take position of main app figure
                pos = app.StreamSessantaquattroUIFigure.Position;
                pos(1) = pos(1) + 460;
                pos(3) = 600; pos(4) = 525;
                app.scope1 = timescope("SampleRate",[app.Fs, app.Fs], ...
                    "TimeSpanOverrunAction","scroll",...
                    'PlotType','line', ...
                    'TimeUnits','seconds', ...
                    'YLimits',[-1,1], ...
                    'TimeSpan',T,...
                    'Name','EMG', ...
                    'NumInputPorts',2, ...
                    'ChannelNames',{'Raw','RMS Env'}, ...
                    'Position',pos);

                % compute size of expected amount of data
                % 16 bits resolution
                blockData = 2*app.NumChan*app.Fs*app.read_win;   
                
                % define filters
                % stop band BW filter
                [b_bandStop,a_bandStop] = butter(3,[49 51]/(app.Fs/2),'stop');    
                % high pass BW filter - 20 Hz
                [b_HP,a_HP] = butter(3,20/(app.Fs/2),'high');    
                % signal is thus bandpass filtered [20, app.Fs/4]. low pass
                % filter is firmware-based and can't be changed: depends on
                % sampl. f.($ Doc.)

                
                % create files for saving: trials are saves in file couples 
                % ending with -EMG_raw.txt, -EMG_env.txt (in Recordings
                % folder), while MVCs are stored as files ending with
                % '-MVC.txt' in folder MVCs, besides in the file
                % 'MVC.txt', used to temporarily provide MVCs to re-use
                % during experiments

                if ~strcmp(app.MVC_list.CheckedNodes.Text,'Compute new MVC')
                    % check and update trial number if it already exists
                    % for selected task
                    % (for same subject in same day)
                    % read folder for previous stored trials for the same
                    % day,subject and task, then find and increase trial number
                    files_in_pwd_folder = dir(app.recordings_path);
                    % string with today date (es. '2023-02-12')
                    today_str = string(datetime('now','Format','y-MM-dd'));
                    % string to match for selected task search
                    selected_task = string(['task-', app.task.Value]);
                    % temporarily store trial number
                    temp = app.trial.Value;
                    % check if stored files in current folder (pwd, you may want to
                    % change folder) contain trials performed by a subject on same
                    % day
                    for i = 1:length(files_in_pwd_folder)
                        if contains(files_in_pwd_folder(i).name,today_str) && contains(files_in_pwd_folder(i).name,app.subj.Value) && contains(files_in_pwd_folder(i).name,selected_task)
                            start_ind = regexp(files_in_pwd_folder(i).name,'trial-','end');
                            end_ind = regexp(files_in_pwd_folder(i).name,'-EMG_raw');
                            % if trial number of file > local variable, increase it
                            if str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1))>=app.trial.Value
                                app.trial.Value = str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1));
                                % user already performed this task and trial
                                % number today, so starting from next one
                                temp = app.trial.Value+1;
                            end
                        end
                    end
                    app.trial.Value = temp;

                    % build title string of the new files to store recordings
                    % (raw and env): 
                    dateStr = strrep(strrep(string(datetime('now'),'y-MM-dd'),':','-'),' ','-');
                    title_EMG = strcat(dateStr, '-', app.subj.Value, '-task-', app.task.Value,'-trial-', num2str(app.trial.Value), '-EMG_raw');
                    title_EMG = strcat(title_EMG,'.txt');
                    title_env = strcat(dateStr, '-', app.subj.Value, '-task-', app.task.Value, '-trial-', num2str(app.trial.Value), '-EMG_env');
                    title_env = strcat(title_env,'.txt');
                    % update edit field with filename (of the raw EMG)
                    app.file_name.Value = title_EMG;
                    % create new pair of files
                    new_file_EMGraw = strcat(app.recordings_path, '\', title_EMG);
                    new_file_EMGenv = strcat(app.recordings_path,'\', title_env);
                    % Save MVC into the txt file
                    writematrix(app.MVC,new_file_EMGraw);
                    writematrix(app.MVC,new_file_EMGenv);
                    % Take current time and write to txt file (only at I round)
                    cc = 0;
                    time_start = datestr(now,'HH:MM:SS.FFF');
                    writematrix(time_start,new_file_EMGraw,'WriteMode','append');
                    writematrix(time_start,new_file_EMGenv,'WriteMode','append');
                    cc = cc + 1;
                else    % recording of new MVC started
                    % build title string of the new file to store MVC
                    % recording
                    dateStr = strrep(strrep(string(datetime('now'),'y-MM-dd-HH-mm-ss'),':','-'),' ','-');
                    title_MVC = strcat(dateStr, '-', app.subj.Value, '-MVC');
                    title_EMG = strcat(title_MVC,'.txt');
                    % create new file
                    if ~isfolder(app.MVCs_path)
                        mkdir(app.MVCs_path);
                    end
                    new_file_MVC = strcat(app.MVCs_path, '\', title_MVC);
                    % Take current time and write to txt file (only at I round)
                    cc = 0;
                    time_start = datestr(now,'HH:MM:SS.FFF');
                    writematrix(time_start,new_file_MVC,'WriteMode','append');
                    cc = cc + 1;
                    % set curr_MVC and MVC low to find new one
                    app.curr_MVC = eps;
                    app.MVC = eps;
                    % wait 1 s to avoid peaks in EMG reading due to
                    % streaming start
                    pause(1)
                end
                
                % start timer for saving the timestamps 
                tic_start = tic;
                while(app.start_stop)
                    try
                        while app.t.BytesAvailable<blockData
                        end
                        
                        % if 'Compute new MVC' checked, instruct user that
                        % can stop when finished (no timer)
                        if strcmp(app.MVC_list.CheckedNodes.Text,'Compute new MVC')
                            if app.first_run
                                app.first_run = 0;
                                % raise it back once MVC computation is
                                % interrupted
%                                 Non serve rimetterli a eps se l'ho già
%                                 fatto prima di iniziare lo stream nel
%                                 caso "Compute new MVC"
%                                 app.curr_MVC = eps;
%                                 app.MVC = eps;
                                txt = 'Press Stop button to stop MVC computation';
                                app.Disp.Value = txt;
                            end
                        else
                            % trial begun correctly
                            app.first_run = 0;
                            txt = sprintf('MVC: %.2f\n\nTrial %d started correctly. Stop whenever you like',app.MVC, app.trial.Value);
                            app.Disp.Value = txt;
                        end

                        % Read requested amount of data into signed integer
                        data = fread(app.t, [app.NumChan, app.Fs*app.read_win], 'int16');

                        % take time soon after reading, for pause command
                        % at end of loop.
                        app.comp_time = tic;
                        % Funziona, ma causa un delay che con drawnow non
                        % c'è. Eppure sarebbe più esatto
                        % pause(0.24-comp_time) per correttezza della
                        % finestra di acquisizione.
                        % Vedi se con il fisso più potente, non c'è.
                        % vedi riga 629
                        
                        % Create the vectors for timestamps
                        timestamps = nan(size(data,2),1);
                        timestamps(end) = toc(tic_start);

                        % only take desired channel
                        data = data(1,:);
                        data = data*app.ConvFact;

                        % filter EMG
                        data = filtfilt(b_bandStop,a_bandStop,data); % bandstop
                        data = filtfilt(b_HP,a_HP,data);             % high pass

                        % write EMG data into buffer
                        write(bufdata,data');

                        % read data from buffer without changing no. unread samples (peek)
                        % (window over which to compute the rms)
                        pointData = peek(bufdata);
                        % In case buffer doesn't have the requested amount
                        % of samples, use nZP as second output from peek
                        % [pointData, nZP] = peek(bufdata), and use it to
                        % exclude zeros from rms computation

                        % compute rms envelope
                        env = rms(pointData);

                        % Compute MVC
                        if strcmp(app.MVC_list.CheckedNodes.Text,'Compute new MVC')
                            % save env sample into MVC_rec for plotting
                            % purpose at end of MVC acquisition
                            app.MVC_rec = [app.MVC_rec, env];
                            app.raw_rec = [app.raw_rec, data];
                            if env>app.curr_MVC
                                app.curr_MVC = env;
                            end
                            % Save the data into the txt file, with
                            % timestamp vector
                            writematrix([timestamps(end) env],new_file_MVC,'WriteMode','append');
                            % display MVC being computed
                            txt = sprintf('MVC: %.2f',app.curr_MVC);
                            app.Disp.Value = txt;
                        else    % controlling VR hand
                            % Normalize env and raw data wrt MVC
                            data = data./app.MVC;
                            env = env/app.MVC;

                            % Push sample through LSL
                            app.outlet_sig.push_sample(env);

                            % Save the data into the txt files
                            writematrix([timestamps data'],new_file_EMGraw,'WriteMode','append');
                            writematrix([timestamps(end) env],new_file_EMGenv,'WriteMode','append');
                        end

                        % Plot raw EMG and envelope
                        app.scope1(data',env*ones(length(data),1))

                        % Wait for "readwin" seconds
                        pause(app.read_win-toc(app.comp_time));
                        % drawnow
                        % (#check2#fix2#bug2)
                        % ------- UNO TRA QUESTI DUE COMANDI (comunque di
                        % attesa) evita che l'app non resti chiusa nel loop
                        % di streaming senza sentire la callback del
                        % bottone quando si stoppa.
                        % Se si usa pause, c'è bisogno di comp_time, quindi
                        % scommentarlo alla linea 563
                    catch
                    end
                end
                
                
            else
                close(app.scope1)    % close scope
                % enable subject and task dropdowns
                app.subj.Enable = 'on';
                app.task.Enable = 'on';
                % if user was computing new MVC
                if strcmp(app.MVC_list.CheckedNodes.Text,'Compute new MVC')
                    % plot MVC acquisition to visually check it
                    fH = figure;
                    % take position of main app figure
                    pos = app.StreamSessantaquattroUIFigure.Position;
                    pos(1) = pos(1) + 460;
                    pos(3) = 600; pos(4) = 474;
                    fH.Position = pos;
                    % build x axes for both raw and env streams
                    x = 0:1/app.Fs:(length(app.raw_rec)-1)/app.Fs;
                    plot(x,app.raw_rec,'k'); hold on;
                    Fs_env = length(app.MVC_rec)/length(app.raw_rec)*app.Fs;
                    % this is based on the fact that raw and env streams
                    % differ in length due to sampling rates being
                    % different
                    x_env = 0:1/Fs_env:(length(app.MVC_rec)-1)/Fs_env;
                    plot(x_env,app.MVC_rec,'b','linewidth',2);
                    % highlight MVC
                    [M,pos] = max(app.MVC_rec);
                    plot(x_env(pos),M,'bo','markersize',10,'LineWidth',2);
                    line(gca,0:round(x_env(pos)),M*ones(length(0:round(x_env(pos))),1),'Color','k','LineStyle','--','linewidth',2);
                    xticks([]);
                    xlabel('Time', 'FontSize',15);
                    ylabel('RMS Envelope','FontSize',15);
                    title(sprintf('New MVC: %.2f', M),"FontSize",15);
                    
                    % warn user
                    txt = sprintf('MVC: %.2f\n\nAcquisition completed.\nYou can repeat again or start a new trial by pressing Start button',app.curr_MVC);
                    app.Disp.Value = txt;
                    app.MVC = app.curr_MVC;
                    % write file MVC.txt
                    fID = fopen(strcat(pwd,'\MVC.txt'),'a');
                    fprintf(fID,['\n' app.subj.Value ' ' num2str(app.MVC)]);
                    fclose(fID);
                    % read newly written file
                    app.stored_MVCs = readlines(strcat(pwd,'\MVC.txt'));
                    % update list by removing first to last MVC..
                    allnodes = findall(app.MVC_list);
                    allnodes(end).delete;
                    % .. adding last MVC
                    uitreenode(app.MVC_list,'Text',num2str(app.stored_MVCs(end)));
                    % and putting back 'Compute new MVC' option
                    uitreenode(app.MVC_list,'Text','Compute new MVC');
                    % scroll to bottom of checklist for simplicity of use
                    scroll(app.MVC_list,'bottom');
                    
                    % check last computed MVC by default
                    allnodes = findall(app.MVC_list);
                    app.MVC_list.CheckedNodes = allnodes(end-1);
                    
                    % update list of prev_checked_nodes (which is used to
                    % take note of the current selected node and delete it
                    % in case of a new selection and prevent multiple
                    % ticks)
                    allnodes = findall(app.MVC_list);
                    app.prev_checked_nodes = ismember(allnodes,app.MVC_list.CheckedNodes);

                    % reset first_run to start again timer from 0 s next
                    % time MVC is computed
                    app.first_run = 1;
                    app.MVC_rec = [];
                    app.raw_rec = [];
                    
                else    % user was streaming
                    % reset first run, to display message next time new
                    % trial is started ('trial started correctly')
                    app.first_run = 1;
                    % update trial counter
                    app.trial.Value = app.trial.Value+1;
                    % trial stopped correctly, warn user
                    txt = sprintf('Trial %d correctly stopped.\nNext trial: %d', app.trial.Value-1, app.trial.Value);
                    app.Disp.Value = txt;
                end
                app.MVC_list.Enable = 'on';
                app.StartButton.Text = 'Start';
% %                 app.TCP.Enable = 'on';
            end
        end

        % Value changed function: subj
        function subjValueChanged(app, event)
            % when changing subject, look in directory if he/she already
            % performed trials for selected task, today
            app.trial.Value = 1;
            % check and update trial number if it already exists
            % (for same subject in same day) -- you may want to
            % change folder
            % read folder for previous stored trials for the same day and
            % subject and find trial number
            files_in_pwd_folder = dir(app.recordings_path);
            % string with today date (es. '2023-02-12')
            today_str = string(datetime('now','Format','y-MM-dd'));
            % string to match for selected task search
            selected_task = string(['task-', app.task.Value]);
            % temporarily store trial number
            temp = app.trial.Value;
            % check if stored files in current folder (pwd, you may want to
            % change folder) contain trials performed by a subject on same
            % day, for selected task
            for i = 1:length(files_in_pwd_folder)
                if contains(files_in_pwd_folder(i).name,today_str) && contains(files_in_pwd_folder(i).name,app.subj.Value) && contains(files_in_pwd_folder(i).name,selected_task)
                    start_ind = regexp(files_in_pwd_folder(i).name,'trial-','end');
                    end_ind = regexp(files_in_pwd_folder(i).name,'-EMG_raw');
                    % if trial number of file > local variable, increase it
                    % and warn user
                    if str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1))>=app.trial.Value
                        app.trial.Value = str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1));
                        % user already performed this trial number
                        % today, so starting from next one
                        temp = app.trial.Value+1;
                    end
                end
            end
            app.trial.Value = temp;
            
            % warn user about last stored trial for selected task
            app.Disp.Value = sprintf('Task: %s\nLast saved trial: %d. Starting from %d.', app.task.Value, app.trial.Value-1,app.trial.Value);
            dateStr = strrep(strrep(string(datetime('now'),'y-MM-dd'),':','-'),' ','-');
            txt = strcat(dateStr, '-', app.subj.Value, '-task-', app.task.Value, '-trial-', num2str(app.trial.Value), '-EMG_raw');
            txt = strcat(txt,'.txt');
            % show filename in the edit field
            app.file_name.Value = txt;
        end

        % Value changed function: task
        function taskValueChanged(app, event)
            % check new trial number if user already performed this task
            % today, warn user and update filename
            % ----stessa identica funzione per la callback subjValueChanged
            % ----
            % when changing subject, look in directory if he/she already
            % performed trials for selected task, today
            app.trial.Value = 1;
            % check and update trial number if it already exists
            % (for same subject in same day) -- you may want to
            % change folder
            % read folder for previous stored trials for the same day and
            % subject and find trial number
            files_in_pwd_folder = dir(app.recordings_path);
            % string with today date (es. '2023-02-12)
            today_str = string(datetime('now','Format','y-MM-dd'));
            % string to match for selected task search
            selected_task = string(['task-', app.task.Value]);
            % temporarily store trial number
            temp = app.trial.Value;
            % check if stored files in current folder (pwd, you may want to
            % change folder) contain trials performed by a subject on same
            % day, for selected task
            for i = 1:length(files_in_pwd_folder)
                if contains(files_in_pwd_folder(i).name,today_str) && contains(files_in_pwd_folder(i).name,app.subj.Value) && contains(files_in_pwd_folder(i).name,selected_task)
                    start_ind = regexp(files_in_pwd_folder(i).name,'trial-','end');
                    end_ind = regexp(files_in_pwd_folder(i).name,'-EMG_raw');
                    % if trial number of file > local variable, increase it
                    % and warn user
                    if str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1))>=app.trial.Value
                        app.trial.Value = str2num(files_in_pwd_folder(i).name(start_ind+1:end_ind-1));
                        % user already performed this trial number
                        % today, so starting from next one
                        temp = app.trial.Value+1;
                    end
                end
            end
            app.trial.Value = temp;
            
            % warn user about last stored trial for selected task
            app.Disp.Value = sprintf('Task: %s\nLast saved trial: %d. Starting from %d.', app.task.Value, app.trial.Value-1,app.trial.Value);
            dateStr = strrep(strrep(string(datetime('now'),'y-MM-dd'),':','-'),' ','-');
            txt = strcat(dateStr, '-', app.subj.Value, '-task-', app.task.Value, '-trial-', num2str(app.trial.Value), '-EMG_raw');
            txt = strcat(txt,'.txt');
            % show filename in the edit field
            app.file_name.Value = txt;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StreamSessantaquattroUIFigure = uifigure('Visible', 'off');
            app.StreamSessantaquattroUIFigure.Name = 'Stream Sessantaquattro';
            app.StreamSessantaquattroUIFigure.CloseRequestFcn = createCallbackFcn(app, @StreamSessantaquattroUIFigureCloseRequest, true);
            app.TCP = uibutton(app.StreamSessantaquattroUIFigure, 'push');
            app.TCP.ButtonPushedFcn = createCallbackFcn(app, @TCPButtonPushed, true);
            app.TCP.Text = 'Open TCP';
            app.IPEditFieldLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IP = uieditfield(app.StreamSessantaquattroUIFigure, 'text');
            app.IP.Value = '0.0.0.0';
            app.PortEditFieldLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.port = uieditfield(app.StreamSessantaquattroUIFigure, 'numeric');
            app.port.ValueDisplayFormat = '%11.4d';
            app.port.Value = 45454;
            app.ConnectedLampLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.ConnectedLampLabel.Text = 'Connected';
            app.Connected = uilamp(app.StreamSessantaquattroUIFigure);
            app.Disp = uitextarea(app.StreamSessantaquattroUIFigure);
            app.MVC_list = uitree(app.StreamSessantaquattroUIFigure, 'checkbox');
            % Assign Checked Nodes
            app.MVC_list.CheckedNodesChangedFcn = createCallbackFcn(app, @MVC_listCheckedNodesChanged, true);
            app.StartButton = uibutton(app.StreamSessantaquattroUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FilenameEditFieldLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.file_name = uieditfield(app.StreamSessantaquattroUIFigure, 'text');
            app.SubjectDropDownLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.subj = uidropdown(app.StreamSessantaquattroUIFigure);
            app.subj.Items = {'sub1', 'sub2', 'sub3', 'sub4', 'sub5'};
            app.subj.ValueChangedFcn = createCallbackFcn(app, @subjValueChanged, true);
            app.subj.Value = 'sub1';
            app.TrialEditFieldLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.trial = uieditfield(app.StreamSessantaquattroUIFigure, 'numeric');
            app.trial.Limits = [1 Inf];
            app.trial.Editable = 'off';
            app.trial.Value = 1;
            app.TaskLabel = uilabel(app.StreamSessantaquattroUIFigure);
            app.TaskLabel.Text = 'Task';
            app.task = uidropdown(app.StreamSessantaquattroUIFigure);
            app.task.Items = {'1', '2', '3'};
            app.task.ValueChangedFcn = createCallbackFcn(app, @taskValueChanged, true);
            app.task.Value = '1';
            % Show the figure after all components are created
            app.StreamSessantaquattroUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stream_plot_save_64_win

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StreamSessantaquattroUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StreamSessantaquattroUIFigure)
        end
    end
end