classdef interface_characterization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InterfaceUIFigure              matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SaveTab                        matlab.ui.container.Tab
        DetectedFEsLabel               matlab.ui.control.Label
        AnalogIOLamp                   matlab.ui.control.Lamp
        AnalogIOLampLabel              matlab.ui.control.Label
        EMGLamp                        matlab.ui.control.Lamp
        EMGLampLabel                   matlab.ui.control.Label
        StimLamp                       matlab.ui.control.Lamp
        StimLampLabel                  matlab.ui.control.Label
        RecordingLabel                 matlab.ui.control.Label
        RecordingstatusPanel           matlab.ui.container.Panel
        FilesaveMsg                    matlab.ui.control.TextArea
        StopRecordingButton            matlab.ui.control.Button
        PauseRecordingButton           matlab.ui.control.Button
        StartRecordingButton           matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        SubjectDropDown                matlab.ui.control.DropDown
        SubjectDropDownLabel           matlab.ui.control.Label
        SelectfolderButton             matlab.ui.control.Button
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        SingleChannelStimulationTab    matlab.ui.container.Tab
        StimChannelDropDown            matlab.ui.control.DropDown
        StimChannelDropDownLabel       matlab.ui.control.Label
        stimSetup                      matlab.ui.container.TabGroup
        SinglePulseTab                 matlab.ui.container.Tab
        single_pulse_ampl              matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel       matlab.ui.control.Label
        icon_pulse                     matlab.ui.control.Image
        single_pulse_PW                matlab.ui.control.NumericEditField
        DurationsLabel                 matlab.ui.control.Label
        single_pulse_descr             matlab.ui.control.Label
        SingleBurstTab                 matlab.ui.container.Tab
        single_burst_dur               matlab.ui.control.NumericEditField
        DurationmsLabel                matlab.ui.control.Label
        single_burst_freq              matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_3    matlab.ui.control.Label
        single_burst_ampl              matlab.ui.control.NumericEditField
        AmplitudeAEditField_2Label     matlab.ui.control.Label
        single_burst_PW                matlab.ui.control.NumericEditField
        DurationsLabel_2               matlab.ui.control.Label
        icon_single_burst              matlab.ui.control.Image
        single_burst_descr             matlab.ui.control.Label
        RCTab                          matlab.ui.container.Tab
        RC_amp_step                    matlab.ui.control.Spinner
        RC_freq                        matlab.ui.control.Spinner
        FrequencyHzSpinnerLabel        matlab.ui.control.Label
        RC_PW                          matlab.ui.control.NumericEditField
        PulseWidthsEditFieldLabel      matlab.ui.control.Label
        RC_reps                        matlab.ui.control.NumericEditField
        RepetitionsperamplitudeEditFieldLabel  matlab.ui.control.Label
        RC_max_ampl                    matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        RC_min_ampl                    matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        OffsetLabel_9                  matlab.ui.control.Label
        RC_label                       matlab.ui.control.Label
        RC_descr                       matlab.ui.control.Label
        icon_RC                        matlab.ui.control.Image
        AMBurstsTab                    matlab.ui.container.Tab
        AM_burst_intra_burst_freq      matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_8     matlab.ui.control.Label
        AM_burst_amp_step              matlab.ui.control.Spinner
        OffsetLabel_10                 matlab.ui.control.Label
        AM_burst_dur                   matlab.ui.control.NumericEditField
        BurstdurationmsLabel_3         matlab.ui.control.Label
        AM_burst_max_ampl              matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        AM_burst_min_ampl              matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        AM_burst_PW                    matlab.ui.control.NumericEditField
        PulseWidthsEditField_2Label    matlab.ui.control.Label
        AM_burst_reps                  matlab.ui.control.NumericEditField
        RepetitionsperamplitudeEditField_2Label  matlab.ui.control.Label
        AM_burst_inter_burst_interval  matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_7     matlab.ui.control.Label
        AM_burst_label3                matlab.ui.control.Label
        AM_burst_descr                 matlab.ui.control.Label
        icon_AM_burst                  matlab.ui.control.Image
        FMBurstsTab                    matlab.ui.container.Tab
        FM_burst_freq_step             matlab.ui.control.Spinner
        OffsetLabel_11                 matlab.ui.control.Label
        FM_burst_inter_burst_int       matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_10    matlab.ui.control.Label
        FM_burst_ampl                  matlab.ui.control.NumericEditField
        IntraBurstFrequencyLabel_9     matlab.ui.control.Label
        FM_burst_max_freq              matlab.ui.control.NumericEditField
        MaxEditField_3Label            matlab.ui.control.Label
        FM_burst_dur                   matlab.ui.control.NumericEditField
        BurstdurationmsLabel_4         matlab.ui.control.Label
        FM_burst_min_freq              matlab.ui.control.NumericEditField
        MinEditField_3Label            matlab.ui.control.Label
        FM_burst_PW                    matlab.ui.control.NumericEditField
        PulseWidthsEditField_4Label    matlab.ui.control.Label
        FM_burst_reps                  matlab.ui.control.NumericEditField
        RepetitionsperfrequencyEditFieldLabel  matlab.ui.control.Label
        FM_burst_label3                matlab.ui.control.Label
        FM_burst_descr                 matlab.ui.control.Label
        icon_FM_burst                  matlab.ui.control.Image
        TonicTab                       matlab.ui.container.Tab
        tonic_PW                       matlab.ui.control.NumericEditField
        DurationsLabel_3               matlab.ui.control.Label
        icon_tonic                     matlab.ui.control.Image
        tonic_freq                     matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel_2    matlab.ui.control.Label
        tonic_TC                       matlab.ui.container.ButtonGroup
        tonic_train_dur                matlab.ui.control.NumericEditField
        tonic_stop_button              matlab.ui.control.RadioButton
        tonic_train_dur_button         matlab.ui.control.RadioButton
        tonic_descr                    matlab.ui.control.Label
        tonic_ampl                     matlab.ui.control.NumericEditField
        AmplitudeALabel                matlab.ui.control.Label
        IPintervalsSpinnerLabel_2      matlab.ui.control.Label
        ax_train                       matlab.ui.control.UIAxes
        ax_waveform                    matlab.ui.control.UIAxes
        MultiChannelStimulationTab     matlab.ui.container.Tab
        ax_multiCH                     matlab.ui.control.UIAxes
        StreamingTab                   matlab.ui.container.Tab
        StimLabel                      matlab.ui.control.Label
        StopStimButton                 matlab.ui.control.Button
        StartStimButton                matlab.ui.control.Button
        StreamButton                   matlab.ui.control.Button
        GainDropDown                   matlab.ui.control.DropDown
        GainDropDownLabel              matlab.ui.control.Label
        ObjectListBox                  matlab.ui.control.ListBox
        ChannelsPanel                  matlab.ui.container.Panel
        allChs                         matlab.ui.control.CheckBox
        Ch12CheckBox                   matlab.ui.control.CheckBox
        Ch11CheckBox                   matlab.ui.control.CheckBox
        Ch10CheckBox                   matlab.ui.control.CheckBox
        Ch9CheckBox                    matlab.ui.control.CheckBox
        Ch8CheckBox                    matlab.ui.control.CheckBox
        Ch7CheckBox                    matlab.ui.control.CheckBox
        Ch6CheckBox                    matlab.ui.control.CheckBox
        Ch5CheckBox                    matlab.ui.control.CheckBox
        Ch4CheckBox                    matlab.ui.control.CheckBox
        Ch3CheckBox                    matlab.ui.control.CheckBox
        Ch2CheckBox                    matlab.ui.control.CheckBox
        Ch1CheckBox                    matlab.ui.control.CheckBox
        ObjectshapeLabel               matlab.ui.control.Label
        stimAxes                       matlab.ui.control.UIAxes
        EMGaxes                        matlab.ui.control.UIAxes
        pressureAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)

        % Recording variables
        savePath = fileparts(mfilename('fullpath'));    % folder where to save recordings. By defaut it is current directory.
        fileNameBase = [];      % parameter for xippmex('trial') function: filename
        autoStopTime = 0;       % parameter for xippmex('trial') function: if set, recording automatically stops after auto_stop_time seconds.
        autoIncr = 0;           % parameter for xippmex('trial') function: boolean. If set to 1, it outputs filename

        % Sampling rates
        fsEMG = 2000            % EMG sampling rate [Hz]
        clockSummit = 3e4       % clock of Summit processor [Hz]

        % Stim variables
        safeLimit = 120*1e3;    % charge safety limit [pC]
        maxFreq = 1000;         % maximum allowed frequency [Hz]
        stimChsID               % IDs of micro+stim FE available channels
        activeStimCh            % ID of channel selected for stimulation
        stimEN = 0              % boolean for stim enabled

        % Variables for recorded signals (EMG and pressure)
        EMGchs = zeros(1,16)    % IDs of EMG channels in EMG front-end
        analogChs               % IDs of analog channels in I/O front-end

        % Streaming variables
        appTimer                % timer object used for streaming
        streaming = 0           % boolean for streaming status
        firstRun = 1            % boolean for I streaming run (used to clean the axes)
        plotUpdateRate = 4      % plot update rate [Hz] -> [10,15] Hz maximum

        anLpressure             % animated line for pressure stream
        anLEMG                  % animated line for EMG
        anLstimulation          % animated line for stim data

        t0                      % first timepoint of Summit at 30kHz clock
        prevTimestamp           % previous timestamp (used to append data streams) at 30kHz clock

        plotTimeSpan = 5        % time span for the plot [s]

        EMGchsChecks = zeros(1,12)   % booleans indicating if EMG chs are to be streamed (based on check box)
        EMGchsToStream          % EMG channels to stream (Summit IDs)
        EMGreadWind             % length of requested EMG data to Summit [s]
        EMGyTicks               % array of indices for yTicks of EMG streams
        computeEMGscale = 1     % boolean for the computation of EMG scaling factor (it is done only at I stream run)
        EMGscale                % scaling factor for EMG channels

        streamStim = 1          % boolean to stream stim (if stim data are not received for period = plotTimeSpan, stim is not streamed anymore)
        nChecksStimData = 0     % number of additional checks for Stim data stream:
        % this is because, timer checks if stim
        % buffer contains anything and plots it, only when
        % the flag 'active_stim_ch' is not 0. it is
        % set when a stimulation command is
        % sent, and unset when the stim buffer is
        % empty for 3 consecutive runs. it might
        % happen that buffer is empty at i-th check,
        % but still there's data at the subsequent
        % (i+1)-th check, that's the reason why this
        % variable exists
    end


    methods (Access = private)

        function stopTmr(app,src,~)
            % delete(src)
        end

        function confirmClose(app,~,event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                xippmex('close')
                pause(0.5)
                stop(app.appTimer);
                delete(timerfind);
                delete(app);
            end
        end

        % update file name
        function updateFileName(app)
            dateStr = string(datetime('now','Format','yyyy-MM-dd'));
            timeStr = string(datetime('now','Format','HH-mm-ss'));
            % compute recording number incrementally for today
            recNb = computeRecNb(app,dateStr);
            % create file name
            fileName = [dateStr '-' timeStr '-rec' num2str(recNb) '-S' num2str(app.SubjectDropDown.Value) '-' strrep(app.stimSetup.SelectedTab.Title,' ','_')];
            % update field with path + user-written text
            app.FileEditField.Value = strjoin([app.savePath '/' fileName],'');
        end

        % update title for stim axis
        function updateStimAxisTitle(app)
            % take stim parameters depending on the type of stim
            switch app.stimSetup.SelectedTab.Title
                case 'Single Pulse'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.single_pulse_ampl.Value) 'uA'];
                case 'Single Burst'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.single_burst_ampl.Value) 'uA - ', ...
                        num2str(app.single_burst_freq.Value) 'Hz - ', ...
                        num2str(app.single_burst_dur.Value) 's'];
                case 'RC'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.RC_min_ampl.Value) ':', ...
                        num2str(app.RC_amp_step.Value), ':' ...
                        num2str(app.RC_max_ampl.Value) 'uA'];
                case 'AM Bursts'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.AM_burst_min_ampl.Value) ':', ...
                        num2str(app.AM_burst_amp_step.Value), ':' ...
                        num2str(app.AM_burst_max_ampl.Value) 'uA - ', ...
                        num2str(app.AM_burst_intra_burst_freq.Value) 'Hz'];
                case 'FM Bursts'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.FM_burst_ampl.Value) 'uA - ', ...
                        num2str(app.FM_burst_min_freq.Value) ':', ...
                        num2str(app.FM_burst_freq_step.Value), ':' ...
                        num2str(app.FM_burst_max_freq.Value) 'Hz'];
                case 'Tonic'
                    paramString = ['ch', app.StimChannelDropDown.Value, ' - ', ...
                        num2str(app.tonic_ampl.Value) 'uA - ', ...
                        num2str(app.tonic_freq.Value) 'Hz - '];
                    if app.tonic_train_dur_button.Value
                        paramString = [paramString num2str(app.tonic_train_dur.Value) 's'];
                    else
                        paramString = [paramString 'continuous'];
                    end
            end
            % create the string and update the axis title
            txt = [app.stimSetup.SelectedTab.Title ' - ' paramString];
            title(app.stimAxes,txt);
        end

        function updatePressurePlot(app,h)
            % get digital events
            [~, timestamps, events] = xippmex('digin'); % timestamps based on 30 kHz clock ticks
            if ~isempty(events)app.firstRun
                s = double(extractfield(events,'parallel'));
                x = (timestamps-app.t0)/3e4;    % (s)
                if x(end)<=app.plotTimeSpan
                    xlim(app.pressureAxes,[0 app.plotTimeSpan]);
                    addpoints(h,x,s);
                else
                    if x(1)<app.plotTimeSpan
                        idx = find(x==app.plotTimeSpan);
                        addpoints(h,x(1:idx),s(1:idx));
                        addpoints(h,x(idx+1:end),s(idx+1:end));
                    else
                        x_range(2) = x(end);
                        x_range(1) = x_range(2)-app.plotTimeSpan;
                        xlim(app.pressureAxes,x_range)
                        addpoints(h,x,s);
                    end
                end
            end
        end

        function updateEMGplot(app,aL)

            % request EMG data to the Summit
            [EMG, timestamp] = xippmex('cont',app.EMGchsToStream,app.EMGreadWind*1e3,'hi-res');
            timestamp = double(timestamp);
            % compute EMG scale if it is first run
            if app.computeEMGscale
                app.EMGscale = max(EMG,[],2) * 10; % max EMG in the I read window multiplied per 10
            end
            % scale EMG for display purpose
            EMG = EMG./app.EMGscale;
            % save timepoint of last read sample (to start from that next cycle)
            app.prevTimestamp = timestamp + app.EMGreadWind*app.clockSummit; % timestamp is sampled at a freq = summit clock
            % da risolvere il fatto che possano sovrapporsi dei samples..

            % plot data batch
            xStep = app.clockSummit/app.fsEMG;
            x = timestamp:xStep:(timestamp+app.EMGreadWind*app.clockSummit-1);
            xShifted  = (x-app.t0)/app.clockSummit;
            if xShifted(end)<=app.plotTimeSpan
                xlim(app.EMGaxes,[0 app.plotTimeSpan]);
                for iCh = 1:size(EMG,1)
                    addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                end
            else
                if xShifted(1)<app.plotTimeSpan
                    idx = find(xShifted==app.plotTimeSpan);
                    for iCh = 1:size(EMG,1)
                        addpoints(aL{iCh},xShifted(1:idx),EMG(iCh,1:idx)+app.EMGyTicks(iCh));
                    end
                    for iCh = 1:size(EMG,1)
                        addpoints(aL{iCh},xShifted(idx+1:end),EMG(iCh,idx+1:end)+app.EMGyTicks(iCh));
                    end
                else
                    xRange(2) = xShifted(end);
                    xRange(1) = xRange(2)-app.plotTimeSpan;
                    xlim(app.EMGaxes,xRange)
                    try
                        for iCh = 1:size(EMG,1)
                            addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                        end
                    catch
                        for iCh = 1:size(EMG,1)-1
                            addpoints(aL{iCh},xShifted,EMG(iCh,:)+app.EMGyTicks(iCh));
                        end
                        disp(['Index exceeds the number of array elements.\n' ...
                            'This may happen when updating which EMG channel to ' ...
                            'stream, since checkbox may be ticked while timer is' ...
                            ' already updating EMG lines with previous set of channels!'])
                    end
                end
            end
        end

        function initializeFileEditField(app)
            app.FileEditField.Value = app.savePath;
        end

        % Compute recording number for filename
        function recNb = computeRecNb(app,dateStr)

            % Find all the files of today
            fileNameList = extractfield(dir(app.savePath),'name');
            filesToday = fileNameList(find(contains(fileNameList,dateStr)));

            % Find the recording numbers of today
            recsToday = [];
            for iT = 1:length(filesToday)
                recIdx = strfind(filesToday{iT},'rec');
                recsToday(iT) = str2double(regexp(filesToday{iT}(recIdx+3:recIdx+5),'\d*','Match'));
            end
            maxRecNb = max(recsToday);
            if isempty(maxRecNb)
                maxRecNb = 0;
            end

            % Compute recording number incrementally
            recNb = maxRecNb + 1;
        end

        % ....
        function updateEMGchsChecks(app)
            app.EMGchsChecks =  [app.Ch1CheckBox.Value app.Ch2CheckBox.Value ...
                app.Ch3CheckBox.Value app.Ch4CheckBox.Value ...
                app.Ch5CheckBox.Value app.Ch6CheckBox.Value ...
                app.Ch7CheckBox.Value app.Ch8CheckBox.Value ...
                app.Ch9CheckBox.Value app.Ch10CheckBox.Value ...
                app.Ch11CheckBox.Value app.Ch12CheckBox.Value];
        end

        % .....
        function add_remove_EMG_plot(app)
            % update list of selected electrode IDs
            app.EMGchsToStream = app.EMGchs(logical(app.EMGchsChecks));
            for ch = 1:length(app.anLEMG)
                clearpoints(app.anLEMG{ch})
            end
            % EMG
            for ch = 1:length(app.EMGchsToStream)
                app.anLEMG{ch} = animatedline(app.EMGaxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',app.fsEMG*(app.plotTimeSpan+1));
            end
            app.EMGaxes.XLim = [0 app.plotTimeSpan];
            app.EMGaxes.YLim = [-1 length(app.EMGchsToStream)+1];
            app.EMGyTicks = 1:length(app.EMGchsToStream);
            app.EMGaxes.YTick = app.EMGyTicks;
            app.EMGaxes.YTickLabel = num2cell(app.EMGchsToStream-(app.EMGchs(1)-1));    % levo la quantità elecs(1)-1 per far sì che si legga sempre
            % l'ID dell'elettrodo a partire da 1 (e non da 129 nel caso il
            % FE EMG sia connesso alla porta B)
        end

        % ......
        function updateStimPlot(app)

            % request stim data to the Summit
            [stimCount, stimTimestamps, ~] = xippmex('spike', app.activeStimCh, 1);
            % if stim data are received, plot them
            if stimCount
                app.nChecksStimData = 0;
                x = (stimTimestamps{1}-app.t0)/app.clockSummit; % timestamp is sampled at a freq = summit clock
                if x(end)<=app.plotTimeSpan
                    stem_h{1,1} = stem(app.stimAxes,repelem(x,2), repmat([-10 10],1,stimCount), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                else
                    if x(1)<app.plotTimeSpan   % questo caso si può forse evitare
                        idx = find(x==app.plotTimeSpan);
                        stem_h{2,1} = stem(app.stimAxes,repelem(x(1:idx),2), repmat([-10 10],1,idx), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                        stem_h{3,1} = stem(app.stimAxes,repelem(x(idx+1:end),2), repmat([-10 10],1,stimCount-idx), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                    else
                        stem_h{4,1} = stem(app.stimAxes,repelem(x,2), repmat([-10 10],1,stimCount), 'Color', 'k', 'MarkerFaceColor', 'none', 'MarkerEdgeColor', 'none', 'LineWidth', 1);
                    end
                end
                % if data are not received for some time (for example when
                % stim train is over), stim streaming is stopped to slim
                % the processing cost
            else
                app.nChecksStimData = app.nChecksStimData + 1;
                if app.nChecksStimData > ceil(app.plotTimeSpan*app.plotUpdateRate)
                    % put down streamStim flag
                    app.streamStim = 0;
                    % clear plot to save memory
                    cla(app.stimAxes)
                end
            end
        end
    end

    methods (Access = public)

        function timerCallback(app,~,~)
            if app.streaming
                % ------------- Pressure ----------------
                if ~isempty(app.analogChs)
                    try
                        updatePressurePlot(app,app.anLpressure);
                    catch
                        disp('Issue in streaming pressure data')
                    end
                end
                % ------------- EMG ---------------------
                if ~isempty(app.EMGchsToStream)
                    try
                        updateEMGplot(app,app.anLEMG);
                    catch
                        disp('Issue in streaming EMG data')
                    end
                end
                % ------------- Stimulation -------------
                if app.stimEN && app.streamStim
                    try
                        updateStimPlot(app);
                    catch
                        disp('Issue in streaming Stim data')
                    end
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % add path for auxiliary functions
            addpath(genpath(fileparts(mfilename('fullpath'))));
            % connect with Summit
            try
                xippmex;
                pause(1);
            catch
                waitfor(msgbox('Could not find Summit. Fix the problem and restart the app.'));
                delete(app);
                return
            end
            % find stim channels
            app.stimChsID = xippmex('elec','stim');
            if isempty(app.stimChsID)
                app.StartStimButton.Enable = 'off';
                app.StopStimButton.Enable = 'off';
            else
                app.StimLamp.Color = 'g';
            end
            % find EMG channels and apply hi-res filters
            app.EMGchs = xippmex('elec','EMG');
            if isempty(app.EMGchs)
                app.ChannelsPanel.Enable = 'off';
            else
                app.EMGLamp.Color = 'g';
                %[~,hires_filters] = xippmex('filter','list',1,'hires');
                %[~,hires_notch_filters] = xippmex('filter','list',1,'hires notch');
                for ich = 1:length(app.EMGchs)
                    xippmex('filter','set',app.EMGchs(ich),'hires notch',5); % notch at 50/100/150 Hz
                    xippmex('filter','set',app.EMGchs(ich),'hires',6); % band-pass 15-375 Hz
                end
            end
            % find analog channels
            app.analogChs = xippmex('elec','analog');
            if isempty(app.analogChs)
                app.ObjectListBox.Enable = 'off';
                app.GainDropDown.Enable = 'off';
            else
                app.AnalogIOLamp.Color = 'g';
            end
            % flush stim buffer by calling spike cmd
            xippmex('spike',app.stimChsID,1);
            % update file name with pre-set settings from drop down
            updateFileName(app);
            % check if there are other timers and delete them
            timers = timerfind;
            if ~isempty(timers)
                stop(timers);
                % delete(timers);     % l'ho scommentata il 12 aprile 2023:
                % prima di allora funzionava, quindi se hai problemi sui
                % timer, ricommentala
            end
            % define timer
            app.appTimer = timer;
            app.appTimer.Period = 1/app.plotUpdateRate;
            app.appTimer.ExecutionMode = 'fixedRate';
            % define timer callback functions
            app.appTimer.StartFcn = @(~,~)tic;
            app.appTimer.TimerFcn = @app.timerCallback;
            app.appTimer.StopFcn = @app.stopTmr;
            % start timer
            start(app.appTimer);
            %             dbstop if caught error

            % initialize single-channel stimulation axes
            % plots a pulse waveform which is updated every time a field is
            % changed
            %show_biphasic_pulse(app.ax_waveform,1,app.single_pulse_PW.Value,app.single_pulse_PW.Value,app.single_pulse_ampl.Value,app.single_pulse_ampl.Value);

            % fill StimElectrodesDropDown with available stim channels
            app.StimChannelDropDown.Items = string(num2cell(string(app.stimChsID)));
            % get selected electrode ID
            app.activeStimCh = app.stimChsID(str2double(app.StimChannelDropDown.Value));
            % activate stim data stream
            xippmex('signal',app.stimChsID,'stim',ones(1,length(app.stimChsID)));
            % set stim axis title
            if ~isempty(app.stimChsID)
                updateStimAxisTitle(app);
            else
                title(app.stimAxes, 'No stim channels available');
            end
            % constrain xlim for Streaming Tab axes and link axes
            app.pressureAxes.XLim = [0 app.plotTimeSpan];
            app.EMGaxes.XLim = [0 app.plotTimeSpan];
            app.stimAxes.XLim = [0 app.plotTimeSpan];
            linkaxes([app.pressureAxes, app.EMGaxes, app.stimAxes],'x');
            % set length of EMG data batch to ask Summit based on plotUpdateRate
            app.EMGreadWind = 1/app.plotUpdateRate;
            % set ylim for EMG axes (will have to update them runtime)
            app.EMGaxes.YLim = [0 1];
            % set ylim for Stim_Axes
            app.stimAxes.YLim = [-30,30];
            hold(app.stimAxes,'on');

        end

        % Close request function: InterfaceUIFigure
        function InterfaceUIFigureCloseRequest(app, event)
            % Display confirmation dialog box
            msg = 'Are you sure you want to close?';
            uiconfirm(app.InterfaceUIFigure,msg,'Confirm Close','CloseFcn',@app.confirmClose);
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            newPath = uigetdir();
            if newPath
                app.savePath = newPath;
                updateFileName(app);
            end
        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            updateFileName(app);
        end

        % Value changed function: FileEditField
        function FileEditFieldValueChanged(app, event)
            txt1 = regexp(app.FileEditField.Value,'/','split');
            txt1 = txt1{end};
            computeRecNb(app,app.savePath,txt1);
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Current status: RECORDING
            if strcmp(trial_descriptor.status,'recording')

                msgbox('Already recording. Please stop the current recording and start a new one.')
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Already in RECORDING status';

                % Current status: STOPPED
            elseif strcmp(trial_descriptor.status,'stopped')

                % Update filename to prevent file replacement
                updateFileName(app);
                app.fileNameBase = app.FileEditField.Value;
                % Start recording
                xippmex('trial','recording',app.fileNameBase,app.autoStopTime,app.autoIncr);
                % Update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Recording...';

                % Current status: PAUSED -> Resume recording
            elseif strcmp(trial_descriptor.status,'paused')

                % Resume recording
                xippmex('trial','recording',app.fileNameBase,app.autoStopTime,app.autoIncr);
                % Update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'off';
                app.PauseRecordingButton.Enable = 'on';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = sprintf('Recording correctly resumed after pause...');
            end
        end

        % Button pushed function: PauseRecordingButton
        function PauseRecordingButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Current status: RECORDING
            if  strcmp(trial_descriptor.status,'recording')

                % pause recording
                xippmex('trial','paused');
                % update buttons and message on the GUI
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = sprintf(['Recording paused successfully.\n' ...
                    'Resume whenever want']);

                % Current status: already PAUSED
            elseif strcmp(trial_descriptor.status,'paused')

                msgbox('Recording was already paused.')
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'on';
                app.FilesaveMsg.Value = 'Recording was already paused.';

                % Current status: STOPPED
            elseif strcmp(trial_descriptor.status,'stopped')

                msgbox('Recording is off.')
                app.StartRecordingButton.Enable = 'on';
                app.PauseRecordingButton.Enable = 'off';
                app.StopRecordingButton.Enable = 'off';
                app.FilesaveMsg.Value = 'Recording is off.';

            end
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonButtonPushed(app, event)

            % Check current xippmex status: possibly repeat since it is possible it just returns a
            % logical value instead of the struct with fields included "status"
            trial_descriptor = xippmex('trial');
            while ~isstruct(trial_descriptor)
                trial_descriptor = xippmex('trial');
            end

            % Stop recording if xippmex was actually recording
            if  strcmp(trial_descriptor.status,'recording') || strcmp(trial_descriptor.status,'paused')

                % stop recording
                xippmex('trial','stopped');
                % update filename to prevent file replacement
                updateFileName(app);
                % update message on the GUI
                app.FilesaveMsg.Value = 'Recording stopped successfully.';
                % reset saving settings
                app.fileNameBase = [];
                app.autoStopTime = 0;

                % Otherwise just send a message
            elseif strcmp(trial_descriptor.status,'stopped')

                msgbox('Xippmex was not recording.')
                app.FilesaveMsg.Value = 'Xippmex was not recording.';

            end

            % In any case update buttons on the GUI
            app.StartRecordingButton.Enable = 'on';
            app.PauseRecordingButton.Enable = 'off';
            app.StopRecordingButton.Enable = 'off';

        end

        % Value changed function: StimChannelDropDown
        function StimChannelDropDownValueChanged(app, event)
            % update stim ch based on selected electrode
            app.activeStimCh = app.stimChsID(str2double(app.StimChannelDropDown.Value));
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
        end

        % Selection change function: stimSetup
        function stimSetupSelectionChanged(app, event)
            % update file name
            updateFileName(app);
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
        end

        % Value changed function: Ch1CheckBox
        function Ch1CheckBoxValueChanged(app, event)
            if app.Ch1CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(1)      % previously 0
                    app.EMGchsChecks(1) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(1)      % previously 1
                    app.EMGchsChecks(1) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch2CheckBox
        function Ch2CheckBoxValueChanged(app, event)
            if app.Ch2CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(2)      % previously 0
                    app.EMGchsChecks(2) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(2)      % previously 1
                    app.EMGchsChecks(2) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch3CheckBox
        function Ch3CheckBoxValueChanged(app, event)
            if app.Ch3CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(3)      % previously 0
                    app.EMGchsChecks(3) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(3)      % previously 1
                    app.EMGchsChecks(3) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch4CheckBox
        function Ch4CheckBoxValueChanged(app, event)
            if app.Ch4CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(4)      % previously 0
                    app.EMGchsChecks(4) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(4)      % previously 1
                    app.EMGchsChecks(4) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch5CheckBox
        function Ch5CheckBoxValueChanged(app, event)
            if app.Ch5CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(5)      % previously 0
                    app.EMGchsChecks(5) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(5)      % previously 1
                    app.EMGchsChecks(5) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch6CheckBox
        function Ch6CheckBoxValueChanged(app, event)
            if app.Ch6CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(6)      % previously 0
                    app.EMGchsChecks(6) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(6)      % previously 1
                    app.EMGchsChecks(6) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch7CheckBox
        function Ch7CheckBoxValueChanged(app, event)
            if app.Ch7CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(7)      % previously 0
                    app.EMGchsChecks(7) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(7)      % previously 1
                    app.EMGchsChecks(7) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch8CheckBox
        function Ch8CheckBoxValueChanged(app, event)
            if app.Ch8CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(8)      % previously 0
                    app.EMGchsChecks(8) = 1;
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(8)      % previously 1
                    app.EMGchsChecks(8) = 0;
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch9CheckBox
        function Ch9CheckBoxValueChanged(app, event)
            if app.Ch9CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(9)      % previously 0
                    app.EMGchsChecks(9) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(9)      % previously 1
                    app.EMGchsChecks(9) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch10CheckBox
        function Ch10CheckBoxValueChanged(app, event)
            if app.Ch10CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(10)      % previously 0
                    app.EMGchsChecks(10) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(10)      % previously 1
                    app.EMGchsChecks(10) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch11CheckBox
        function Ch11CheckBoxValueChanged(app, event)
            if app.Ch11CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(11)      % previously 0
                    app.EMGchsChecks(11) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(11)      % previously 1
                    app.EMGchsChecks(11) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: Ch12CheckBox
        function Ch12CheckBoxValueChanged(app, event)
            if app.Ch12CheckBox.Value    % set at 1
                if ~app.EMGchsChecks(12)      % previously 0
                    app.EMGchsChecks(12) = 1;
                    % add this channel to visualization
                    add_remove_EMG_plot(app)
                end
            else                        % unset (0)
                if app.EMGchsChecks(12)      % previously 1
                    app.EMGchsChecks(12) = 0;
                    % remove channel from visualization
                    add_remove_EMG_plot(app)
                end
            end
        end

        % Value changed function: allChs
        function allChsValueChanged(app, event)
            if app.allChs.Value
                app.EMGchsChecks(:) = 1;
                app.Ch1CheckBox.Value = 1;
                app.Ch2CheckBox.Value = 1;
                app.Ch3CheckBox.Value = 1;
                app.Ch4CheckBox.Value = 1;
                app.Ch5CheckBox.Value = 1;
                app.Ch6CheckBox.Value = 1;
                app.Ch7CheckBox.Value = 1;
                app.Ch8CheckBox.Value = 1;
                app.Ch9CheckBox.Value = 1;
                app.Ch10CheckBox.Value = 1;
                app.Ch11CheckBox.Value = 1;
                app.Ch12CheckBox.Value = 1;
            else
                app.EMGchsChecks(:) = 0;
                app.Ch1CheckBox.Value = 0;
                app.Ch2CheckBox.Value = 0;
                app.Ch3CheckBox.Value = 0;
                app.Ch4CheckBox.Value = 0;
                app.Ch5CheckBox.Value = 0;
                app.Ch6CheckBox.Value = 0;
                app.Ch7CheckBox.Value = 0;
                app.Ch8CheckBox.Value = 0;
                app.Ch9CheckBox.Value = 0;
                app.Ch10CheckBox.Value = 0;
                app.Ch11CheckBox.Value = 0;
                app.Ch12CheckBox.Value = 0;
            end
            add_remove_EMG_plot(app);
        end

        % Value changed function: ObjectListBox
        function ObjectListBoxValueChanged(app, event)
            updateFileName(app);
        end

        % Value changed function: single_pulse_ampl
        function single_pulse_amplValueChanged(app, event)
            % check for safet limits
            if app.single_pulse_ampl.Value*app.single_pulse_PW.Value > app.safeLimit
                app.single_pulse_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);

            %cla(app.ax_waveform)
            %show_biphasic_pulse(app.ax_waveform,1,round(app.single_pulse_PW.Value),round(app.single_pulse_PW.Value),round(app.single_pulse_ampl.Value),round(app.single_pulse_ampl.Value));
        end

        % Value changed function: single_pulse_PW
        function single_pulse_PWValueChanged(app, event)
            % check for satey limits
            if app.single_pulse_ampl.Value*app.single_pulse_PW.Value > app.safeLimit
                app.single_pulse_ampl.PW = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);

            %cla(app.ax_waveform)
            %show_biphasic_pulse(app.ax_waveform,1,round(app.single_pulse_PW.Value),round(app.single_pulse_PW.Value),round(app.single_pulse_ampl.Value),round(app.single_pulse_ampl.Value));
        end

        % Value changed function: single_burst_ampl
        function single_burst_amplValueChanged(app, event)
            % check safety limits
            if app.single_burst_ampl.Value*app.single_burst_PW.Value > app.safeLimit
                app.single_burst_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.single_burst_PW.Value),round(app.single_burst_PW.Value),round(app.single_burst_freq.Value),round(app.single_burst_dur.Value*1e3),round(app.single_burst_offset.Value*1e3));
        end

        % Value changed function: single_burst_freq
        function single_burst_freqValueChanged(app, event)
            % Frequency exceeds limit
            if app.single_burst_freq.Value > app.maxFreq
                app.single_burst_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.single_burst_PW.Value),round(app.single_burst_PW.Value),round(app.single_burst_freq.Value),round(app.single_burst_dur.Value*1e3),round(app.single_burst_offset.Value*1e3));
        end

        % Value changed function: single_burst_dur
        function single_burst_durValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.single_burst_PW.Value),round(app.single_burst_PW.Value),round(app.single_burst_freq.Value),round(app.single_burst_dur.Value*1e3),round(app.single_burst_offset.Value*1e3));
        end

        % Value changed function: single_burst_PW
        function single_burst_PWValueChanged(app, event)
            % check safety limits
            if app.single_burst_ampl.Value*app.single_burst_PW.Value > app.safeLimit
                app.single_burst_ampl.PW = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.single_burst_PW.Value),round(app.single_burst_PW.Value),round(app.single_burst_freq.Value),round(app.single_burst_dur.Value*1e3),round(app.single_burst_offset.Value*1e3));
        end

        % Value changed function: RC_min_ampl
        function RC_min_amplValueChanged(app, event)
            % check safety limits
            if app.RC_min_ampl.Value*app.RC_PW.Value > app.safeLimit
                app.RC_min_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.RC_min_ampl.Value > app.RC_max_ampl.Value
                app.RC_min_ampl.Value = app.RC_max_ampl.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: RC_max_ampl
        function RC_max_amplValueChanged(app, event)
            % check safety limits
            if app.RC_max_ampl.Value*app.RC_PW.Value > app.safeLimit
                app.RC_max_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.RC_max_ampl.Value < app.RC_min_ampl.Value
                app.RC_max_ampl.Value = app.RC_min_ampl.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: RC_amp_step
        function RC_amp_stepValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: RC_reps
        function RC_repsValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: RC_PW
        function RC_PWValueChanged(app, event)
            % check safety limits
            if app.RC_min_ampl.Value*app.RC_PW.Value > app.safeLimit | app.RC_max_ampl.Value*app.RC_PW.Value > app.safeLimit
                app.RC_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: RC_freq
        function RC_freqValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %recruitment_curves_display_v0(app.ax_train,app.RC_min_ampl.Value,app.RC_max_ampl.Value,app.RC_amp_step.Value,app.RC_freq.Value,app.RC_reps.Value);
        end

        % Value changed function: AM_burst_intra_burst_freq
        function AM_burst_intra_burst_freqValueChanged(app, event)
            % Frequency exceeds limit
            if app.AM_burst_intra_burst_freq.Value > app.maxFreq
                app.AM_burst_intra_burst_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_inter_burst_interval
        function AM_burst_inter_burst_intervalValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_dur
        function AM_burst_durValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_min_ampl
        function AM_burst_min_amplValueChanged(app, event)
            % ABOVE SAFETY LIMITS
            if app.AM_burst_min_ampl.Value*app.AM_burst_PW.Value > app.safeLimit
                app.AM_burst_min_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Min amp > max amp
            if app.AM_burst_min_ampl.Value > app.AM_burst_max_ampl.Value
                app.AM_burst_min_ampl.Value = app.AM_burst_max_ampl.Value;
                warndlg('Min amp is higher than max amp!','Parameter Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_max_ampl
        function AM_burst_max_amplValueChanged(app, event)
            % check safety limits
            if app.AM_burst_max_ampl.Value*app.AM_burst_PW.Value > app.safeLimit
                app.AM_burst_max_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % Max amp < min amp
            if app.AM_burst_max_ampl.Value < app.AM_burst_min_ampl.Value
                app.AM_burst_max_ampl.Value = app.AM_burst_min_ampl.Value;
                warndlg('Max amp is lower than min amp!','Parameter Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_amp_step
        function AM_burst_amp_stepValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_reps
        function AM_burst_repsValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: AM_burst_PW
        function AM_burst_PWValueChanged(app, event)
            % check safety limits
            if app.AM_burst_min_ampl.Value*app.AM_burst_PW.Value > app.safeLimit | app.AM_burst_max_ampl.Value*app.AM_burst_PW.Value > app.safeLimit
                app.AM_burst_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %AM_bursts_display_v0(app.ax_train,app.AM_burst_PW.Value,app.AM_burst_min_ampl.Value,app.AM_burst_max_ampl.Value,app.AM_burst_amp_step.Value,app.AM_burst_intra_burst_int.Value*1e3,app.AM_burst_inter_burst_int.Value*1e3,app.AM_burst_dur.Value*1e3,app.AM_burst_reps.Value,app.AM_burst_offset.Value);
        end

        % Value changed function: FM_burst_ampl
        function FM_burst_amplValueChanged(app, event)
            % check safety limits
            if app.FM_burst_ampl.Value*app.FM_burst_PW.Value > app.safeLimit
                app.FM_burst_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_inter_burst_int
        function FM_burst_inter_burst_intValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_dur
        function FM_burst_durValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_min_freq
        function FM_burst_min_freqValueChanged(app, event)
            % Min freq > max freq
            if app.FM_burst_min_freq.Value > app.FM_burst_max_freq.Value
                app.FM_burst_min_freq.Value = app.FM_burst_max_freq.Value;
                warndlg('Min freq is higher than max freq!','Parameter Warning')
            end
            % Frequency exceeds limit
            if app.FM_burst_min_freq.Value > app.maxFreq
                app.FM_burst_min_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_max_freq
        function FM_burst_max_freqValueChanged(app, event)
            % Max freq < min freq
            if app.FM_burst_max_freq.Value < app.FM_burst_min_freq.Value
                app.FM_burst_max_freq.Value = app.FM_burst_min_freq.Value;
                warndlg('Max freq is lower than min freq!','Parameter Warning')
            end
            % Frequency exceeds limit
            if app.FM_burst_max_freq.Value > app.maxFreq
                app.FM_burst_max_freq.Value = app.maxFreq;
                warndlg('Frequency exceeds limit!','Parameter warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_freq_step
        function FM_burst_freq_stepValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_reps
        function FM_burst_repsValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: FM_burst_PW
        function FM_burst_PWValueChanged(app, event)
            % check safety limits
            if app.FM_burst_ampl.Value*app.FM_burst_PW.Value > app.safeLimit
                app.FM_burst_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %FM_bursts_display_v0(app.ax_train,app.FM_burst_PW.Value,app.FM_burst_ampl.Value,app.FM_burst_min_freq.Value,app.FM_burst_max_freq.Value,app.FM_burst_freq_step.Value,app.FM_burst_inter_burst_int.Value*1e3,app.FM_burst_dur.Value*1e3,app.FM_burst_reps.Value,app.FM_burst_offset.Value);
        end

        % Value changed function: tonic_ampl
        function tonic_amplValueChanged(app, event)
            % check safety limits
            if app.tonic_ampl.Value*app.tonic_PW.Value > app.safeLimit
                app.tonic_ampl.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Amplitude has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.tonic_PW.Value),round(app.tonic_PW.Value),round(app.tonic_freq.Value),round(app.tonic_train_dur.Value*1e3),round(app.tonic_offset.Value*1e3));
        end

        % Value changed function: tonic_freq
        function tonic_freqValueChanged(app, event)
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.tonic_PW.Value),round(app.tonic_PW.Value),round(app.tonic_freq.Value),round(app.tonic_train_dur.Value*1e3),round(app.tonic_offset.Value*1e3));
        end

        % Value changed function: tonic_PW
        function tonic_PWValueChanged(app, event)
            % check safety limits
            if app.tonic_ampl.Value*app.tonic_PW.Value > app.safeLimit
                app.tonic_PW.Value = 0;
                warndlg('STIM EXCEEDS SAFETY LIMITS! Pulse-width has been set to zero.','Stim Warning')
            end
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.tonic_PW.Value),round(app.tonic_PW.Value),round(app.tonic_freq.Value),round(app.tonic_train_dur.Value*1e3),round(app.tonic_offset.Value*1e3));
        end

        % Value changed function: tonic_train_dur
        function tonic_train_durValueChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);
            %cla(app.ax_train);
            %pulse_train_display_v1(app.ax_train,round(app.tonic_PW.Value),round(app.tonic_PW.Value),round(app.tonic_freq.Value),round(app.tonic_train_dur.Value*1e3),round(app.tonic_offset.Value*1e3));
        end

        % Button pushed function: StreamButton
        function Stream(app, event)

            app.streaming = ~app.streaming;

            % Streaming = 1
            if app.streaming
                % set firstRun to 0 and check if it changes for further runs
                if app.firstRun
                    app.firstRun = ~app.firstRun;
                else
                    app.computeEMGscale = 0;
                    % clear points in streaming axes
                    clearpoints(app.anLpressure);
                    for ch = 1:length(app.anLEMG)
                        clearpoints(app.anLEMG{ch});
                    end
                    try
                        clearpoints(app.anLstimulation); % sometimes it deletes the stim animated line and I don't know why
                    catch
                    end
                end
                % check if there are other timers and delete them
                timers = timerfind;
                if ~isempty(timers)
                    stop(timers);
                    delete(timers);
                end
                % define timer
                app.appTimer = timer;
                app.appTimer.Period = 1/app.plotUpdateRate;
                app.appTimer.ExecutionMode = 'fixedRate';
                app.appTimer.BusyMode = 'drop';
                % take selected EMG channels
                updateEMGchsChecks(app);
                app.EMGchsToStream = app.EMGchs(logical(app.EMGchsChecks));
                % initialize plots
                % pressure
                app.anLpressure = animatedline(app.pressureAxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',100*app.plotTimeSpan);
                app.pressureAxes.XLim = [0 app.plotTimeSpan];
                % EMG
                for ch = 1:length(app.EMGchsToStream)
                    app.anLEMG{ch} = animatedline(app.EMGaxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',app.fsEMG*(app.plotTimeSpan+1)); % EL: why +1?
                end
                app.EMGaxes.XLim = [0 app.plotTimeSpan];
                app.EMGaxes.YLim = [-1 length(app.EMGchsToStream)+1];
                % stimulation
                app.anLstimulation = animatedline(app.stimAxes,'LineStyle','-','LineWidth',0.5,'MaximumNumPoints',100*app.plotTimeSpan);
                app.stimAxes.XLim = [0 app.plotTimeSpan];
                xlabel(app.stimAxes, ['time [' num2str(app.plotTimeSpan) ' s]']);
                % define timer callback functions
                app.appTimer.StartFcn = @(~,~)tic;
                app.appTimer.TimerFcn = @app.timerCallback;
                app.appTimer.StopFcn = @app.stopTmr;
                % change button appearance
                app.StreamButton.Text = 'Stop';
                app.StreamButton.BackgroundColor = [1 0 0];
                % start timer t and take NIP clock time
                app.t0 = xippmex('time');
                app.prevTimestamp = app.t0;
                start(app.appTimer);
                % Enable StartStimButton and StopStimButton
                % this prevents stimulating while experimenter is not
                % streaming any data
                if ~isempty(app.stimChsID)
                    app.StartStimButton.Enable = 'on';
                    app.StopStimButton.Enable = 'on';
                end

                % ......
            elseif ~app.streaming && strcmp(app.appTimer.Running,'on')   % button pressed while executing ('stop')
                %                 stop(app.t);    % ASSICURATI che chiamando questa funzione
                % vada nella callback stopFcn, salvi (chiami xippmex) e si elimini.
                % altrimenti, mi conviene racchiudere salvataggio ed
                % eliminazione del timer in una funzione che chiamo quando
                % occorre. infatti, se non do TasksToExecute ma premo il
                % bottone, devo assicurarmi che timer vada nella callback
                % di terminazione.

                % delete(app.t);
                % disable button to prevent pressing it while deleting
                % timer
                app.StreamButton.Enable = 0;
                app.StreamButton.BackgroundColor = [0.96 0.96 0.96];
                pause(1)
                % restore start button
                app.StreamButton.BackgroundColor = [0 1 0];
                app.StreamButton.Text = 'Stream';
                app.StreamButton.Enable = 1;

                % stop stimulation
                StopStimButtonPushed(app,event);
                % Disable StartStimButton and StopStimButton
                % this prevents stimulating while experimenter is not
                % streaming any data
                app.StartStimButton.Enable = 'off';
                app.StopStimButton.Enable = 'off';
            end
        end

        % Button pushed function: StartStimButton
        function StartStimButtonPushed(app, event)
            % Enable stimulation
            xippmex('stim','enable',1);
            pause(0.5);
            app.stimEN = 1;
            % set flag for stim streaming to 1
            app.streamStim = 1;
            % flush stim buffer by calling spike cmd
            xippmex('spike',app.stimChsID,1);
            % Get active tab from Single-Channel Stimulation settings and
            % fetch current parameters and put them in struct to send to
            % the function that prepares the stimulation string
            switch app.stimSetup.SelectedTab.Title
                case 'Single Pulse'
                    mode = 1;
                    stim_params.('single_pulse_ampl') = app.single_pulse_ampl.Value;
                    stim_params.('single_pulse_PW') = app.single_pulse_PW.Value;
                case 'Single Burst'
                    mode = 2;
                    stim_params.('single_burst_ampl') = app.single_burst_ampl.Value;
                    stim_params.('single_burst_freq') = app.single_burst_freq.Value;
                    stim_params.('single_burst_dur') = app.single_burst_dur.Value;
                    stim_params.('single_burst_PW') = app.single_burst_PW.Value;
                case 'RC'
                    mode = 3;
                    stim_params.('RC_min_ampl') = app.RC_min_ampl.Value;
                    stim_params.('RC_max_ampl') = app.RC_max_ampl.Value;
                    stim_params.('RC_amp_step') = app.RC_amp_step.Value;
                    stim_params.('RC_reps') = app.RC_reps.Value;
                    stim_params.('RC_PW') = app.RC_PW.Value;
                    stim_params.('RC_freq') = app.RC_freq.Value;
                case 'AM Bursts'
                    mode = 4;
                    stim_params.('AM_burst_intra_burst_freq') = app.AM_burst_intra_burst_freq.Value;
                    stim_params.('AM_burst_PW') = app.AM_burst_PW.Value;
                    stim_params.('AM_burst_dur') = app.AM_burst_dur.Value;
                    stim_params.('AM_burst_inter_burst_int') = app.AM_burst_inter_burst_interval.Value;
                    stim_params.('AM_burst_min_ampl') = app.AM_burst_min_ampl.Value;
                    stim_params.('AM_burst_max_ampl') = app.AM_burst_max_ampl.Value;
                    stim_params.('AM_burst_amp_step') = app.AM_burst_amp_step.Value;
                    stim_params.('AM_burst_reps') = app.AM_burst_reps.Value;
                case 'FM Bursts'
                    mode = 5;
                    stim_params.('FM_burst_ampl') = app.FM_burst_ampl.Value;
                    stim_params.('FM_burst_PW') = app.FM_burst_PW.Value;
                    stim_params.('FM_burst_dur') = app.FM_burst_dur.Value;
                    stim_params.('FM_burst_inter_burst_int') = app.FM_burst_inter_burst_int.Value;
                    stim_params.('FM_burst_min_freq') = app.FM_burst_min_freq.Value;
                    stim_params.('FM_burst_max_freq') = app.FM_burst_max_freq.Value;
                    stim_params.('FM_burst_freq_step') = app.FM_burst_freq_step.Value;
                    stim_params.('FM_burst_reps') = app.FM_burst_reps.Value;
                case 'Tonic'
                    mode = 6;
                    stim_params.('tonic_ampl') = app.tonic_ampl.Value;
                    stim_params.('tonic_PW') = app.tonic_PW.Value;
                    stim_params.('tonic_freq') = app.tonic_freq.Value;
                    if app.tonic_train_dur_button.Value
                        stim_params.('tonic_train_dur') = app.tonic_train_dur.Value;
                    else
                        stim_params.('tonic_train_dur') = 3.6*1e3; % 3600 s = 1 h
                    end
            end
            % make stimulation string
            [stim_string,safe,cmdDelay] = make_stim_string_vMultiCmd(mode,stim_params,app.activeStimCh,app.safeLimit);
            % Stimulate if below safety limits!
            if safe
                app.StimLabel.Text = 'Stim: ON';
                app.StimLabel.FontColor = 'r';
                for icmd = 1:length(stim_string)
                    if app.stimEN % otherwise it continues sending the stim commands even after pressing the stop stim button
                        xippmex('stim',stim_string{icmd});
                        pause(cmdDelay);
                    end
                end
            else
                StopStimButtonPushed(app,event);
                warndlg('STIM EXCEEDS SAFETY LIMITS! Stim has been disabled.','Stim Warning')
            end
        end

        % Button pushed function: StopStimButton
        function StopStimButtonPushed(app, event)
            % Disable stimulation
            xippmex('stim', 'enable', 0);
            pause(0.5);
            app.stimEN = 0;
            % Write text on the GUI
            app.StimLabel.Text = 'Stim: OFF';
            app.StimLabel.FontColor = 'k';
        end

        % Selection changed function: tonic_TC
        function tonic_TCSelectionChanged(app, event)
            % update title for stim axis with selected stimulation protocol
            updateStimAxisTitle(app);       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.InterfaceUIFigure = uifigure('Visible', 'off');
            app.InterfaceUIFigure.Name = 'Interface';
            app.InterfaceUIFigure.CloseRequestFcn = createCallbackFcn(app, @InterfaceUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.InterfaceUIFigure);
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.Panel = uipanel(app.SaveTab);
            app.FileEditFieldLabel = uilabel(app.Panel);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.Panel, 'text');
            app.FileEditField.ValueChangedFcn = createCallbackFcn(app, @FileEditFieldValueChanged, true);
            app.FileEditField.Editable = 'off';
            app.SelectfolderButton = uibutton(app.Panel, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.SubjectDropDownLabel = uilabel(app.Panel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.Panel);
            app.SubjectDropDown.Items = {'1', '2'};
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.SubjectDropDown.Value = '1';
            app.StartRecordingButton = uibutton(app.SaveTab, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonButtonPushed, true);
            app.StartRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'start.jpeg');
            app.StartRecordingButton.Text = '';
            app.PauseRecordingButton = uibutton(app.SaveTab, 'push');
            app.PauseRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @PauseRecordingButtonPushed, true);
            app.PauseRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'pause.jpeg');
            app.PauseRecordingButton.Enable = 'off';
            app.PauseRecordingButton.Text = '';
            app.StopRecordingButton = uibutton(app.SaveTab, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonButtonPushed, true);
            app.StopRecordingButton.Icon = fullfile(pathToMLAPP, 'icons', 'stop.jpeg');
            app.StopRecordingButton.Enable = 'off';
            app.StopRecordingButton.Text = '';
            app.RecordingstatusPanel = uipanel(app.SaveTab);
            app.RecordingstatusPanel.Title = 'Recording status';
            app.FilesaveMsg = uitextarea(app.RecordingstatusPanel);
            app.FilesaveMsg.Editable = 'off';
            app.FilesaveMsg.Value = {'Recording is OFF.'};
            app.RecordingLabel = uilabel(app.SaveTab);
            app.RecordingLabel.Text = 'Recording';
            app.StimLampLabel = uilabel(app.SaveTab);
            app.StimLampLabel.Text = 'Stim';
            app.StimLamp = uilamp(app.SaveTab);
            app.EMGLampLabel = uilabel(app.SaveTab);
            app.EMGLampLabel.Text = 'EMG';
            app.EMGLamp = uilamp(app.SaveTab);
            app.AnalogIOLampLabel = uilabel(app.SaveTab);
            app.AnalogIOLampLabel.Text = 'Analog I/O';
            app.AnalogIOLamp = uilamp(app.SaveTab);
            app.DetectedFEsLabel = uilabel(app.SaveTab);
            app.DetectedFEsLabel.Text = 'Detected FEs';
            app.SingleChannelStimulationTab = uitab(app.TabGroup);
            app.SingleChannelStimulationTab.Title = 'Single-Channel Stimulation';
            app.ax_waveform = uiaxes(app.SingleChannelStimulationTab);
            title(app.ax_waveform, 'Waveform')
            zlabel(app.ax_waveform, 'Z')
            app.ax_waveform.XTick = [];
            app.ax_waveform.YTick = [];
            app.ax_train = uiaxes(app.SingleChannelStimulationTab);
            title(app.ax_train, 'Train')
            zlabel(app.ax_train, 'Z')
            app.ax_train.XTick = [];
            app.ax_train.YTick = [];
            app.IPintervalsSpinnerLabel_2 = uilabel(app.SingleChannelStimulationTab);
            app.IPintervalsSpinnerLabel_2.Text = '';
            app.stimSetup = uitabgroup(app.SingleChannelStimulationTab);
            app.stimSetup.SelectionChangedFcn = createCallbackFcn(app, @stimSetupSelectionChanged, true);
            app.SinglePulseTab = uitab(app.stimSetup);
            app.SinglePulseTab.Title = 'Single Pulse';
            app.single_pulse_descr = uilabel(app.SinglePulseTab);
            app.single_pulse_descr.Text = 'Deliver single pulse.';
            app.DurationsLabel = uilabel(app.SinglePulseTab);
            app.DurationsLabel.Text = 'PulseWidth [µs]';
            app.single_pulse_PW = uieditfield(app.SinglePulseTab, 'numeric');
            app.single_pulse_PW.Limits = [0 100];
            app.single_pulse_PW.ValueChangedFcn = createCallbackFcn(app, @single_pulse_PWValueChanged, true);
            app.single_pulse_PW.Value = 50;
            app.icon_pulse = uiimage(app.SinglePulseTab);
            app.icon_pulse.ImageSource = fullfile(pathToMLAPP, 'icons', 'pulse.png');
            app.AmplitudeAEditFieldLabel = uilabel(app.SinglePulseTab);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude [µA]';
            app.single_pulse_ampl = uieditfield(app.SinglePulseTab, 'numeric');
            app.single_pulse_ampl.Limits = [0 1000];
            app.single_pulse_ampl.ValueChangedFcn = createCallbackFcn(app, @single_pulse_amplValueChanged, true);
            app.single_pulse_ampl.Value = 100;
            app.SingleBurstTab = uitab(app.stimSetup);
            app.SingleBurstTab.Title = 'Single Burst';
            app.single_burst_descr = uilabel(app.SingleBurstTab);
            app.single_burst_descr.Text = {'Deliver single burst at specified frequency and amplitude, for the'; 'desired duration.'};
            app.icon_single_burst = uiimage(app.SingleBurstTab);
            app.icon_single_burst.ImageSource = fullfile(pathToMLAPP, 'icons', 'burst.png');
            app.DurationsLabel_2 = uilabel(app.SingleBurstTab);
            app.DurationsLabel_2.Text = 'PulseWidth [µs]';
            app.single_burst_PW = uieditfield(app.SingleBurstTab, 'numeric');
            app.single_burst_PW.Limits = [0 100];
            app.single_burst_PW.ValueChangedFcn = createCallbackFcn(app, @single_burst_PWValueChanged, true);
            app.single_burst_PW.Value = 50;
            app.AmplitudeAEditField_2Label = uilabel(app.SingleBurstTab);
            app.AmplitudeAEditField_2Label.Text = 'Amplitude [µA]';
            app.single_burst_ampl = uieditfield(app.SingleBurstTab, 'numeric');
            app.single_burst_ampl.Limits = [0 1000];
            app.single_burst_ampl.ValueChangedFcn = createCallbackFcn(app, @single_burst_amplValueChanged, true);
            app.single_burst_ampl.Value = 100;
            app.FrequencyHzEditFieldLabel_3 = uilabel(app.SingleBurstTab);
            app.FrequencyHzEditFieldLabel_3.Text = 'Frequency [Hz]';
            app.single_burst_freq = uieditfield(app.SingleBurstTab, 'numeric');
            app.single_burst_freq.Limits = [0 1000];
            app.single_burst_freq.ValueChangedFcn = createCallbackFcn(app, @single_burst_freqValueChanged, true);
            app.single_burst_freq.Value = 50;
            app.DurationmsLabel = uilabel(app.SingleBurstTab);
            app.DurationmsLabel.Text = 'Duration [s]';
            app.single_burst_dur = uieditfield(app.SingleBurstTab, 'numeric');
            app.single_burst_dur.Limits = [0 Inf];
            app.single_burst_dur.ValueChangedFcn = createCallbackFcn(app, @single_burst_durValueChanged, true);
            app.single_burst_dur.Value = 1;
            app.RCTab = uitab(app.stimSetup);
            app.RCTab.Title = 'RC';
            app.icon_RC = uiimage(app.RCTab);
            app.icon_RC.ImageSource = fullfile(pathToMLAPP, 'icons', 'RC.png');
            app.RC_descr = uilabel(app.RCTab);
            app.RC_descr.Text = {'Pulses with amplitude ranging from specified minimum to'; 'maximum values. '; 'Amplitude is kept fixed for the desired number of pulses.'};
            app.RC_label = uilabel(app.RCTab);
            app.RC_label.Text = 'Amplitude [µA]';
            app.OffsetLabel_9 = uilabel(app.RCTab);
            app.OffsetLabel_9.Text = 'step [µA]';
            app.minEditFieldLabel = uilabel(app.RCTab);
            app.minEditFieldLabel.Text = 'min';
            app.RC_min_ampl = uieditfield(app.RCTab, 'numeric');
            app.RC_min_ampl.Limits = [0 1000];
            app.RC_min_ampl.ValueChangedFcn = createCallbackFcn(app, @RC_min_amplValueChanged, true);
            app.RC_min_ampl.Value = 50;
            app.maxEditFieldLabel = uilabel(app.RCTab);
            app.maxEditFieldLabel.Text = 'max';
            app.RC_max_ampl = uieditfield(app.RCTab, 'numeric');
            app.RC_max_ampl.Limits = [0 Inf];
            app.RC_max_ampl.ValueChangedFcn = createCallbackFcn(app, @RC_max_amplValueChanged, true);
            app.RC_max_ampl.Value = 200;
            app.RepetitionsperamplitudeEditFieldLabel = uilabel(app.RCTab);
            app.RepetitionsperamplitudeEditFieldLabel.Text = {'Repetitions'; 'per amplitude'};
            app.RC_reps = uieditfield(app.RCTab, 'numeric');
            app.RC_reps.Limits = [1 100];
            app.RC_reps.ValueChangedFcn = createCallbackFcn(app, @RC_repsValueChanged, true);
            app.RC_reps.Value = 3;
            app.PulseWidthsEditFieldLabel = uilabel(app.RCTab);
            app.PulseWidthsEditFieldLabel.Text = 'PulseWidth [µs]';
            app.RC_PW = uieditfield(app.RCTab, 'numeric');
            app.RC_PW.Limits = [0 100];
            app.RC_PW.ValueChangedFcn = createCallbackFcn(app, @RC_PWValueChanged, true);
            app.RC_PW.Value = 50;
            app.FrequencyHzSpinnerLabel = uilabel(app.RCTab);
            app.FrequencyHzSpinnerLabel.Text = 'Frequency [Hz]';
            app.RC_freq = uispinner(app.RCTab);
            app.RC_freq.Limits = [1 2];
            app.RC_freq.ValueChangedFcn = createCallbackFcn(app, @RC_freqValueChanged, true);
            app.RC_freq.Value = 1;
            app.RC_amp_step = uispinner(app.RCTab);
            app.RC_amp_step.Step = 5;
            app.RC_amp_step.Limits = [10 100];
            app.RC_amp_step.ValueChangedFcn = createCallbackFcn(app, @RC_amp_stepValueChanged, true);
            app.RC_amp_step.Value = 20;
            app.AMBurstsTab = uitab(app.stimSetup);
            app.AMBurstsTab.Title = 'AM Bursts';
            app.icon_AM_burst = uiimage(app.AMBurstsTab);
            app.icon_AM_burst.ImageSource = fullfile(pathToMLAPP, 'icons', 'AM.png');
            app.AM_burst_descr = uilabel(app.AMBurstsTab);
            app.AM_burst_descr.Text = {'Amplitude modulated bursts, with amplitude ranging from '; 'specified minimum to maximum.'; 'Amplitude is kept fixed for the desired number of bursts.'};
            app.AM_burst_label3 = uilabel(app.AMBurstsTab);
            app.AM_burst_label3.Text = 'Amplitude [µA]';
            app.IntraBurstFrequencyLabel_7 = uilabel(app.AMBurstsTab);
            app.IntraBurstFrequencyLabel_7.Text = 'IBI [ms]';
            app.AM_burst_inter_burst_interval = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_inter_burst_interval.Limits = [0 Inf];
            app.AM_burst_inter_burst_interval.ValueChangedFcn = createCallbackFcn(app, @AM_burst_inter_burst_intervalValueChanged, true);
            app.AM_burst_inter_burst_interval.Value = 500;
            app.RepetitionsperamplitudeEditField_2Label = uilabel(app.AMBurstsTab);
            app.RepetitionsperamplitudeEditField_2Label.Text = {'Repetitions'; 'per amplitude'};
            app.AM_burst_reps = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_reps.Limits = [1 20];
            app.AM_burst_reps.ValueChangedFcn = createCallbackFcn(app, @AM_burst_repsValueChanged, true);
            app.AM_burst_reps.Value = 2;
            app.PulseWidthsEditField_2Label = uilabel(app.AMBurstsTab);
            app.PulseWidthsEditField_2Label.Text = 'PulseWidth [µs]';
            app.AM_burst_PW = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_PW.Limits = [0 100];
            app.AM_burst_PW.ValueChangedFcn = createCallbackFcn(app, @AM_burst_PWValueChanged, true);
            app.AM_burst_PW.Value = 50;
            app.MinEditFieldLabel = uilabel(app.AMBurstsTab);
            app.MinEditFieldLabel.Text = 'Min';
            app.AM_burst_min_ampl = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_min_ampl.Limits = [0 1000];
            app.AM_burst_min_ampl.ValueChangedFcn = createCallbackFcn(app, @AM_burst_min_amplValueChanged, true);
            app.AM_burst_min_ampl.Value = 50;
            app.MaxEditFieldLabel = uilabel(app.AMBurstsTab);
            app.MaxEditFieldLabel.Text = 'Max';
            app.AM_burst_max_ampl = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_max_ampl.Limits = [0 1000];
            app.AM_burst_max_ampl.ValueChangedFcn = createCallbackFcn(app, @AM_burst_max_amplValueChanged, true);
            app.AM_burst_max_ampl.Value = 500;
            app.BurstdurationmsLabel_3 = uilabel(app.AMBurstsTab);
            app.BurstdurationmsLabel_3.Text = 'Burst duration [ms]';
            app.AM_burst_dur = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_dur.Limits = [0 Inf];
            app.AM_burst_dur.ValueChangedFcn = createCallbackFcn(app, @AM_burst_durValueChanged, true);
            app.AM_burst_dur.Value = 500;
            app.OffsetLabel_10 = uilabel(app.AMBurstsTab);
            app.OffsetLabel_10.Text = 'step [µA]';
            app.AM_burst_amp_step = uispinner(app.AMBurstsTab);
            app.AM_burst_amp_step.Step = 10;
            app.AM_burst_amp_step.Limits = [10 100];
            app.AM_burst_amp_step.ValueChangedFcn = createCallbackFcn(app, @AM_burst_amp_stepValueChanged, true);
            app.AM_burst_amp_step.Value = 20;
            app.IntraBurstFrequencyLabel_8 = uilabel(app.AMBurstsTab);
            app.IntraBurstFrequencyLabel_8.Text = 'Intra-Burst Frequency [Hz]';
            app.AM_burst_intra_burst_freq = uieditfield(app.AMBurstsTab, 'numeric');
            app.AM_burst_intra_burst_freq.Limits = [0 1000];
            app.AM_burst_intra_burst_freq.ValueChangedFcn = createCallbackFcn(app, @AM_burst_intra_burst_freqValueChanged, true);
            app.AM_burst_intra_burst_freq.Value = 50;
            app.FMBurstsTab = uitab(app.stimSetup);
            app.FMBurstsTab.Title = 'FM Bursts';
            app.icon_FM_burst = uiimage(app.FMBurstsTab);
            app.icon_FM_burst.ImageSource = fullfile(pathToMLAPP, 'icons', 'FM.png');
            app.FM_burst_descr = uilabel(app.FMBurstsTab);
            app.FM_burst_descr.Text = {'Frequency modulated bursts, with frequency ranging from '; 'specified minimum to maximum.'; 'Burst duration is always the same.'; 'Frequency is kept fixed for the desired number of bursts.'};
            app.FM_burst_label3 = uilabel(app.FMBurstsTab);
            app.FM_burst_label3.Text = 'Frequency [Hz]';
            app.RepetitionsperfrequencyEditFieldLabel = uilabel(app.FMBurstsTab);
            app.RepetitionsperfrequencyEditFieldLabel.Text = {'Repetitions'; 'per frequency'};
            app.FM_burst_reps = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_reps.Limits = [1 20];
            app.FM_burst_reps.ValueChangedFcn = createCallbackFcn(app, @FM_burst_repsValueChanged, true);
            app.FM_burst_reps.Value = 2;
            app.PulseWidthsEditField_4Label = uilabel(app.FMBurstsTab);
            app.PulseWidthsEditField_4Label.Text = 'PulseWidth [µs]';
            app.FM_burst_PW = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_PW.Limits = [0 100];
            app.FM_burst_PW.ValueChangedFcn = createCallbackFcn(app, @FM_burst_PWValueChanged, true);
            app.FM_burst_PW.Value = 50;
            app.MinEditField_3Label = uilabel(app.FMBurstsTab);
            app.MinEditField_3Label.Text = 'Min';
            app.FM_burst_min_freq = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_min_freq.Limits = [0 1000];
            app.FM_burst_min_freq.ValueChangedFcn = createCallbackFcn(app, @FM_burst_min_freqValueChanged, true);
            app.FM_burst_min_freq.Value = 20;
            app.BurstdurationmsLabel_4 = uilabel(app.FMBurstsTab);
            app.BurstdurationmsLabel_4.Text = 'Burst duration [ms]';
            app.FM_burst_dur = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_dur.Limits = [0 Inf];
            app.FM_burst_dur.ValueChangedFcn = createCallbackFcn(app, @FM_burst_durValueChanged, true);
            app.FM_burst_dur.Value = 500;
            app.MaxEditField_3Label = uilabel(app.FMBurstsTab);
            app.MaxEditField_3Label.Text = 'Max';
            app.FM_burst_max_freq = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_max_freq.Limits = [0 1000];
            app.FM_burst_max_freq.ValueChangedFcn = createCallbackFcn(app, @FM_burst_max_freqValueChanged, true);
            app.FM_burst_max_freq.Value = 100;
            app.IntraBurstFrequencyLabel_9 = uilabel(app.FMBurstsTab);
            app.IntraBurstFrequencyLabel_9.Text = 'Amplitude [µA]';
            app.FM_burst_ampl = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_ampl.Limits = [0 1000];
            app.FM_burst_ampl.ValueChangedFcn = createCallbackFcn(app, @FM_burst_amplValueChanged, true);
            app.FM_burst_ampl.Value = 100;
            app.IntraBurstFrequencyLabel_10 = uilabel(app.FMBurstsTab);
            app.IntraBurstFrequencyLabel_10.Text = 'IBI [ms]';
            app.FM_burst_inter_burst_int = uieditfield(app.FMBurstsTab, 'numeric');
            app.FM_burst_inter_burst_int.Limits = [0 1000];
            app.FM_burst_inter_burst_int.ValueChangedFcn = createCallbackFcn(app, @FM_burst_inter_burst_intValueChanged, true);
            app.FM_burst_inter_burst_int.Value = 500;
            app.OffsetLabel_11 = uilabel(app.FMBurstsTab);
            app.OffsetLabel_11.Text = 'step [Hz]';
            app.FM_burst_freq_step = uispinner(app.FMBurstsTab);
            app.FM_burst_freq_step.Step = 10;
            app.FM_burst_freq_step.Limits = [10 100];
            app.FM_burst_freq_step.ValueChangedFcn = createCallbackFcn(app, @FM_burst_freq_stepValueChanged, true);
            app.FM_burst_freq_step.Value = 10;
            app.TonicTab = uitab(app.stimSetup);
            app.TonicTab.Title = 'Tonic';
            app.AmplitudeALabel = uilabel(app.TonicTab);
            app.AmplitudeALabel.Text = 'Amplitude [µA]';
            app.tonic_ampl = uieditfield(app.TonicTab, 'numeric');
            app.tonic_ampl.Limits = [0 1000];
            app.tonic_ampl.ValueChangedFcn = createCallbackFcn(app, @tonic_amplValueChanged, true);
            app.tonic_ampl.Value = 100;
            app.tonic_descr = uilabel(app.TonicTab);
            app.tonic_descr.Text = {'Stimulation occurs continuously at the selected frequency and '; 'amplitude until the stop button is pressed or train duration ends.'; ''};
            app.tonic_TC = uibuttongroup(app.TonicTab);
            app.tonic_TC.SelectionChangedFcn = createCallbackFcn(app, @tonic_TCSelectionChanged, true);
            app.tonic_TC.Title = 'Termination Condition';
            app.tonic_train_dur_button = uiradiobutton(app.tonic_TC);
            app.tonic_train_dur_button.Text = 'Train duration [s]';
            app.tonic_train_dur_button.Value = true;
            app.tonic_stop_button = uiradiobutton(app.tonic_TC);
            app.tonic_stop_button.Text = 'Continuously (until STOP button pressed)';
            app.tonic_train_dur = uieditfield(app.tonic_TC, 'numeric');
            app.tonic_train_dur.Limits = [0 Inf];
            app.tonic_train_dur.ValueChangedFcn = createCallbackFcn(app, @tonic_train_durValueChanged, true);
            app.tonic_train_dur.Value = 5;
            app.FrequencyHzEditFieldLabel_2 = uilabel(app.TonicTab);
            app.FrequencyHzEditFieldLabel_2.Text = 'Frequency [Hz]';
            app.tonic_freq = uieditfield(app.TonicTab, 'numeric');
            app.tonic_freq.Limits = [0 1000];
            app.tonic_freq.ValueChangedFcn = createCallbackFcn(app, @tonic_freqValueChanged, true);
            app.tonic_freq.Value = 10;
            app.icon_tonic = uiimage(app.TonicTab);
            app.icon_tonic.ImageSource = fullfile(pathToMLAPP, 'icons', 'tonic2.png');
            app.DurationsLabel_3 = uilabel(app.TonicTab);
            app.DurationsLabel_3.Text = 'PulseWidth [µs]';
            app.tonic_PW = uieditfield(app.TonicTab, 'numeric');
            app.tonic_PW.Limits = [0 100];
            app.tonic_PW.ValueChangedFcn = createCallbackFcn(app, @tonic_PWValueChanged, true);
            app.tonic_PW.Value = 50;
            app.StimChannelDropDownLabel = uilabel(app.SingleChannelStimulationTab);
            app.StimChannelDropDownLabel.Text = 'Stim Channel';
            app.StimChannelDropDown = uidropdown(app.SingleChannelStimulationTab);
            app.StimChannelDropDown.Items = {'1', '2,', '16'};
            app.StimChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @StimChannelDropDownValueChanged, true);
            app.StimChannelDropDown.Value = '16';
            app.MultiChannelStimulationTab = uitab(app.TabGroup);
            app.MultiChannelStimulationTab.Title = 'Multi-Channel Stimulation';
            app.ax_multiCH = uiaxes(app.MultiChannelStimulationTab);
            title(app.ax_multiCH, 'Multi-Channel Stim')
            zlabel(app.ax_multiCH, 'Z')
            app.ax_multiCH.XTick = [];
            app.ax_multiCH.YTick = [];
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.pressureAxes = uiaxes(app.StreamingTab);
            ylabel(app.pressureAxes, 'Pressure level')
            zlabel(app.pressureAxes, 'Z')
            app.pressureAxes.YLim = [2000 20000];
            app.pressureAxes.XTick = [];
            app.pressureAxes.YTick = [];
            app.EMGaxes = uiaxes(app.StreamingTab);
            ylabel(app.EMGaxes, 'EMG channel')
            zlabel(app.EMGaxes, 'Z')
            app.EMGaxes.YLim = [2000 20000];
            app.EMGaxes.XTick = [];
            app.EMGaxes.YTick = [];
            app.stimAxes = uiaxes(app.StreamingTab);
            ylabel(app.stimAxes, 'Stim')
            zlabel(app.stimAxes, 'Z')
            app.stimAxes.XTick = [];
            app.stimAxes.YTick = [];
            app.ObjectshapeLabel = uilabel(app.StreamingTab);
            app.ObjectshapeLabel.Text = 'Object';
            app.ChannelsPanel = uipanel(app.StreamingTab);
            app.ChannelsPanel.Title = 'Channels';
            app.Ch1CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch1CheckBoxValueChanged, true);
            app.Ch1CheckBox.Text = 'Ch1';
            app.Ch2CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch2CheckBoxValueChanged, true);
            app.Ch2CheckBox.Text = 'Ch2';
            app.Ch3CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch3CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch3CheckBoxValueChanged, true);
            app.Ch3CheckBox.Text = 'Ch3';
            app.Ch4CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch4CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch4CheckBoxValueChanged, true);
            app.Ch4CheckBox.Text = 'Ch4';
            app.Ch5CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch5CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch5CheckBoxValueChanged, true);
            app.Ch5CheckBox.Text = 'Ch5';
            app.Ch6CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch6CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch6CheckBoxValueChanged, true);
            app.Ch6CheckBox.Text = 'Ch6';
            app.Ch7CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch7CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch7CheckBoxValueChanged, true);
            app.Ch7CheckBox.Text = 'Ch7';
            app.Ch8CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch8CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch8CheckBoxValueChanged, true);
            app.Ch8CheckBox.Text = 'Ch8';
            app.Ch9CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch9CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch9CheckBoxValueChanged, true);
            app.Ch9CheckBox.Text = 'Ch9';
            app.Ch10CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch10CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch10CheckBoxValueChanged, true);
            app.Ch10CheckBox.Text = 'Ch10';
            app.Ch11CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch11CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch11CheckBoxValueChanged, true);
            app.Ch11CheckBox.Text = 'Ch11';
            app.Ch12CheckBox = uicheckbox(app.ChannelsPanel);
            app.Ch12CheckBox.ValueChangedFcn = createCallbackFcn(app, @Ch12CheckBoxValueChanged, true);
            app.Ch12CheckBox.Text = 'Ch12';
            app.allChs = uicheckbox(app.ChannelsPanel);
            app.allChs.ValueChangedFcn = createCallbackFcn(app, @allChsValueChanged, true);
            app.allChs.Text = '';
            app.ObjectListBox = uilistbox(app.StreamingTab);
            app.ObjectListBox.Items = {'Pinch', 'Cyl_S', 'Cyl_L', 'Ball_S', 'Ball_L'};
            app.ObjectListBox.ValueChangedFcn = createCallbackFcn(app, @ObjectListBoxValueChanged, true);
            app.ObjectListBox.Value = 'Pinch';
            app.GainDropDownLabel = uilabel(app.StreamingTab);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.StreamingTab);
            app.GainDropDown.Items = {'1', '2', '8', '16', '32', '64'};
            app.GainDropDown.Value = '8';
            app.StreamButton = uibutton(app.StreamingTab, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @Stream, true);
            app.StreamButton.Text = 'Stream';
            app.StartStimButton = uibutton(app.StreamingTab, 'push');
            app.StartStimButton.ButtonPushedFcn = createCallbackFcn(app, @StartStimButtonPushed, true);
            app.StartStimButton.Icon = fullfile(pathToMLAPP, 'icons', 'start.jpeg');
            app.StartStimButton.Enable = 'off';
            app.StartStimButton.Text = '';
            app.StopStimButton = uibutton(app.StreamingTab, 'push');
            app.StopStimButton.ButtonPushedFcn = createCallbackFcn(app, @StopStimButtonPushed, true);
            app.StopStimButton.Icon = fullfile(pathToMLAPP, 'icons', 'stop.jpeg');
            app.StopStimButton.Enable = 'off';
            app.StopStimButton.Text = '';
            app.StimLabel = uilabel(app.StreamingTab);
            app.StimLabel.Text = 'Stim: OFF';
            % Show the figure after all components are created
            app.InterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface_characterization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InterfaceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InterfaceUIFigure)
        end
    end
end